classdef FormationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        AgentInformationLabel           matlab.ui.control.Label
        FileNameEditField_2Label        matlab.ui.control.Label
        AgentNameEditField              matlab.ui.control.EditField
        LoadAgentButton                 matlab.ui.control.Button
        ArenaPlot                       matlab.ui.control.UIAxes
        AgentTable                      matlab.ui.control.Table
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        SimulationControlsLabel         matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        DistancePlot                    matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function plotArena(app, agentPositions, A)
            % Setting up the plot
            delta = 1/20;
            numAgents = size(agentPositions(:,1:2),1);
            app.ArenaPlot.ColorOrderIndex = 1;
            cla(app.ArenaPlot)
            hold(app.ArenaPlot,'on');
            
            % Agent dots
            plot(app.ArenaPlot,agentPositions(:,1),agentPositions(:,2),'Marker','o');
            % Agent Labeling
            text(app.ArenaPlot, agentPositions(:,1)+delta, agentPositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            
            % Adjusting Label Axes to fit all agents
            app.ArenaPlot.XLim = ([min(agentPositions(:,1)) - 1, max(agentPositions(:,1)) + 1]);
            app.ArenaPlot.YLim = ([min(agentPositions(:,2)) - 1, max(agentPositions(:,2)) + 1]);
            
            % Adding communication lines between agents
            % Get plot values (can't do gplot directly to specified axis in 2019a)
            [X,Y] = gplot(A, agentPositions);
            % Plot values
            plot(app.ArenaPlot, X, Y,'-or','LineWidth',1,'MarkerFaceColor','auto');
             
            hold(app.ArenaPlot,'off');
        end
        
        function plotDistance(app, distanceToAverage, iteration)
            % Setting up the plot
            app.DistancePlot.ColorOrderIndex = 1;
            cla(app.DistancePlot)
            hold(app.DistancePlot,'on');
            
            % Plotting the distance from the average for each agent
            plot(app.DistancePlot, distanceToAverage(1:iteration,:));
            
            hold(app.DistancePlot,'off');
        end
        
        function saveSimulationData(~,agentPositionHistory,agentEnergyHistory,...
                distanceToAverage, f)
            ssName = 'agentData.xlsx';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            agentPositionHistory = agentPositionHistory(1:f,:);
            agentEnergyHistory = agentEnergyHistory(1:f,:);
            distanceToAverage = distanceToAverage(1:f,:);
            save(matName,'agentPositionHistory','agentEnergyHistory','distanceToAverage')
            writematrix(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writematrix(agentEnergyHistory,ssName,'FileType','spreadsheet','Sheet','Agent Energy History');
            writematrix(distanceToAverage,ssName,'FileType','spreadsheet','Sheet','Distance To Average');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AgentTable.Data = zeros(5,6);
            app.AgentTable.ColumnWidth = 'auto';
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
        end

        % Button pushed function: LoadAgentButton
        function loadAgentInfo(app, event)
            % Load data from external .mat to populate AgentTable
            filename = app.AgentNameEditField.Value;
            if isfile(filename)
                file = load(filename);
                try
                    % Put all data into the table
                    app.AgentTable.Data = file.matrix;
                    app.NumberofAgentsEditField.Value = size(app.AgentTable.Data,1);
                catch
                    warning('Bad Agent Information file: File must contain info in variable ''matrix''')
                end
            else
                warning(['Bad Agent Information file: No such file ''' filename ''''])
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
% Main function to run simulation
app.isStopPushed = false;

%% Initialize Values %%
agentData = app.AgentTable.Data;
numAgents = size(agentData,1);
timeStep = app.TimeStepEditField.Value;
iteration = 1;
duration = app.DurationEditField.Value;
%% END Initialize Values %%

%% Initialize Plots %%
app.DistancePlot.XLim = [0 duration];
%% END Initialize Plots %%

%% Stats setup %%
% Each row in agentPositionHistory is position at a given iteration. Stored
% in [x1 y1 x2 y2 ...] format
agentPositionHistory = zeros(duration, 2*numAgents);
agentPositionHistory(1,:) = reshape(agentData(:,1:2)',1,[]);

agentEnergyHistory = zeros(duration, numAgents);
agentEnergyHistory(1,:) = reshape(agentData(:,6),1,[]);

%% TODO: Does averagePosition need to be recalculated every iteration?
averagePosition = mean(agentData(:,1:2));
% Each row in distanceToAverage has numAgents columns, representing the
% Euclidean distance between that agent and average position for that
% iteration
distanceToAverage = zeros(duration,numAgents);
distanceToAverage(1,:) = pdist2(agentData(:,1:2),averagePosition)';
%% END Stats setup %%

% RUNNING THE SIMULATION
while iteration < duration && ~app.isStopPushed
    % Update UI for current iteration
    app.CurrentIterationEditField.Value = iteration;
    
    %% Matrix Functions %%
    % calcA should return an adjacency matrix using the agent state (positions)
    A = calcA(agentData(:,1:2));
    % Calculate Laplacian matrix from A (calculates D internally)
    L = calcL(A);
    %% END Matrix Functions %%
    
    %% Move Agents %%
    % Call student function
    agentData = moveAgents(agentData, L, iteration, timeStep);
    % Update UI
    app.AgentTable.Data = agentData;
    % Update agent history
    agentPositionHistory(iteration+1,:) = reshape(agentData(:,1:2)',1,[]);
    agentEnergyHistory(iteration+1,:) = reshape(agentData(:,6),1,[]);
    % Calculates the each agent's distance to the average and stores it in the distance variable
    distanceToAverage(iteration+1,:) = pdist2(agentData(:,1:2),averagePosition)';
    %% END Move Agents %%
    
    iteration = iteration + 1;
    
    %% Plots %%
    plotArena(app, agentData(:,1:2), A);
    plotDistance(app, distanceToAverage, iteration);
    %% END Plots %%
    
    drawnow;
end

% Writes Position History to an Excel Spreadsheet
saveSimulationData(app,agentPositionHistory,agentEnergyHistory,...
    distanceToAverage,iteration);
        end

        % Button pushed function: StopButton
        function stopSimulation(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NumberofAgentsEditField
        function numAgentsChanged(app, event)
            numAgents = app.NumberofAgentsEditField.Value;
            if size(app.AgentTable.Data,1) < numAgents
                app.AgentTable.Data = vertcat(app.AgentTable.Data,zeros(numAgents...
                    - size(app.AgentTable.Data,1),6));
            elseif size(app.AgentTable.Data,1) > numAgents
                app.AgentTable.Data = app.AgentTable.Data(1:numAgents,:);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Formation Algorithm';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FileNameEditField_2Label = uilabel(app.Window);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.AgentNameEditField = uieditfield(app.Window, 'text');
            app.AgentNameEditField.Value = 'agentInfo.mat';
            app.LoadAgentButton = uibutton(app.Window, 'push');
            app.LoadAgentButton.ButtonPushedFcn = createCallbackFcn(app, @loadAgentInfo, true);
            app.LoadAgentButton.Text = 'Load';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.AgentTable = uitable(app.Window);
            app.AgentTable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'; 'Energy'};
            app.AgentTable.ColumnEditable = [true true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.NumberofIterationsEditFieldLabel = uilabel(app.Window);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.CurrentIterationEditFieldLabel = uilabel(app.Window);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Limits = [0 Inf];
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.Window);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.Window, 'numeric');
            app.TimeStepEditField.Limits = [0.0001 Inf];
            app.TimeStepEditField.Value = 0.2;
            app.DistancePlot = uiaxes(app.Window);
            title(app.DistancePlot, 'Distance From Average')
            xlabel(app.DistancePlot, 'Iteration')
            ylabel(app.DistancePlot, 'Distance')
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumberofAgentsEditField.Limits = [1 Inf];
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumberofAgentsEditField.Value = 4;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.ParametersLabel = uilabel(app.Window);
            app.ParametersLabel.Text = 'Parameters';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end