classdef LloydApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        ArenaPlot                       matlab.ui.control.UIAxes
        CoveragePlot                    matlab.ui.control.UIAxes
        ExtraPlot                       matlab.ui.control.UIAxes
        CommPlot                        matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumAgentsEditField              matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel        matlab.ui.control.Label
        SideLengthEditField             matlab.ui.control.NumericEditField
        PartitionNumberEditFieldLabel   matlab.ui.control.Label
        PartitionNumberEditField        matlab.ui.control.NumericEditField
        RadiusofObservationEditFieldLabel  matlab.ui.control.Label
        RadiusofObservationEditField    matlab.ui.control.NumericEditField
        RadiusofCommunicationEditFieldLabel  matlab.ui.control.Label
        RadiusofCommunicationEditField  matlab.ui.control.NumericEditField
        VelocityDropDownLabel           matlab.ui.control.Label
        VelocityDropDown                matlab.ui.control.DropDown
        VelocityEditField               matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PositionTable                   matlab.ui.control.Table
        CurrentTimeEditFieldLabel       matlab.ui.control.Label
        CurrentTimeEditField            matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
        SimulationControlsLabel         matlab.ui.control.Label
        DensityLabel                    matlab.ui.control.Label
        VelocityFieldLabel              matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        AgentsLabel                     matlab.ui.control.Label
        ArenaLabel                      matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorLabel                matlab.ui.control.Label
        InitialPositionFileEditFieldLabel  matlab.ui.control.Label
        InitialPositionFileEditField    matlab.ui.control.EditField
        LoadPositionsButton             matlab.ui.control.Button
        PlotControlsLabel               matlab.ui.control.Label
        RadiusCommLabel                 matlab.ui.control.Label
        RadiusCommSwitch                matlab.ui.control.Switch
        RadiusObsSwitchLabel            matlab.ui.control.Label
        RadiusObsSwitch                 matlab.ui.control.Switch
        DensitySwitchLabel              matlab.ui.control.Label
        DensitySwitch                   matlab.ui.control.Switch
        ExtraPlotSwitchLabel            matlab.ui.control.Label
        ExtraPlotSwitch                 matlab.ui.control.Switch
        PlotsCheckBox                   matlab.ui.control.CheckBox
        dtEditFieldLabel                matlab.ui.control.Label
        dtEditField                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        isStopPushed % Holds state of stop button
        extraPlot % Holds state of extra plot
        plotRComm % Holds state of rComm plot
        plotRObs % Holds state of rObs plot
        plotDensity % Holds state of plotDensity
    end

    methods (Access = private)
        % Communication plot, plotting communication graph and radius of
        % communication if switch is on
        function plotCommunication(app,adjMatrix,agentPositions,rComm)
            % Clear old communication plot
            cla(app.CommPlot);
            hold(app.CommPlot,'on');
            if app.plotRComm
                %% TODO: If DABLAB ever allows multiple circle plots without loops, change this
                for i = 1:size(agentPositions,1)
                    % pos is [x y w h] where (x,y) is lower left corner of
                    % rect
                    pos = [agentPositions(i,:)-rComm rComm*2*[1 1]];
                    rectangle(app.CommPlot,'Position',pos,'Curvature',1,...
                        'LineWidth',0.5,'EdgeColor','b');
                end
            end
            % Get plot values (can't do gplot directly to specified axis in 2019a)
            [X,Y] = gplot(adjMatrix, agentPositions);
            % Plot values
            plot(app.CommPlot, X, Y,'-or','LineWidth',1,'MarkerFaceColor','auto');
            
            hold(app.CommPlot,'off');
        end
        
        % Plots coverage and distance as line plots for the given iteration
        function plotCoverageDistance(app,coverage,distanceTravelled,i)
            % Clear old coverage/distance plot
            cla(app.CoveragePlot);
            % Switch to left axis
            yyaxis(app.CoveragePlot, 'left');
            cla(app.CoveragePlot);
            % Hold to plot both y axes
            hold(app.CoveragePlot,'on');
            plot(app.CoveragePlot, 1:i, coverage(1:i),'Marker','none','Color','b');
            % Plot distance travelled on the right
            yyaxis(app.CoveragePlot, 'right');
            ylabel(app.CoveragePlot, 'Distance Travelled');
            plot(app.CoveragePlot, 1:i, distanceTravelled(1:i),'Marker','none','Color','r');
            hold(app.CoveragePlot,'off');
        end
        
        % Plots agents with numerical labels on scatter plot and centroids. Also draws
        % voronoi diagram and optionally the density contour diagram and
        % radius of observation circles
        function plotArena(app,agentPositions,centroids,density,xMesh,yMesh,sides,rObs)
            % Offset of agent labels
            delta = sides/65;
            numAgents = size(agentPositions,1);
            % Clear old arena plot
            cla(app.ArenaPlot);
            % Hold to draw all these plots
            hold(app.ArenaPlot, 'on');
            % Plot agents with blue dots
            scatter(app.ArenaPlot, agentPositions(:,1), agentPositions(:,2), 90,'blue','filled');
            % Add agent labels
            text(app.ArenaPlot, agentPositions(:,1)+delta, agentPositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            % Plot centroids with red dots
            scatter(app.ArenaPlot,centroids(:,1),centroids(:,2),50,'red','filled');
            % Voronoi diagram
            % If all agents are on the same point, delaunay will throw an error
            try
                voronoi(app.ArenaPlot, agentPositions(:,1),agentPositions(:,2));
            catch ME
                % Do nothing, don't plot
            end
            % Contour plot of density, if density is on
            if app.plotDensity
                [~, contPlot] = contour(app.ArenaPlot, xMesh, yMesh, density, 4);
                % Change line width on contour plot
                set(contPlot,'LineWidth',0.5)
            end
            if app.plotRObs
                %% TODO: If DABLAB ever allows multiple circle plots without loops, change this
                for i = 1:numAgents
                    pos = [agentPositions(i,:)-rObs rObs*2*[1 1]];
                    rectangle(app.ArenaPlot,'Position',pos,'Curvature',1,...
                        'LineWidth',0.5,'EdgeColor','b');
                end
            end
            hold(app.ArenaPlot,'off');
        end
        
        % Save simulation stats up to the final iteration number f
        % Can sometimes leave an extra line of 0's at end of file, and
        % Excel seems to think there are 3 blank sheets at the beginning of
        % the file
        function saveSimulationData(~,agentPositionHistory, coverage, distanceTravelled, densityHistory, f, dt)
            ssName = 'agentData.xlsx';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName);
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim stats
            agentPositionHistory = agentPositionHistory(1:f,:);
            coverage = coverage(1:f);
            distanceTravelled = distanceTravelled(1:f);
            densityHistory = densityHistory(1:f*size(densityHistory,2),:);
            % Save .mat before modifying
            save(matName,'agentPositionHistory','coverage','distanceTravelled','densityHistory');
            
            % Generate column headings for table
            N = (1:size(agentPositionHistory,2)/2)';
            posHeadings = reshape(cat(2,cellstr(num2str(N,'x%d')),cellstr(num2str(N,'y%d')))',1,[]);
            
            % Timestamp for rows
            timeStamp = dt*(0:f-1)';
            posHeadings = cat(2,cellstr('t'),posHeadings);
            
            % Add timestamps to data
            agentPositionHistory = cat(2,timeStamp,agentPositionHistory);
            coverage = cat(2,timeStamp,coverage);
            distanceTravelled = cat(2,timeStamp,distanceTravelled);
            
            % Generate tables
            agentPositionHistory = array2table(agentPositionHistory,'VariableNames',posHeadings);
            coverage = array2table(coverage,'VariableNames',["t" "Coverage"]);
            distanceTravelled = array2table(distanceTravelled,'VariableNames',["t" "Distance"]);
            
            writetable(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writetable(coverage,ssName,'FileType','spreadsheet','Sheet','Coverage');
            writetable(distanceTravelled,ssName,'FileType','spreadsheet','Sheet','Distance Travelled');
            writematrix(densityHistory,ssName,'FileType','spreadsheet','Sheet','Density History');
        end
        
        % Loads the density matrix and interprets it as a symbolic function
        % or matrix of discrete symbolic functions, and checks for various
        % errors
        function D = loadDensity(~, densityFileName, expectedSize)
            if isfile(densityFileName)
                try
                    densityFile = load(densityFileName);
                    if ~densityFile.isDensity
                        error('Bad density matrix: Ensure ''Density?'' is checked in Matrix Editor')
                    end
                    % Density is a single continuous function
                    if densityFile.isContinuous
                        syms x y t;
                        D = densityFile.func;
                        % Ensure function is in terms of x,y,t
                        if any(~ismember(symvar(D),[x y t]))
                            error('Bad density function: Continuous function must be in terms of x, y, t')
                        end
                    % Density is a matrix of discrete symbolic functions
                    else
                        syms t;
                        D = densityFile.matrix;
                        % Ensure function is only in terms of t
                        if any(~ismember(symvar(D), t))
                            error('Bad density matrix: Discrete functions must be in terms of t')
                        elseif size(D) ~= expectedSize*ones(1,2)
                            error('Bad density matrix: Dimensions of density matrix must equal sides*partitions')
                        end
                    end
                catch ME
                    error(['Bad density matrix: ' densityFileName ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create a density matrix'])
                end
            else
                error('Bad density matrix: File does not exist')
            end
        end
        
        % Plots the remaining 2 plots, either energy or assigned points.
        % Could always revise this to allow users to do a custom plot in
        % the future
        function plotExtra(app, energy, maxEnergy, agentPoints, pointColors, sides)
            cla(app.ExtraPlot);
            % Plot 1 is Assigned Points plot (scatter plot)
            if strcmp(app.extraPlot,'1')
                app.ExtraPlot.Title.String = 'Assigned Points';
                % Reset axis limits
                set(app.ExtraPlot,'XLim',[0 sides],'YLim',[0 sides]);
                hold(app.ExtraPlot,'on');
                for i = 1:size(agentPoints,1)
                    scatter(app.ExtraPlot,agentPoints{i}(:,1),...
                        agentPoints{i}(:,2),[],pointColors(i,:),'filled');
                end
                hold(app.ExtraPlot,'off');
            % Plot 2 is Energy plot (bar graph)
            else
                app.ExtraPlot.Title.String = 'Energy';
                set(app.ExtraPlot, 'YLim',[0 maxEnergy]);
                bar(app.ExtraPlot, energy);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Generates random positions upon startup
            numAgents = app.NumAgentsEditField.Value;
            sides = app.SideLengthEditField.Value;
            app.PositionTable.Data = rand(numAgents,2)*sides;
            
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
            % Suppress warnings for Delaunay
            warning('off','MATLAB:delaunay:DupPtsDelaunayWarnId');
            warning('off','MATLAB:triangulation:PtsNotInTriWarnId');
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
%% MAIN PROGRAM LOOP %%
close('all');
app.isStopPushed = false;

%% Get UI Values %%
sides = app.SideLengthEditField.Value;
partitions = app.PartitionNumberEditField.Value;
agentPositions = app.PositionTable.Data;
numAgents = app.NumAgentsEditField.Value;
maxVelocity = app.VelocityEditField.Value;
velocityScaleFactor = app.ScaleFactorEditField.Value;
velocityType = app.VelocityDropDown.Value;
duration = app.DurationEditField.Value;
dt = app.dtEditField.Value;
rObs = app.RadiusofObservationEditField.Value;
rComm = app.RadiusofCommunicationEditField.Value;
delay = app.DelayEditField.Value;
%% END Get UI Values %%

%% START Initialize plots %%
% Initialize app plot values, i.e. what should be plotted initially
shouldPlot = app.PlotsCheckBox.Value;
app.plotDensity = strcmp(app.DensitySwitch.Value,'On');
app.plotRComm = strcmp(app.RadiusCommSwitch.Value,'On');
app.plotRObs = strcmp(app.RadiusObsSwitch.Value, 'On');
app.extraPlot = app.ExtraPlotSwitch.Value;

% Set axis limits
set(app.CommPlot,'XLim',[0 sides],'YLim',[0 sides]);
set(app.ArenaPlot,'XLim',[0 sides],'YLim',[0 sides]);

% Need meshgrid to impose density over grid with physical dimension
% sides-by-sides
[xMesh, yMesh] = meshgrid(1:sides*partitions);
xMesh = xMesh/partitions;
yMesh = yMesh/partitions;

% Generate RGB triplet colors for assigned point plot
pointColors = rand(numAgents,3);

% Coverage/distance plot
yyaxis(app.CoveragePlot, 'left');
set(app.CoveragePlot,'YLim',[0 100]);
yyaxis(app.CoveragePlot, 'right');
set(app.CoveragePlot,'YLimMode','auto');
ylabel(app.CoveragePlot, 'Distance Travelled');

% Setup current time field display format if dt not an integer
if floor(dt)~=dt
    dtStr = split(num2str(dt),'.');
    spec = ['%0.' num2str(numel(dtStr{2})) 'f'];
    app.CurrentTimeEditField.ValueDisplayFormat = spec;
end
%% END Initialize Plots %%

%% Load density matrix %%
densityFileName = app.DensityMatrixNameEditField.Value;
D = loadDensity(app, densityFileName, partitions*sides);
%% END Load density matrix %%

%% Agent Positions setup %%
% Ensure none are out of bounds
agentPositions(agentPositions>sides) = sides;
agentPositions(agentPositions<0) = partitions;
%% END Agent Positions setup %%

%% Dynamic var setup %%
time = 0;
numIterations = duration/dt;
iteration = 1;
% Turn symbolic D to currentDensity matrix
currentDensity = calcDensity(D,iteration,sides,partitions);
%% END Dynamic var setup %%

%% Stats setup %%
% Pre-allocate all stat vars for speed
% Each row is the positions of each agent in [x1 y1 x2 y2 ...] format
agentPositionHistory = zeros(numIterations, numAgents*2);
agentPositionHistory(1,:) = reshape(agentPositions', [1 numAgents*2]);

% coverage is a column vector of percent of total arena observed during each iteration
coverage = zeros(numIterations,1);

% distanceTravelled is a column vector of the total distance travelled by all agents
% in each iteration
distanceTravelled = zeros(numIterations,1);

% Keep track of density over time
densityHistory = zeros(sides*partitions*numIterations, sides*partitions);
densityHistory(1:sides*partitions,:) = currentDensity;
%% END Stats setup %%

%% Energy %%
% Energy is a column vector of each agent's energy
% Try to access file named energy.mat, with variable energy
if isfile('energy.mat')
    energyFile = load('energy.mat');
    energy = energyFile.energy;
    numEnergy = size(energy,1);
    % If not enough energy entries, add filler
    if numEnergy<numAgents
        energy = vertcat(energy,ones(numAgents-numEnergy));
    elseif numEnergy>numAgents
        energy = energy(1:numAgents);
    end
else
    energy = 100*ones(numAgents,1);
end
% Fix energy plot to maxEnergy
maxEnergy = max(energy); 
%% END Energy %%

%----------------------------------------- Loop -----------------------------------------------------%
while iteration < numIterations && ~app.isStopPushed
    % Update Current Iteration field
    app.CurrentTimeEditField.Value = time;
    
    %% Calculate Communication %%
    % Calculate agent communication cells and adjacency matrix
    [commCells, adjMatrix] = communication(agentPositions,rComm);
    if shouldPlot
        plotCommunication(app, adjMatrix, agentPositions, rComm);
    end
    %% END Calculate Communication %%
    
    %% Points, Mass, and Coverage Calculation %%
    % Assign points to agents to generate their observed region
    agentPoints = assignAgentPoints(agentPositions, commCells, sides,...
        partitions, rObs);
    % Calculate mass of each observed region
    mass = calcMass(agentPoints, currentDensity, partitions);
    totalMass = sum(currentDensity,'all');
    coverage(iteration) = 100*calcCoverage(agentPoints, partitions,...
        currentDensity,totalMass);
    %% END Points, Mass, and Coverage Calculation %%
    
    %% Centroids and Movement %%
    % Calculate centroids of each region for agents to move towards
    centroids = calcCentroids(agentPoints, mass, currentDensity, agentPositions,...
        partitions);
    % Move the agents
    [agentPositions,distanceChange,energy] = moveAgents(agentPositions,...
        centroids, sides, dt, energy, velocityType, maxVelocity, velocityScaleFactor);
    agentPositionHistory(iteration,:) = reshape(agentPositions', [1 numAgents*2]);
    if iteration == 1
        distanceTravelled(1,1) = distanceChange;
    else
        distanceTravelled(iteration,1) = distanceTravelled(iteration-1,1) + distanceChange;
    end
    %% END Centroids and Movement %%
    
    %% Plots %%
    % Update table data
    if shouldPlot
        app.PositionTable.Data(:,1:2) = agentPositions;
        plotCoverageDistance(app, coverage, distanceTravelled, iteration);
        plotArena(app,agentPositions,centroids,currentDensity,xMesh,yMesh,sides,rObs);
        plotExtra(app,energy,maxEnergy,agentPoints,pointColors,sides);
    end
    %% END Plots %%
    
    time = time + dt;
    iteration = iteration + 1;
    
    %% Density Variation %%
    if iteration < numIterations
        % Check if we update density this iteration
        if mod(time,delay) == 0 || mod(time,delay) < mod(time-dt,delay)
            % Update density
            currentDensity = calcDensity(D,iteration,sides,partitions);
        end
        densityHistory((iteration-1)*sides*partitions+1:iteration*sides*partitions,:) = currentDensity;
    end
    %% END Density Variation
    % Draw plots and process callbacks
    drawnow;

end
%% END OF LOOP
% Save data and stuff
close('all');
saveSimulationData(app, agentPositionHistory, coverage, distanceTravelled, densityHistory, iteration-1, dt);
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
        % Updates state of isStopPushed to end simulation
        app.CurrentTimeEditField.Value = 0;
        app.isStopPushed = true;
        end

        % Value changed function: NumAgentsEditField
        function numAgentsChanged(app, event)
            % Adds/removes agents upon numAgents changing
            numAgents = app.NumAgentsEditField.Value;
            oldNumAgents = size(app.PositionTable.Data,1);
            delta = numAgents - oldNumAgents;
            sides = app.SideLengthEditField.Value;
            % If adding agents
            if delta > 0
                app.PositionTable.Data = vertcat(app.PositionTable.Data, rand(delta,2)*sides);
            % If removing agents
            else
                app.PositionTable.Data = app.PositionTable.Data(1:numAgents,:);
            end
        end

        % Value changed function: SideLengthEditField
        function sidesChanged(app, event)
            % Randomize positions with new range
            sides = app.SideLengthEditField.Value;
            app.PositionTable.Data = sides*rand(size(app.PositionTable.Data));
        end

        % Value changed function: VelocityDropDown
        function velocityTypeChanged(app, event)
            value = app.VelocityDropDown.Value;
            % The Velocity edit field acts in different ways depending on
            % Velocity type, and the label reflects that
            if strcmp(value, 'Constant Velocity')
                app.VelocityFieldLabel.Text = 'Velocity';
                set(app.ScaleFactorEditField,'Enable',false,'Editable',false,'Value',1);
                set(app.ScaleFactorLabel,'Enable',false);
            else
                app.VelocityFieldLabel.Text = 'Max Velocity';
                set(app.ScaleFactorEditField,'Enable',true,'Editable',true);
                set(app.ScaleFactorLabel,'Enable',true);
            end
        end

        % Button pushed function: LoadPositionsButton
        function loadPositions(app, event)
            % Loads initial positions from the file specified
            % Stores them in the position table
            positionFileName = app.InitialPositionFileEditField.Value;
            numAgents = app.NumAgentsEditField.Value;
            sides = app.SideLengthEditField.Value;
            if isfile(positionFileName)
                try
                    positionFile = load(positionFileName);
                    positions = str2double(positionFile.matrix);
                    % Ensure there are enough rows in position matrix
                    if numAgents > size(positions,1)
                        error(['Bad position matrix: Not enough rows for ' numAgents ' agents'])
                    % Ensure there are x,y coordinates for all agents
                    elseif size(positions,2) ~= 2
                        error('Bad position matrix: Matrix must have 2 columns in x, y format')
                    % Ensure no NaN values in positions
                    elseif sum(sum(isnan(positions)))
                        error('Bad position matrix: NaN values not allowed')
                    else
                        % Filter all invalid positions via logical indexing
                        positions(positions<0) = 0;
                        positions(positions>sides) = sides;
                        app.PositionTable.Data = positions;
                    end
                catch ME
                    disp(ME)
                    error(['Bad position matrix: ' positionFileName ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create a position matrix'])
                end
            else
                error('Bad position matrix: File does not exist')
            end
        end

        % Cell edit callback: PositionTable
        function positionChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            sides = app.SideLengthEditField.Value;
            % Filter new value
            if newData > sides
                newData = sides;
            elseif newData < 0 || isnan(newData)
                newData = 0;
            end
            app.PositionTable.Data(indices(1),indices(2)) = newData;
        end

        % Value changed function: ExtraPlotSwitch
        function extraPlotStateChanged(app, event)
            app.extraPlot = app.ExtraPlotSwitch.Value;
        end

        % Value changed function: RadiusCommSwitch
        function rCommPlotChanged(app, event)
            app.plotRComm = strcmp(app.RadiusCommSwitch.Value,'On');
        end

        % Value changed function: RadiusObsSwitch
        function rObsPlotChanged(app, event)
            app.plotRObs = strcmp(app.RadiusObsSwitch.Value,'On');
        end

        % Value changed function: DensitySwitch
        function densityPlotChanged(app, event)
            app.plotDensity = strcmp(app.DensitySwitch.Value,'On');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Lloyd''s Algorithm';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.CoveragePlot = uiaxes(app.Window);
            title(app.CoveragePlot, 'Coverage and Distance')
            xlabel(app.CoveragePlot, 'Iteration Number')
            ylabel(app.CoveragePlot, 'Percent Covered')
            app.ExtraPlot = uiaxes(app.Window);
            title(app.ExtraPlot, 'Assigned Points')
            app.CommPlot = uiaxes(app.Window);
            title(app.CommPlot, 'Communication Graph')
            xlabel(app.CommPlot, '')
            ylabel(app.CommPlot, '')
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumAgentsEditField.Value = 10;
            app.SideLengthEditFieldLabel = uilabel(app.Window);
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.Window, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @sidesChanged, true);
            app.SideLengthEditField.Value = 5;
            app.PartitionNumberEditFieldLabel = uilabel(app.Window);
            app.PartitionNumberEditFieldLabel.Text = 'Partition Number';
            app.PartitionNumberEditField = uieditfield(app.Window, 'numeric');
            app.PartitionNumberEditField.Value = 10;
            app.RadiusofObservationEditFieldLabel = uilabel(app.Window);
            app.RadiusofObservationEditFieldLabel.Text = 'Radius of Observation';
            app.RadiusofObservationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofObservationEditField.Value = 2;
            app.RadiusofCommunicationEditFieldLabel = uilabel(app.Window);
            app.RadiusofCommunicationEditFieldLabel.Text = 'Radius of Communication';
            app.RadiusofCommunicationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofCommunicationEditField.Value = 3;
            app.VelocityDropDownLabel = uilabel(app.Window);
            app.VelocityDropDownLabel.Text = 'Velocity';
            app.VelocityDropDown = uidropdown(app.Window);
            app.VelocityDropDown.Items = {'Constant Velocity', 'Proportional Velocity'};
            app.VelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @velocityTypeChanged, true);
            app.VelocityDropDown.Value = 'Constant Velocity';
            app.VelocityEditField = uieditfield(app.Window, 'numeric');
            app.VelocityEditField.Value = 1;
            app.DelayEditFieldLabel = uilabel(app.Window);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.Window, 'numeric');
            app.DelayEditField.Value = 5;
            app.DurationEditFieldLabel = uilabel(app.Window);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Value = 50;
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PositionTable = uitable(app.Window);
            app.PositionTable.ColumnName = {'X'; 'Y'};
            app.PositionTable.ColumnEditable = [true true];
            app.PositionTable.CellEditCallback = createCallbackFcn(app, @positionChanged, true);
            app.CurrentTimeEditFieldLabel = uilabel(app.Window);
            app.CurrentTimeEditFieldLabel.Text = 'Current Time';
            app.CurrentTimeEditField = uieditfield(app.Window, 'numeric');
            app.CurrentTimeEditField.Editable = 'off';
            app.DensityMatrixNameEditFieldLabel = uilabel(app.Window);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.Window, 'text');
            app.DensityMatrixNameEditField.Value = 'density.mat';
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.DensityLabel = uilabel(app.Window);
            app.DensityLabel.Text = 'Density';
            app.VelocityFieldLabel = uilabel(app.Window);
            app.VelocityFieldLabel.Text = 'Velocity';
            app.VelocityLabel = uilabel(app.Window);
            app.VelocityLabel.Text = 'Velocity';
            app.AgentsLabel = uilabel(app.Window);
            app.AgentsLabel.Text = 'Agents';
            app.ArenaLabel = uilabel(app.Window);
            app.ArenaLabel.Text = 'Arena';
            app.ScaleFactorEditField = uieditfield(app.Window, 'numeric');
            app.ScaleFactorEditField.Editable = 'off';
            app.ScaleFactorEditField.Enable = 'off';
            app.ScaleFactorEditField.Value = 1;
            app.ScaleFactorLabel = uilabel(app.Window);
            app.ScaleFactorLabel.Enable = 'off';
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.InitialPositionFileEditFieldLabel = uilabel(app.Window);
            app.InitialPositionFileEditFieldLabel.Text = 'Initial Position File';
            app.InitialPositionFileEditField = uieditfield(app.Window, 'text');
            app.InitialPositionFileEditField.Value = 'positions.mat';
            app.LoadPositionsButton = uibutton(app.Window, 'push');
            app.LoadPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @loadPositions, true);
            app.LoadPositionsButton.Text = 'Load Positions';
            app.PlotControlsLabel = uilabel(app.Window);
            app.PlotControlsLabel.Text = 'Plot Controls';
            app.RadiusCommLabel = uilabel(app.Window);
            app.RadiusCommLabel.Text = 'Radius Comm.';
            app.RadiusCommSwitch = uiswitch(app.Window, 'slider');
            app.RadiusCommSwitch.ValueChangedFcn = createCallbackFcn(app, @rCommPlotChanged, true);
            app.RadiusObsSwitchLabel = uilabel(app.Window);
            app.RadiusObsSwitchLabel.Text = 'Radius Obs.';
            app.RadiusObsSwitch = uiswitch(app.Window, 'slider');
            app.RadiusObsSwitch.ValueChangedFcn = createCallbackFcn(app, @rObsPlotChanged, true);
            app.DensitySwitchLabel = uilabel(app.Window);
            app.DensitySwitchLabel.Text = 'Density';
            app.DensitySwitch = uiswitch(app.Window, 'slider');
            app.DensitySwitch.ValueChangedFcn = createCallbackFcn(app, @densityPlotChanged, true);
            app.ExtraPlotSwitchLabel = uilabel(app.Window);
            app.ExtraPlotSwitchLabel.Text = 'Extra Plot';
            app.ExtraPlotSwitch = uiswitch(app.Window, 'slider');
            app.ExtraPlotSwitch.Items = {'1', '2'};
            app.ExtraPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @extraPlotStateChanged, true);
            app.ExtraPlotSwitch.Value = '1';
            app.PlotsCheckBox = uicheckbox(app.Window);
            app.PlotsCheckBox.Text = 'Plots';
            app.PlotsCheckBox.Value = true;
            app.dtEditFieldLabel = uilabel(app.Window);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.Window, 'numeric');
            app.dtEditField.Value = 0.2;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LloydApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end