classdef OpinionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                     matlab.ui.Figure
        NodeDataTable              matlab.ui.control.Table
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        NumberofNodesLabel         matlab.ui.control.Label
        NodesEditField             matlab.ui.control.NumericEditField
        DimensionsButtonGroup      matlab.ui.container.ButtonGroup
        One                        matlab.ui.control.RadioButton
        Two                        matlab.ui.control.RadioButton
        NodeTrajectoryPlot         matlab.ui.control.UIAxes
        DurationLabel              matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        dtEditFieldLabel           matlab.ui.control.Label
        dtEditField                matlab.ui.control.NumericEditField
        SimulationControlsLabel    matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        AgentInformationLabel      matlab.ui.control.Label
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        NodeLocationPlot           matlab.ui.control.UIAxes
        PlotsCheckBox              matlab.ui.control.CheckBox
        CurrentTimeEditFieldLabel  matlab.ui.control.Label
        CurrentTimeEditField       matlab.ui.control.NumericEditField
        ParametersLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function saveSimulationData(~,isOneDim,f,dt,nodeRadiiHistory,nodePositionHistory,nodeNoiseHistory)
            ssName = 'nodeData.xlsx';
            matName = 'nodeData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            nodeRadiiHistory = nodeRadiiHistory(1:f,:);
            nodePositionHistory = nodePositionHistory(1:f,:);
            % Generate column headings
            N = (1:size(nodeRadiiHistory,2))';
            nodeHeadings = cellstr(num2str(N,'Node%d'))';
            
            % Generate timestamps
            timeStamp = dt*(0:f-1)';
            
            if isOneDim
                % Save to .mat without altering data
                save(matName,'nodeRadiiHistory','nodePositionHistory','nodeNoiseHistory');
                
                % Trim noise
                nodeNoiseHistory = nodeNoiseHistory(1:f,:);
                % Generate remaining column headings
                noiseHeadings = reshape(cat(2,cellstr(num2str(N,'Node%dL')),cellstr(num2str(N,'Node%dR')))',1,[]);
                posHeadings = nodeHeadings;
                % Add timestamp column to headings
                noiseHeadings = cat(2,cellstr('t'),noiseHeadings);
                % Add timestamps to data
                nodeNoiseHistory = cat(2,timeStamp,nodeNoiseHistory);
                % Convert to table
                nodeNoiseHistory = array2table(nodeNoiseHistory,'VariableNames',noiseHeadings);
                
                writetable(nodeNoiseHistory,ssName,'FileType','spreadsheet','Sheet','Node Noise History');
                
            else
                % Save to .mat without altering data
                save(matName,'nodeRadiiHistory','nodePositionHistory');
                
                % Generate remaining column headings
                posHeadings = reshape(cat(2,cellstr(num2str(N,'x%d')),cellstr(num2str(N,'y%d')))',1,[]);
            end
            
            % Add timestamp column to headings
            nodeHeadings = cat(2,cellstr('t'),nodeHeadings);
            posHeadings = cat(2,cellstr('t'),posHeadings);
            % Add timestamps to data
            nodePositionHistory = cat(2,timeStamp,nodePositionHistory);
            nodeRadiiHistory = cat(2,timeStamp,nodeRadiiHistory);
            % Convert to table
            nodePositionHistory = array2table(nodePositionHistory,'VariableNames',posHeadings);
            nodeRadiiHistory = array2table(nodeRadiiHistory,'VariableNames',nodeHeadings);
            
            writetable(nodePositionHistory,ssName,'FileType','spreadsheet','Sheet','Node Position History');
            writetable(nodeRadiiHistory,ssName,'FileType','spreadsheet','Sheet','Node Radii History');
       end
        
        function toggleDimensionUI(app, isOneDimensional)
            if isOneDimensional
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','Position',...
                    'Left Noise','Right Noise'}');
                app.One.Value = true;
                app.Two.Value = false;
                % Show trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',true);
                % Decrease location plot size
                set(app.NodeLocationPlot,'Position',[12 208 300 185]);
            else
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','X Position',...
                    'Y Position'});
                app.One.Value = false;
                app.Two.Value = true;
                % Hide trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',false);
                % Increase location plot size
                set(app.NodeLocationPlot,'Position',[12 15 300 378]);
            end
        end
        
        % nodeData needs to be padded as if it were two dimensional
        % nodeData should be of form [r1, x1, y1; r2, x2, y2; ... ]
        function plotLocation(app,nodeData,numNodes)
            delta = 1/20;
            cla(app.NodeLocationPlot);
            % Plotting the node location on the Node Location Plot
            hold(app.NodeLocationPlot,'on');
            
            % Plot the location of each node
            scatter(app.NodeLocationPlot,nodeData(:,2),nodeData(:,3),'Marker','o');
            
            % Plot circles around each agent to reflect an agents radius of communication
            % TODO: If DABLAB ever allows circles to be plotted not in a
            % loop in app designer, changed this for efficiency
            for node = 1:size(nodeData,1)
                radii = nodeData(node,1);
                pos = [nodeData(node,2)-radii nodeData(node,3)-radii 2*radii 2*radii];
                rectangle(app.NodeLocationPlot,'Position',pos,'Curvature',[1 1]);
            end
            
            % Adding node labels
            text(app.NodeLocationPlot, nodeData(:,2)+delta, nodeData(:,3)+delta,...
                cellfun(@num2str,num2cell((1:numNodes)'),'UniformOutput',false));
            
            % Adjusting plotting window to display all nodes
            app.NodeLocationPlot.XLim = ([min(nodeData(:,2)) - 3, max(nodeData(:,2)) + 3]);
            app.NodeLocationPlot.YLim = ([min(nodeData(:,3)) - 3, max(nodeData(:,3)) + 3]);
            
            hold(app.NodeLocationPlot,'off')
        end
        
        function plotTrajectory(app, trajectories,numNodes, iteration, dt, numIterations)
            delta = 1/20;
            cla(app.NodeTrajectoryPlot);
            hold(app.NodeTrajectoryPlot,'on')
        
            plot(app.NodeTrajectoryPlot, dt*(0:iteration), trajectories,...
                '-o','MarkerIndices',iteration);
            text(app.NodeLocationPlot, trajectories(end,:)+delta, iteration*dt*ones(1,numNodes)+delta,...
                cellfun(@num2str,num2cell((1:numNodes)'),'UniformOutput',false));
           
            app.NodeTrajectoryPlot.XLim = ([0, numIterations*dt]);
            app.NodeTrajectoryPlot.YLim = ([min(trajectories,[],'all') - 1, max(trajectories,[],'all') + 1]);
        
            hold(app.NodeTrajectoryPlot, 'off') 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
            
            app.NodeDataTable.Data = zeros(5,4);
            app.NodesEditField.Value = 5;
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NodesEditField
        function numNodesChanged(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            % New total number of agents 
            newNumNodes = app.NodesEditField.Value; 
            
            % Old total number of agents
            oldNumNodes = size(app.NodeDataTable.Data,1);
            delta = newNumNodes - oldNumNodes;
            
            % If adding nodes
            if delta > 0
                app.NodeDataTable.Data = vertcat(app.NodeDataTable.Data, zeros(delta,size(app.NodeDataTable.Data,2)));
            else
                app.NodeDataTable.Data = app.NodeDataTable.Data(1:newNumNodes,:);
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
        % Main simulation code
        app.isStopPushed = false;
        
        %% START Initialize Values %%
        nodeData = app.NodeDataTable.Data;
        numNodes = app.NodesEditField.Value;
        duration = app.DurationEditField.Value;
        dt = app.dtEditField.Value;
        time = 0;
        isOneDim = strcmp(app.DimensionsButtonGroup.SelectedObject.Text,'1');
        shouldPlot = app.PlotsCheckBox.Value;
      
        % numIterations is the duration in terms of discrete time steps 
        numIterations = duration/dt;
        % Iteration counter variable used in while loop
        iteration = 1;     
        %% END Initialize Values %% 
        
        %% START Setup Plots %%
        % Setup current time field display format if dt not an integer
        if floor(dt)~=dt
            dtStr = split(num2str(dt),'.');
            spec = ['%0.' num2str(numel(dtStr{2})) 'f'];
            app.CurrentTimeEditField.ValueDisplayFormat = spec;
        end
        %% END Setup Plots %%
        
        % Storing the node information from each iteration in the simulation to be exported 
        % into an excel spreadsheet upon simulation completion
        nodeRadiiHistory = zeros(numIterations,numNodes);
        nodeRadiiHistory(1,:) = nodeData(:,1)';
        
        if isOneDim
            nodePositionHistory = zeros(numIterations,numNodes);
            nodePositionHistory(1,:) = nodeData(:,2)';
            nodeNoiseHistory = zeros(numIterations,numNodes*2);
            nodeNoiseHistory(1,:) = reshape(nodeData(:,3:4)',1,[]);
        else
            nodePositionHistory = zeros(numIterations,numNodes*2);
            nodePositionHistory(1,:) = reshape(nodeData(:,2:3)',1,[]);
        end
        
        % RUNING THE SIMULATION
        while iteration < numIterations && ~app.isStopPushed
            app.CurrentTimeEditField.Value = time;
            
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            A = calcA(nodeData);
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            L = calcL(A);
            
            % STUDENT MADE FUNCTION to update the Node Data. Must update
            % Node Positions, but can also update radii of communication
            % and left and right offsets with the introduction of cost
            % functions
            nodeData = updateNodeData(nodeData,L,dt,time);
           
            %% START Stats %%
            nodeRadiiHistory(iteration+1,:) = nodeData(:,1)';
            if isOneDim
                nodePositionHistory(iteration+1,:) = nodeData(:,2)';
                nodeNoiseHistory(iteration+1,:) = reshape(nodeData(:,3:4)',1,[]);
            else
                nodePositionHistory(1,:) = reshape(nodeData(:,2:3)',1,[]);
            end
            %% END Stats %%
            
            %% START Plotting %%
            app.NodeDataTable.Data = nodeData;
            % Only plot trajectory if simulation is one dim
            if shouldPlot
                if isOneDim
                    plotTrajectory(app,nodePositionHistory(1:iteration+1,:),numNodes,iteration,dt,numIterations);
                    plotNodeData = cat(2,nodeData(:,1),zeros(numNodes,1));
                    plotNodeData = cat(2,plotNodeData,nodeData(:,2));
                    plotLocation(app,plotNodeData,numNodes);
                else
                    plotLocation(app,nodeData);
                end
            end
            
            %% END Plotting %%
            
            time = time + dt;
            iteration = iteration + 1;
            drawnow;
        end
        % Update last iteration value
        app.CurrentTimeEditField.Value = time;
        
        % Save simulation Results
        saveSimulationData(app, isOneDim, iteration, dt, nodeRadiiHistory, nodePositionHistory, nodeNoiseHistory);
        end

        % Button pushed function: LoadButton
        function loadNodeInfo(app, event)
            filename = app.FilenameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load dimension
                    isOneDimensional = file.isOneDimensional;
                    % Load data
                    app.NodeDataTable.Data = file.matrix;
                    % Set num agents edit field
                    app.NodesEditField.Value = size(app.NodeDataTable.Data,1);
                    % Set UI based on dimension
                    toggleDimensionUI(app, isOneDimensional);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Selection changed function: DimensionsButtonGroup
        function dimensionChanged(app, event)
            isOneDim = strcmp(app.DimensionsButtonGroup.SelectedObject.Text,'1');
            selection = uiconfirm(app.Window,'This could delete some data. Continue?', 'Confirm Change',...
                    'Options',{'Yes', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
            switch (selection)
                case 'Yes'
                    oldData = app.NodeDataTable.Data;
                    % If it was 2D and is now 1D
                    if isOneDim
                        % Keep rComm,xPos, add left/right noise columns
                        toggleDimensionUI(app, true);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),2));
                    % If it was 1D and is now 2D
                    else
                        % Keep rComm,Pos, add y Pos
                        toggleDimensionUI(app, false);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),1));
                    end
                case 'Cancel'
                    % Revert UI to previous state
                    toggleDimensionUI(app, ~isOneDim);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Opinion Algorithm';
            app.NodeDataTable = uitable(app.Window);
            app.NodeDataTable.ColumnName = {'Radius of Comm.'; 'Position'; 'Left Noise'; 'Right Noise'};
            app.NodeDataTable.ColumnEditable = [true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.NumberofNodesLabel = uilabel(app.Window);
            app.NumberofNodesLabel.Text = 'Number of Nodes';
            app.NodesEditField = uieditfield(app.Window, 'numeric');
            app.NodesEditField.ValueChangedFcn = createCallbackFcn(app, @numNodesChanged, true);
            app.NodesEditField.Value = 5;
            app.DimensionsButtonGroup = uibuttongroup(app.Window);
            app.DimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimensionChanged, true);
            app.DimensionsButtonGroup.Title = 'Simulation Dimension(s)';
            app.One = uiradiobutton(app.DimensionsButtonGroup);
            app.One.Text = '1';
            app.One.Value = true;
            app.Two = uiradiobutton(app.DimensionsButtonGroup);
            app.Two.Text = '2';
            app.NodeTrajectoryPlot = uiaxes(app.Window);
            title(app.NodeTrajectoryPlot, 'Node Trajectory')
            xlabel(app.NodeTrajectoryPlot, 'Time')
            ylabel(app.NodeTrajectoryPlot, 'Location')
            app.DurationLabel = uilabel(app.Window);
            app.DurationLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.dtEditFieldLabel = uilabel(app.Window);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.Window, 'numeric');
            app.dtEditField.Value = 0.2;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadNodeInfo, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FilenameEditFieldLabel = uilabel(app.Window);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Window, 'text');
            app.FilenameEditField.Value = 'agentInfo.mat';
            app.NodeLocationPlot = uiaxes(app.Window);
            title(app.NodeLocationPlot, 'Node Location')
            xlabel(app.NodeLocationPlot, 'X')
            ylabel(app.NodeLocationPlot, 'Y')
            app.PlotsCheckBox = uicheckbox(app.Window);
            app.PlotsCheckBox.Text = 'Plots';
            app.PlotsCheckBox.Value = true;
            app.CurrentTimeEditFieldLabel = uilabel(app.Window);
            app.CurrentTimeEditFieldLabel.Text = 'Current Time';
            app.CurrentTimeEditField = uieditfield(app.Window, 'numeric');
            app.CurrentTimeEditField.Editable = 'off';
            app.ParametersLabel = uilabel(app.Window);
            app.ParametersLabel.Text = 'Parameters';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpinionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end