classdef A_Matrix_Creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        NumberofMatricesEditFieldLabel  matlab.ui.control.Label
        NumberofMatricesEditField       matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        CurrentMatrixEditFieldLabel     matlab.ui.control.Label
        CurrentMatrixEditField          matlab.ui.control.NumericEditField
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end


    properties (Access = private)
        NumMatrices = 1;
        OverallData = zeros(4); 
        file = 'A-Matrix';
    end


    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
              
            data = zeros(4);
            app.OverallData = data;
            
            app.UITable.Data = data;
            
            set(app.UITable, 'RowName', 'numbered','ColumnName','numbered','ColumnWidth','auto');
            
        end

        % Value changed function: NumberofAgentsEditField
        function NumberOfAgents(app, event)
           % This function alters the number of columns and rows in the A matrix table to reflect the desired number of agents in the system.
           n = app.NumberofAgentsEditField.Value;          % new number of agents for system
           data = app.OverallData;
         
           if n < size(data,1)
               newdata = app.OverallData(1:n,1:n,:);
           else
               new = n-size(data,1);
               rowIncrease = zeros(new,size(data,2),size(data,3));
               data = cat(1,data,rowIncrease);
               colIncrease = zeros(size(data,1),new,size(data,3));
               data = cat(2,data,colIncrease);
               newdata = data;
           end
           
           app.UITable.Data = newdata(:,:,app.CurrentMatrixEditField.Value);
           app.OverallData = newdata;
        end

        % Callback function
        function SwitchingChecked(app, event)
                      
        end

        % Cell edit callback: UITable
        function CellEditCallback(app, event)
            data = app.UITable.Data;                  % Current table data
            n = app.CurrentMatrixEditField.Value;     % Current matrix number
            
            app.OverallData(:,:,n) = data;               % Updates A matrix in Overall A matrix
        end

        % Button pushed function: ResetButton
        function ResetButtonPushedFcn(app, event)
            app.UITable.Data = zeros(4);
            app.NumberofMatricesEditField.Value = 1;
            app.CurrentMatrixEditField.Value = 1;
            app.OverallData = zeros(4);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            AMatrix = app.OverallData;
            save(app.file,'AMatrix');            
        end

        % Callback function
        function AddTabButtonPushedFcn(app, event)
     
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushedFcn(app, event)
            data = app.UITable.Data;                % Current A Matrix Data being shown
            n = app.CurrentMatrixEditField.Value;   % Current Matrix Number
            app.OverallData(:,:,n) = data;              % Saves current A Matrix data to the overall data field
            
            n = n - 1; % Subtracts one from matrix index value. This allows for the shift to the previous A Matrix.
            
            % wraps previous to max value if next matrix number will be 0.
            if n == 0
                n = app.NumberofMatricesEditField.Value;
            end
            
            app.CurrentMatrixEditField.Value = n;
            data = app.OverallData(:,:,n);
            app.UITable.Data = data;
        end

        % Button pushed function: NextButton
        function NextButtonPushedFcn(app, event)
            data = app.UITable.Data;                % Current A Matrix Data being shown
            n = app.CurrentMatrixEditField.Value;   % Current Matrix Number
            app.OverallData(:,:,n) = data;          % Saves current A Matrix data to the overall data field
            
            % wraps previous to max value if next matrix number will be 0.
            if n == (app.NumberofMatricesEditField.Value) 
                n = 0;
            end
            
            n = n+1 % updates the matrix index by one
            
            app.CurrentMatrixEditField.Value = n;
            data = app.OverallData(:,:,n);
            app.UITable.Data = data;
        end

        % Value changed function: NumberofMatricesEditField
        function NumMatricesValueChangedFcn(app, event)
            app.NumMatrices = app.NumberofMatricesEditField.Value;
            
            sizeOld = size(app.OverallData,3);
            n = app.NumberofAgentsEditField.Value; 
            
            data = app.OverallData;
            
            if sizeOld < app.NumMatrices
                for i = 1:(app.NumMatrices-1)
                    data = cat(3,data,zeros(n));
                end
            else
                data = data(:,:,1:app.NumMatrices);
            end
            
            app.OverallData = data;
            
            if sizeOld > size(app.OverallData,3)
                app.CurrentMatrixEditField.Value = 1;
                app.UITable.Data = app.OverallData(:,:,1);
            end 
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushedFcn(app, event)
            load(app.file,'AMatrix');
            A = AMatrix;
            app.OverallData = A;
            app.NumberofMatricesEditField.Value = size(A,3);
            app.NumberofAgentsEditField.Value = size(A,1);
            app.UITable.Data = A(:,:,1);
            app.NumMatrices = size(A,3);
            
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            msg = 'Are you sure you want to close?';
            title = 'Close';
            selection = uiconfirm(app.UIFigure,msg,title,...
                'Options',{'Exit','Save & Exit','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            switch selection
                case 'Exit'
                    delete(app)
                case 'Save & Exit'
                    AMatrix = app.OverallData;
                    save(app.file,'AMatrix');
                    delete(app)
                case 'Cancel'
                    % Do nothing
            end 
            
        end

        % Value changed function: FileNameEditField
        function FileNameValueChangedFunction(app, event)
            app.file = app.FileNameEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.NumberofAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAgentsEditField.Limits = [0 Inf];
            app.NumberofAgentsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfAgents, true);
            app.NumberofAgentsEditField.Value = 4;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushedFcn, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.NumberofMatricesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofMatricesEditFieldLabel.Text = 'Number of Matrices';
            app.NumberofMatricesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofMatricesEditField.Limits = [1 Inf];
            app.NumberofMatricesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofMatricesEditField.ValueChangedFcn = createCallbackFcn(app, @NumMatricesValueChangedFcn, true);
            app.NumberofMatricesEditField.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @CellEditCallback, true);
            app.CurrentMatrixEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentMatrixEditFieldLabel.Text = 'Current Matrix';
            app.CurrentMatrixEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentMatrixEditField.Limits = [1 Inf];
            app.CurrentMatrixEditField.ValueDisplayFormat = '%.0f';
            app.CurrentMatrixEditField.Editable = 'off';
            app.CurrentMatrixEditField.Value = 1;
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushedFcn, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushedFcn, true);
            app.NextButton.Text = 'Next';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushedFcn, true);
            app.LoadButton.Text = 'Load';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameValueChangedFunction, true);
            app.FileNameEditField.Value = 'A-Matrix';
        end
    end

    methods (Access = public)

        % Construct app
        function app = A_Matrix_Creator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end