classdef FlockingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        KEditFieldLabel                 matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        SigmaLabel                      matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        LeaderTrajectoryLabel           matlab.ui.control.Label
        xtLabel                         matlab.ui.control.Label
        XTrajectoryEditField            matlab.ui.control.EditField
        ytLabel                         matlab.ui.control.Label
        YTrajectoryEditField            matlab.ui.control.EditField
        NumberofAgentsLabel             matlab.ui.control.Label
        NumAgentsEditField              matlab.ui.control.NumericEditField
        SimulationDurationLabel         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        AgentDataTable                  matlab.ui.control.Table
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        ArenaPlot                       matlab.ui.control.UIAxes
        SimulationControlsLabel         matlab.ui.control.Label
        AgentConfigurationLabel         matlab.ui.control.Label
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        AgentInformationLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        isStopPushed
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumAgentsEditField
        function numAgentsChanged(app, event)
            % Change AgentDataTable size to reflect edit field change
            % New total number of agents 
            newNumAgents = app.NumAgentsEditField.Value; 
            % Old total number of agents
            oldNumAgents = size(app.AgentDataTable.Data,1);
            delta = newNumAgents - oldNumAgents;
            
            % If adding agents
            if delta>0
                app.AgentDataTable.Data = vertcat(app.agentData, zeros(delta,4));
            % If removing agents
            else
                app.AgentDataTable.Data = app.agentData(1:numNewAgents,:);
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
            % This function runs the simulation using the information given
            % by the user.
            %% TODO: Code review this function and all external functions
            
            % VARIABLE INITIALIZATION
            % Initialize the iteration couter variable (current iteration field in app)
            app.CurrentIterationEditField.Value = 1;  
            % Number of iterations simulation is to run for
            numIterations = app.DurationEditField.Value;
            
            % Reads the agent position and velcoity data and stores it to the global variable
            app.agentData = app.AgentDataTable.Data;
            % stores the x,y position data of each agent for a specified iteration
            agentPosition = app.agentData(:,1:2);     
            % stores the x,y velcoity data of each agent for a specified iteration 
            agentVelocity = app.agentData(:,3:4);               
            
            % Variables to store the position and velocity data for each agent throughout the simulation
            agentPositionHistory = zeros(numIterations, 2*app.NumAgentsEditField.Value);
            agentVelocityHistory = zeros(numIterations, 2*app.NumAgentsEditField.Value);
            agentPositionHistory(1,:) = reshape(agentPosition',1,[]);
            agentVelocityHistory(1,:) = reshape(agentVelocity',1,[]);
            
            % "Time step" between each iteration of the simulation
            timeStep = app.TimeStepEditField.Value;             
            % Setting the size of the square arena that the agents have to move in
            % arenaDimensions = 5;                              
            % set up symbolic variable used when interpreting trajectories
            syms t 
            
            % Using Trajectory Equations defined by user
            % Reads in and converts input to a parametric equation for x component of leader trajectory
            leaderXPositionFunction = str2sym(app.XTrajectoryEditField.Value);   
            % Reads in and converts input to a parametric equation for y component of leader trajectory
            leaderYPositionFunction = str2sym(app.YTrajectoryEditField.Value);
            % Differentiating the leaderXPositionFunction to get the X velocity of the leader
            leaderXVelocityFunction = diff(leaderXPositionFunction);
            % Differentiating the leaderYPositionFunction to get the Y velocity of the leader
            leaderYVeloictyFunction = diff(leaderYPositionFunction);
            
            % Initializing the leader's position and velocity based on the user defined trajectories
            leaderPosition = zeros(numIterations,2);
            leaderVelocity = zeros(numIterations,2);
            
            % Stores the position of the leader based on the user defined trajectory for each iteration of the simulation
            leaderPosition(1,:) = agentPosition(1,:);  
            % Stores the velocity of the leader based on the user defined trajectory for each iteration of the simulation
            leaderVelocity(1,:) = agentVelocity(1,:);
            
            
            % Plot Label Spacer
            delta = 1/20;
            
            % Populate X and Y position and velocity of leader for each iteration based on defined trajectory
            if ~strcmpi(app.XTrajectoryEditField.Value,'') && ~strcmpi(app.YTrajectoryEditField.Value,'')
                for iteration = 2:numIterations
                    t = (iteration-1)*timeStep;
                    leaderPosition(iteration,1) = subs(leaderXPositionFunction) + leaderPosition(1,1);
                    leaderPosition(iteration,2) = subs(leaderYPositionFunction) + leaderPosition(1,2);
                    leaderVelocity(iteration,1) = subs(leaderXVelocityFunction) + leaderVelocity(1,1);
                    leaderVelocity(iteration,2) = subs(leaderYVeloictyFunction) + leaderVelocity(1,2);
                end
            end
            
            
            % -------------------------------------------------------------------------------
            
            % SIMULATION
            while app.CurrentIterationEditField.Value < numIterations
                
                % STUDENT MADE FUNCTION: CalculateA -- used to calculate adjacency matrix 
                A = CalculateA(app.KEditField.Value, app.SigmaEditField.Value, app.BetaEditField.Value,agentPosition);
                % STUDENT MADE FUNCTION: CalculateL -- used to calculate Laplacian matrix
                L = CalculateL(A);
                
                % Updates the agents position
                agentPosition = agentPosition + (timeStep*agentVelocity);
                
                % Updates the agent position and history matrices
                agentPositionHistory(app.CurrentIterationEditField.Value,:) = reshape(agentPosition',1,[]);
                agentVelocityHistory(app.CurrentIterationEditField.Value,:) = reshape(agentVelocity',1,[]);
                
                % STUDENT MADE FUNCTION: Trigger -- to determine if trigger is activated for the current iteration
                if Trigger(app.CurrentIterationEditField.Value) == 1
                   % STUDENT MADE FUNCTION: VelocityUpdate -- updates agents' velocity if trigger activated
                   agentVelocity = VelocityUpdate(L,timeStep,agentVelocity);
                end
                
                % If user defined trajectory function exists, replace leader velocity update with that from the leaderVelocity
                % calculated previosly in the function
                if ~strcmpi(app.XTrajectoryEditField.Value,'') && ~strcmpi(app.YTrajectoryEditField.Value,'')
                    agentVelocity(1,:) = leaderVelocity(app.CurrentIterationEditField.Value,:);
                end
                
                app.AgentDataTable.Data = [agentPosition agentVelocity];
                
                % Increases iteration counter by 1
                app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
                
                % ---------------------------------------------------------------------------------------------------
                
                % PLOTTING
                % First Plot previous up to current iteration position of agents. Current position is 
                % indicated with a 'O' marker and the previous points form its tail
                app.ArenaPlot.ColorOrderIndex = 1;
                cla(app.ArenaPlot)
                hold(app.ArenaPlot, 'on')
                plot(app.ArenaPlot,...
                    agentPositionHistory(1:app.CurrentIterationEditField.Value-1,1:2:end),...
                    agentPositionHistory(1:app.CurrentIterationEditField.Value-1,2:2:end),...
                    '-o',"MarkerIndices",app.CurrentIterationEditField.Value-1);    
                
                % Agent Labeling
                text(app.ArenaPlot, agentPositionHistory(app.CurrentIterationEditField.Value-1, 1:2:end) + delta,...
                    agentPositionHistory(app.CurrentIterationEditField.Value-1, 2:2:end) - delta,...
                    cellfun(@num2str,num2cell((1:app.NumAgentsEditField.Value)),'UniformOutput',false));
                                                 
                hold(app.ArenaPlot, 'off')
                % Give time for plot to be shown on screen
                pause(0.05);
            end
            
            % ---------------------------------------------------------------------------------------------------
            
            % Writes Position History to an Excel Spreadsheet
            delete('SimulationPositionResults.csv');
            writematrix(agentPositionHistory,'SimulationPositionResults.csv');
            
            % Writes Velocity History to an Excel Spreadsheet
            delete('SimulationVelocityResults.csv');
            writematrix(agentVelocityHistory,'SimulationVelocityResults.csv');
                        
        end

        % Button pushed function: StopButton
        function stopSimulation(app, event)
            app.isStopPushed = true;
        end

        % Button pushed function: LoadButton
        function loadAgentInfo(app, event)
            filename = app.FilenameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.AgentDataTable.Data = file.matrix;
                    % Load extra info
                    app.XTrajectoryEditField.Value = file.xFunction;
                    app.YTrajectoryEditField.Value = file.yFunction;
                    app.KEditField.Value = file.k;
                    app.SigmaEditField.Value = file.sigma;
                    app.BetaEditField.Value = file.beta;
                    app.NumAgentsEditField.Value = size(file.matrix,1);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'UI Figure';
            app.KEditFieldLabel = uilabel(app.Window);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Window, 'numeric');
            app.KEditField.Value = 1;
            app.BetaEditFieldLabel = uilabel(app.Window);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.Window, 'numeric');
            app.BetaEditField.Value = 0.25;
            app.SigmaLabel = uilabel(app.Window);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Window, 'numeric');
            app.SigmaEditField.Value = 1;
            app.LeaderTrajectoryLabel = uilabel(app.Window);
            app.LeaderTrajectoryLabel.Text = 'Leader Trajectory';
            app.xtLabel = uilabel(app.Window);
            app.xtLabel.Text = 'x(t)';
            app.XTrajectoryEditField = uieditfield(app.Window, 'text');
            app.ytLabel = uilabel(app.Window);
            app.ytLabel.Text = 'y(t)';
            app.YTrajectoryEditField = uieditfield(app.Window, 'text');
            app.NumberofAgentsLabel = uilabel(app.Window);
            app.NumberofAgentsLabel.Text = 'Number of Agents';
            app.NumAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.SimulationDurationLabel = uilabel(app.Window);
            app.SimulationDurationLabel.Text = 'Simulation Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Value = 20;
            app.AgentDataTable = uitable(app.Window);
            app.AgentDataTable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.AgentDataTable.ColumnEditable = [true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.CurrentIterationEditFieldLabel = uilabel(app.Window);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Limits = [0 Inf];
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.Window);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.Window, 'numeric');
            app.TimeStepEditField.Value = 0.2;
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.AgentConfigurationLabel = uilabel(app.Window);
            app.AgentConfigurationLabel.Text = 'Agent Configuration';
            app.FilenameEditFieldLabel = uilabel(app.Window);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Window, 'text');
            app.FilenameEditField.Value = 'agentInfo.mat';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadAgentInfo, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlockingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end