classdef FormationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        FileNameEditFieldLabel          matlab.ui.control.Label
        MatrixNameEditField             matlab.ui.control.EditField
        AMatrixLabel                    matlab.ui.control.Label
        AgentInformationLabel           matlab.ui.control.Label
        FileNameEditField_2Label        matlab.ui.control.Label
        AgentNameEditField              matlab.ui.control.EditField
        LoadAgentButton                 matlab.ui.control.Button
        ArenaPlot                       matlab.ui.control.UIAxes
        AgentTable                      matlab.ui.control.Table
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        DistancePlot                    matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function plotArena(app, agentPositions, A)
            % Setting up the plot
            delta = 1/20;
            numAgents = size(agentPositions(:,1:2),1);
            app.ArenaPlot.ColorOrderIndex = 1;
            cla(app.ArenaPlot)
            hold(app.ArenaPlot,'on');
            
            % Agent dots
            plot(app.ArenaPlot,agentPositions(:,1),agentPositions(:,2),'Marker','o');
            % Agent Labeling
            text(app.ArenaPlot, agentPositions(:,1)+delta, agentPositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            
            % Adjusting Label Axes to fit all agents
            app.ArenaPlot.XLim = ([min(agentPositions(:,1)) - 1, max(agentPositions(:,1)) + 1]);
            app.ArenaPlot.YLim = ([min(agentPositions(:,2)) - 1, max(agentPositions(:,2)) + 1]);
            
            % Adding communication lines between agents
            % Get plot values (can't do gplot directly to specified axis in 2019a)
            [X,Y] = gplot(A, agentPositions);
            % Plot values
            plot(app.ArenaPlot, X, Y,'-or','LineWidth',1,'MarkerFaceColor','auto');
             
            hold(app.ArenaPlot,'off');
        end
        
        function plotDistance(app, distanceToAverage, iteration)
            % Setting up the plot
            app.DistancePlot.ColorOrderIndex = 1;
            cla(app.DistancePlot)
            hold(app.DistancePlot,'on');
            
            % Plotting the distance from the average for each agent
            plot(app.DistancePlot, distanceToAverage(1:iteration,:));
            
            hold(app.DistancePlot,'off');
        end
        
        function saveSimulationData(~,agentPositionHistory,agentEnergyHistory,...
                distanceToAverage, f)
            ssName = 'agentData.xlsx';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            agentPositionHistory = agentPositionHistory(1:f,:);
            agentEnergyHistory = agentEnergyHistory(1:f,:);
            distanceToAverage = distanceToAverage(1:f,:);
            save(matName,'agentPositionHistory','agentEnergyHistory','distanceToAverage')
            writematrix(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writematrix(agentEnergyHistory,ssName,'FileType','spreadsheet','Sheet','Agent Energy History');
            writematrix(distanceToAverage,ssName,'FileType','spreadsheet','Sheet','Distance To Average');
        end
        
        function matrix = loadAMatrix(app, filename)
            if isfile(filename)
                try
                    file = load(filename);
                    matrix = file.matrix;
                    [x, y, ~] = size(matrix);
                    % Ensure A is correct size
                    if x ~= y
                        error('Bad A Matrix: A must be square')
                    end
                    % Clip/add rows on AgentData table to match that of the A
                    % matrix
                    if size(app.AgentTable.Data,1) < x
                        warning('Agent Table data too short. Initial agent data padded with zeros')
                        app.AgentTable.Data = vertcat(app.AgentTable.Data,zeros(x-size(app.AgentTable.Data,1),5));
                    elseif size(app.AgentTable.Data,1) > x
                        warning('Agent Table data too long. Initial agent data clipped')
                        app.AgentTable.Data = app.AgentTable.Data(1:x,:);
                    end
                    app.NumberofAgentsEditField.Value = size(app.AgentTable.Data,1);
                catch ME
                    error(['Bad A matrix: ' filename ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create your matrix'])
                end
            else
                error('Bad A matrix: File does not exist')
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AgentTable.Data = zeros(5,6);
            app.AgentTable.ColumnWidth = 'auto';
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
        end

        % Button pushed function: LoadAgentButton
        function loadAgentInfo(app, event)
            % Load data from external .mat to populate AgentTable
            filename = app.AgentNameEditField.Value;
            if isfile(filename)
                file = load(filename);
                try
                    % Put all data into the table
                    app.AgentTable.Data = file.matrix;
                    app.NumberofAgentsEditField.Value = size(app.AgentTable.Data,1);
                catch
                    warning('Bad Agent Information file: File must contain info in variable ''matrix''')
                end
            else
                warning(['Bad Agent Information file: No such file ''' filename ''''])
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
% Main function to run simulation
app.isStopPushed = false;

%% Initialize Values %%
matrix = loadAMatrix(app, app.MatrixNameEditField.Value);
agentData = app.AgentTable.Data;
numAgents = size(agentData,1);
timeStep = app.TimeStepEditField.Value;
iteration = 1;
duration = app.DurationEditField.Value;
%% END Initialize Values %%

%% Initialize Plots %%
app.DistancePlot.XLim = [0 duration];
%% END Initialize Plots %%

%% Stats setup %%
% Each row in agentPositionHistory is position at a given iteration. Stored
% in [x1 y1 x2 y2 ...] format
agentPositionHistory = zeros(duration, 2*numAgents);
agentPositionHistory(1,:) = reshape(agentData(:,1:2)',1,[]);

agentEnergyHistory = zeros(duration, numAgents);
agentEnergyHistory(1,:) = reshape(agentData(:,6),1,[]);

%% TODO: Does averagePosition need to be recalculated every iteration?
averagePosition = mean(agentData(:,1:2));
% Each row in distanceToAverage has numAgents columns, representing the
% Euclidean distance between that agent and average position for that
% iteration
distanceToAverage = zeros(app.DurationEditField.Value,numAgents);
distanceToAverage(1,:) = pdist2(agentData(:,1:2),averagePosition)';
%% END Stats setup %%

% RUNNING THE SIMULATION
while iteration < app.DurationEditField.Value && ~app.isStopPushed
    % Update UI for current iteration
    app.CurrentIterationEditField.Value = iteration;
    
    %% Matrix Functions %%
    % Get A matrix from the student getA function
    % getA is also responsible for any switching behaviour
    A = getA(matrix,iteration);
    % Calculate Laplacian matrix from A (calculates D internally)
    L = calcL(A);
    %% END Matrix Functions %%
    
    %% Move Agents %%
    % Call student function
    agentData = moveAgents(agentData, L, iteration, timeStep);
    % Update UI
    app.AgentTable.Data = agentData;
    % Update agent history
    agentPositionHistory(iteration+1,:) = reshape(agentData(:,1:2)',1,[]);
    agentEnergyHistory(iteration+1,:) = reshape(agentData(:,6),1,[]);
    % Calculates the each agent's distance to the average and stores it in the distance variable
    distanceToAverage(iteration+1,:) = pdist2(agentData(:,1:2),averagePosition)';
    %% END Move Agents %%
    
    iteration = iteration + 1;
    
    %% Plots %%
    plotArena(app, agentData(:,1:2), A);
    plotDistance(app, distanceToAverage, iteration);
    %% END Plots %%
    
    drawnow;
end

% Writes Position History to an Excel Spreadsheet
saveSimulationData(app,agentPositionHistory,agentEnergyHistory,...
    distanceToAverage,iteration);
        end

        % Button pushed function: StopButton
        function stopSimulation(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NumberofAgentsEditField
        function numAgentsChanged(app, event)
            numAgents = app.NumberofAgentsEditField.Value;
            if size(app.AgentTable.Data,1) < numAgents
                app.AgentTable.Data = vertcat(app.AgentTable.Data,zeros(numAgents...
                    - size(app.AgentTable.Data,1),6));
            elseif size(app.AgentTable.Data,1) > numAgents
                app.AgentTable.Data = app.AgentTable.Data(1:numAgents,:);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Formation Algorithm';
            app.FileNameEditFieldLabel = uilabel(app.Window);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.MatrixNameEditField = uieditfield(app.Window, 'text');
            app.MatrixNameEditField.Value = 'A.mat';
            app.AMatrixLabel = uilabel(app.Window);
            app.AMatrixLabel.Text = 'A Matrix';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FileNameEditField_2Label = uilabel(app.Window);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.AgentNameEditField = uieditfield(app.Window, 'text');
            app.AgentNameEditField.Value = 'agentInfo.mat';
            app.LoadAgentButton = uibutton(app.Window, 'push');
            app.LoadAgentButton.ButtonPushedFcn = createCallbackFcn(app, @loadAgentInfo, true);
            app.LoadAgentButton.Text = 'Load';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.AgentTable = uitable(app.Window);
            app.AgentTable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'; 'Energy'};
            app.AgentTable.ColumnEditable = [true true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.NumberofIterationsEditFieldLabel = uilabel(app.Window);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.CurrentIterationEditFieldLabel = uilabel(app.Window);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Limits = [0 Inf];
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.Window);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.Window, 'numeric');
            app.TimeStepEditField.Limits = [0.0001 Inf];
            app.TimeStepEditField.Value = 0.2;
            app.DistancePlot = uiaxes(app.Window);
            title(app.DistancePlot, 'Distance From Average')
            xlabel(app.DistancePlot, 'Iteration')
            ylabel(app.DistancePlot, 'Distance')
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumberofAgentsEditField.Limits = [1 Inf];
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumberofAgentsEditField.Value = 4;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end