classdef Formation_App_modified_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        AMatricesLabel                  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        AgentInformationLabel           matlab.ui.control.Label
        FileNameEditField_2Label        matlab.ui.control.Label
        FileNameEditField_2             matlab.ui.control.EditField
        LoadButton_2                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aOverall;
        agentSetUp;
    end
    
    methods (Access = private)
        
        function agentInformationUpdated = AgentInformationUpdate(~,L,agentInformation,currentIteration,timeStep)
            % This function updates the position of each agent according to the given
            % agent parameters.
            
            position = agentInformation(:,1:2);
            tau = agentInformation(:,3);
            offset = agentInformation(:,4:5);
            
            timeDelayedAgents = find(tau >= currentIteration);
            
            % Update Position
            positionUpdated = position;
            
            positionUpdated(:,1) = position(:,1) - (timeStep * (L * (position(:,1) - offset(:,1))));
            positionUpdated(:,2) = position(:,2) - (timeStep * (L * (position(:,2) - offset(:,2))));
            
            positionUpdated(timeDelayedAgents,1) = position(timeDelayedAgents,1);
            positionUpdated(timeDelayedAgents,2) = position(timeDelayedAgents,2);
            
            % Preping Data to be returned to main function
            agentInformationUpdated = agentInformation;
            agentInformationUpdated(:,1:2) = positionUpdated;
            agentInformationUpdated(:,3) = tau;
            agentInformationUpdated(:,4:5) = offset;
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadAMatrix(app, event)
            % This function loads the A matrix generated by the user 
            %    using the A_Matrix_Creator app
                    
            file = app.FileNameEditField.Value;
               
            if isfile(strcat(file,'.mat'))
                load(file,'aMatrix');       % Loading the specified .mat file.
                try
                    app.aOverall = aMatrix;                                 % Storing A Matrix to global variable
                                            
                    app.UITable.Data = zeros(size(app.aOverall,1),5);       % Initializes X, Y, Delay, X Offset and Y Offset table to have
                                                                            % the same number of rows as the imported A matrix
                    app.UITable2.Data = zeros(size(app.aOverall,3)-1,1);    % Initializes iteration switch table with number of agents - 1 rows
                catch 
                    % If there is an issue loading the A matix from the given
                    % file, then the following error message will be displayed
                    
                    uialert(app.UIFigure,'Cannot read A Matrix from file. Please change A matrix variable name to "AMatrix"','File Read Error');
                end
                
            else
                % If the file specified by the user does not exist within the
                % current folder, then the following error message will be
                % displayed
                
                uialert(app.UIFigure,'File not found','Invalid File Name')
            end
            
        end

        % Button pushed function: LoadButton_2
        function LoadAgentInfo(app, event)
            % This function loads the X, Y, Delay, X Offset, Y Offset configuration for the system created in an external
            % matrix generating app and displays it in the app
             
            file = app.FileNameEditField_2.Value;
               
            if isfile(strcat(file,'.mat'))
                load(file,'matrix');
                try
                    app.agentSetUp = matrix;         % Storing Agent Set Up to global variable
                    app.UITable.Data = matrix;       % Updating X, Y, Delay, X Offset and Y Offset with the imported setup configuration 
                catch
                                        % If there is an issue loading the A matix from the given
                    % file, then the following error message will be displayed.
                    
                    uialert(app.UIFigure,'Cannot read Set Up from file. Please change Agent Set Up to variable name to "matrix"','File Read Error');
                end
                
            else
                % If the file specified by the user does not exist within the
                % current folder, then the following error message will be
                % displayed.
                
                uialert(app.UIFigure,'File not found','Invalid File Name')
            end
        end

        % Button pushed function: RunButton
        function RunSimulation(app, event)
            % This function runs the simulation over the specified number of iterations
            % SIMULATION SET UP
            
            agentInformation = app.UITable.Data;                                                    % stores the X, Y, Delay, X Offset and Y Offset data
            aMatrixIndex = 1;                                                                       % tracks which A matrix is to be used in the simulation
            aMatrixSwitch = vertcat(app.UITable2.Data, app.NumberofIterationsEditField.Value + 1);  % iterations when A matrix changes
            agentPosition = agentInformation(:,1:2);                                                % 2 column matrix storing agent X and Y position respectively 
            app.CurrentIterationEditField.Value = 1;                                                % Initializes iteration counter
            timeStep = app.TimeStepEditField.Value;                                                 % Stores the desired time step for the simulation
            
            agentPositionHistory = zeros(app.NumberofIterationsEditField.Value, 2*size(agentPosition,1));   % Initializes array that will store position of the agents for each iteration                                                % Stores history of agent positions during the simulation
            
            % Populating the agentPositionHstory with the inital position of the the agents
            for agent = 1:size(agentPosition,1)
                agentPositionHistory(1,2*agent-1) = agentPosition(agent,1);
                agentPositionHistory(1,2*agent) = agentPosition(agent,2);
            end
            
            averagePosition = [mean(agentPosition(:,1)), mean(agentPosition(:,2))];                 % Calculates the average x and y coordiante for the initial position
            distanceToAverage = zeros(size(agentPosition,1),app.NumberofIterationsEditField.Value); % Initializes the distance to the average for each iteration of the simulation
            distanceToAverage(:,1) = pdist2(agentPosition,averagePosition);                         % Distance between each point and the average coordinate location

            % RUNNING THE SIMULATION
            while app.CurrentIterationEditField.Value < app.NumberofIterationsEditField.Value
                
                A = app.aOverall(:,:,aMatrixIndex);     % Retireves required A matrix
                L = CalculateL(A);                      % STUDENT MADE FUNCTION that calculates L matrix
                
                agentInformation = AgentInformationUpdate(app, L, agentInformation, app.CurrentIterationEditField.Value, timeStep);       
                                                        % STUDENT MADE FUNCTION that updates agent position
                
                agentPosition = agentInformation(:,1:2); % Updates agent position vector
                app.UITable.Data(:,1:2) = agentPosition; % Updates Table
                
                % Adding updated agent position to the history of agent position variable
                for agent = 1:size(agentPosition,1)
                    agentPositionHistory(app.CurrentIterationEditField.Value + 1, 2*agent-1) = agentPosition(agent,1);
                    agentPositionHistory(app.CurrentIterationEditField.Value + 1, 2*agent) = agentPosition(agent,2);
                end
                
                % Calculates the each agent's distance to the average and stores it in the distance variable
                distanceToAverage(:,app.CurrentIterationEditField.Value + 1) = pdist2(agentPosition,averagePosition);
                
                % Increase iteration counter by 1
                app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
                
                % Checking if A matrix needs to be changed for next loop iteration
                if app.CurrentIterationEditField.Value == aMatrixSwitch(aMatrixIndex)
                    aMatirxIndex = aMatrixIndex + 1;
                end
                           
                % ------------------------------------------------------------------------------------------------------------------------
                % PLOTTING
                
                % POSITION OF AGENTS PLOT
                % Setting up the plot
                app.UIAxes.ColorOrderIndex = 1;
                cla(app.UIAxes)
                hold(app.UIAxes,'on');
                
                plot(app.UIAxes,agentPosition(:,1),agentPosition(:,2),'Marker','o');
                
                % Agent Labeling
                for agent = 1:size(A,1)
                    text(app.UIAxes, agentPosition(agent,1), agentPosition(agent,2), sprintf('%d', agent), 'color', 'k', 'fontweight', 'bold');
                end
                
                % Adjusting Label Axes to fit all agents
                app.UIAxes.XLim = ([min(agentPosition(:,1)) - 1, max(agentPosition(:,1)) + 1]);
                app.UIAxes.YLim = ([min(agentPosition(:,2)) - 1, max(agentPosition(:,2)) + 1]);
                
                % Adding communication lines between agents
                for row = 1:size(A,1)
                    for col = 1:size(A,1)
                        if A(row,col) == 1
                            line(app.UIAxes, [agentPosition(row,1), agentPosition(col,1)],[agentPosition(row,2), agentPosition(col,2)],'Color','k');
                        end
                    end
                end
                 
                hold(app.UIAxes,'off');
                
                % DISTANCE TO AVERAGE PLOT
                % Setting up the plot
                app.UIAxes2.ColorOrderIndex = 1;
                cla(app.UIAxes2)
                hold(app.UIAxes2,'on');
                app.UIAxes2.XLim = [0 app.NumberofIterationsEditField.Value];
                
                % Plotting the distance from the average for each agent
                for agent = 1:size(agentPosition,1)
                    plot(app.UIAxes2,linspace(0, app.CurrentIterationEditField.Value, app.CurrentIterationEditField.Value), distanceToAverage(agent,1:app.CurrentIterationEditField.Value));
                end
                
                hold(app.UIAxes2,'off');
                pause (0.2);
                
            end
            
            delete('SimulationResults.csv');
            writematrix(agentPositionHistory,'SimulationResults.csv');
            
        end

        % Button pushed function: StopButton
        function StopSimulation(app, event)
            app.CurrentIterationEditField.Value = app.NumberofIterationsEditField.Value;
        end

        % Button pushed function: ResetButton
        function ResetSimulation(app, event)
            app.UITable.Data = app.agentSetUp;
        end

        % Cell edit callback: UITable
        function SetUpChanged(app, event)
            app.agentSetUp = app.UITable.Data;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'A-Matrix';
            app.AMatricesLabel = uilabel(app.UIFigure);
            app.AMatricesLabel.Text = 'A Matrices';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAMatrix, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.UIFigure);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FileNameEditField_2Label = uilabel(app.UIFigure);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField_2.Value = 'Agent-Set-Up';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadAgentInfo, true);
            app.LoadButton_2.Text = 'Load';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Agent Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'};
            app.UITable.ColumnEditable = [true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @SetUpChanged, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Switch'};
            app.UITable2.RowName = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulation, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationsEditField.Value = 50;
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepEditField.Value = 0.2;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSimulation, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Distance From Average')
            xlabel(app.UIAxes2, 'Iteration')
            ylabel(app.UIAxes2, 'Distance')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formation_App_modified_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end