classdef Formation_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumberofAgentsEditField         matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        GraphTypeDropDownLabel          matlab.ui.control.Label
        GraphTypeDropDown               matlab.ui.control.DropDown
        AllowOffsetCheckBox             matlab.ui.control.CheckBox
        PlotButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SwitchesEditFieldLabel          matlab.ui.control.Label
        SwitchesEditField               matlab.ui.control.NumericEditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
    end


    methods (Access = private)

%This is a function accessible within the app. It is used to get our initial A matrix depending on which plot type   
%has been selected, and also to plot the current position of the system if the argument 'N' is 1. 
%This function has two nested functions used to get the A matrices, found at the bottom of this function. 

function A = plottingfunction(app, N)

%get some important stuff from the edit fields
dim = app.NumberofAgentsEditField.Value;
offset_check = app.AllowOffsetCheckBox.Value;
table_data = app.UITable.Data;

%%%%%%%%%%%%%%%%%% Evaluate What Type of Graph We're Doing %%%%%%%%%%%%%%%%%%%%%%
A = zeros(dim);
if offset_check == 0
    if strcmpi('cyclic', app.GraphTypeDropDown.Value)
        A = cyclegraph(dim);
        table_data = table_data(1:dim, 2:4);
    elseif strcmpi('path', app.GraphTypeDropDown.Value)
        A = pathgraph(dim);
        table_data = table_data(1:dim, 2:4);
    elseif strcmpi('switching', app.GraphTypeDropDown.Value)
        %A = [...]
        table_data = table_data(1:dim, 2:4);
    elseif strcmpi('custom', app.GraphTypeDropDown.Value)
        %A = [...]
        table_data = table_data(1:dim, 2:4);
    end
    
elseif offset_check == 1
    if strcmpi('cyclic', app.GraphTypeDropDown.Value)
        A = cyclegraph(dim);
        table_data = table_data(1:dim, 2:6);
    elseif strcmpi('path', app.GraphTypeDropDown.Value)
        A = pathgraph(dim);
        table_data = table_data(1:dim, 2:6);  
    elseif strcmpi('switching', app.GraphTypeDropDown.Value)
        %A = [...]    
        table_data = table_data(1:dim, 2:6);  
    elseif strcmpi('custom', app.GraphTypeDropDown.Value)
        %A = [...]
        table_data = table_data(1:dim, 2:6);
    end
end

%If we want to plot, (N = 1), then plot

if N == 1
    app.UIAxes.ColorOrderIndex = 1;
    for i = 1:dim
        if table_data(i,3) == Inf
            plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o','MarkerEdgeColor','c','MarkerFaceColor','c');
            x_axis_unit = (max(table_data(:,1)) - min(table_data(:,1))) / 14;
            y_axis_unit = (max(table_data(:,2)) - min(table_data(:,2))) / 14;
            text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) - y_axis_unit, sprintf('%d', i), 'color', 'k', 'fontweight', 'bold');
        else
            plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o');     
            hold(app.UIAxes, 'on')
            x_axis_unit = (max(table_data(:,1)) - min(table_data(:,1))) / 14;
            y_axis_unit = (max(table_data(:,2)) - min(table_data(:,2))) / 14;
            text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) - y_axis_unit, sprintf('%d', i), 'color', 'k', 'fontweight', 'bold');
        end
    dimension = app.NumberofAgentsEditField.Value;
    
    %drawing communication lines, won't happen for switching graphs
    for i = 1:dimension
        for j = 1:dimension
            if A(i,j) == 1
                line(app.UIAxes, [table_data(i,1),table_data(j,1)],[table_data(i,2),table_data(j,2)],'Color','k');
            end
        end
    end    
        
        
    app.UIAxes.XLim = [min(table_data(:,1)) - 1, max(table_data(:,1)) + 1];
    app.UIAxes.YLim = [min(table_data(:,2)) - 1, max(table_data(:,2)) + 1];
    hold(app.UIAxes, 'on')
    end 
end
 
%-----------------------Nested Functions---------------------------%

    function [A,L,V,D] = cyclegraph(dim)
    %This function makes a cycle graph using the number of agents in the
    %network. It returns the adjacency matrix, A, the Laplacian matrix, L, the
    %eigenvectors, V, and the eigenvalues, D.
    
    A = zeros(dim);
    
    for i = 1:dim
        for j = 1:dim
            if j == i+1 || i == j+1 || j == i-dim+1 || i == j-dim+1
                A(i,j) = 1;
            elseif j == 1
                A(i,j) = 0;
                %change this to 1 to allow self-loops
            else
                A(i,j) = 0;
            end
        end
    end
    
    D_out = zeros(dim);
    
    for i = 1:dim
        for j = 1:dim
            neighbor(j) = A(i,j);
        end
        diag(i) = sum(neighbor);
        D_out(i,i) = diag(i);
        neighbor = [];
    end
    
    L = D_out - A;
    [V,D] = eig(L);
    
    end


    function [A,L,V,D] = pathgraph(dim)
    %This function makes a path graph using the number of agents in the
    %network. It returns the adjacency matrix, A, the Laplacian matrix, L, the
    %eigenvectors, V, and the eigenvalues, D.
    
    A = zeros(dim);
    
        for i = 1:dim
            for j = 1:dim
                if j == i+1 || i == j+1
                    A(i,j) = 1;
                else
                    A(i,j) = 0;
                end
            end
        end
    
    D_out = zeros(dim);
    
        for i = 1:dim
            for j = 1:dim
                neighbor(j) = A(i,j);
            end
            diag(i) = sum(neighbor);
            D_out(i,i) = diag(i);
            neighbor = [];
        end
    
    L = D_out - A;
    [E,F] = eig(L);
    
    end

end 

%----------------------------------------------------------------------------------------%

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%Some default values that load upon startup
%Change this (AS WELL AS IN THE RESET SECTION) if you don't want to manually reset data each time 
%Data is of the form [x y Delay x-offset y-offset; ...];

%%%%%%% DEFAULT DATA %%%%%%%%%%
data = [1, -4, 2, 0, 0, 0;2, -2, 6, 0, 0, 0;3, 2, 6, 0, 0, 0;4, 4, 2, 0, 0, 0;5, 4, -2, 0, 0, 0;6, 2, -6, 0, 0, 0;7, -2, -6, 0, 0, 0;8, -4, -2, 0, 0, 0];        

app.UITable.Data = data;   
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%If the plot button is hit, call the plotting function. 
A = plottingfunction(app, 1);

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
        
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
%This is the main formation algorithm code that executes when the average button is pressed
            
cla(app.UIAxes)        

%Get information from data fields, and get adjacency matrix from local plotting function
table_data = app.UITable.Data(:, 2:6);
dimension = app.NumberofAgentsEditField.Value;
A = plottingfunction(app, 1);

%This checks whether the user wants to introduce an offset or not
offset_check = app.AllowOffsetCheckBox.Value;

%Checks the maximum number of time steps to allow the algorithm to execute for
MAX_i = app.MaxIterationsEditField.Value;

%This finds the averages of the initial table data so that the consensus
%values can be compared to the desired (average) values in the second plot
%(i.e. app.UIAxes2)
avg_x = mean(table_data(:,1));
avg_y = mean(table_data(:,2));
avg = [avg_x avg_y];

%This makes a temporary offset vector of zeros.
offset_temp = zeros(2*dimension,1);
t = 0;

%Start iteration counter at 1
app.CurrentIterationEditField.Value = 1;

%The while loop executes until we hit the maximum number of iterations is reached, the stop
%button is pressed, or the reset button is pressed
while(app.CurrentIterationEditField.Value > 0  && app.CurrentIterationEditField.Value <= MAX_i)

%This runs the entire consensus loop for the case of a switching graph.
%Look down at the else loop for consensus algorithm for cyclic, path and 
%custom graph types.

if strcmpi('Switching', app.GraphTypeDropDown.Value)
    
    %This is the # of switching topologies that the user defined in the GUI
    switch_num = app.SwitchesEditField.Value;
    
    %----------------------------------------------------------------------
    %This stores the adjacency matrices for the switching graph topologies
    %that were defined by the user in the Workspace.
    
    %For switching graphs, enter the matrices in the MATLAB command window
    %in the form A1 = [...;...;...], A2 = [...;...;...], etc.
    
    B = [];
    for w = 1:switch_num
        B(((w-1)*dimension + 1):dimension*w, 1:dimension) = ...
            (evalin('base', sprintf('A%d', w)));
    end
    
    %Stores position data from table in GUI in a row vector in the form
    %[x_1 y_1 x_2 y_2 ... x_n y_n]. NOTE HOW TABLE DATA IS STORED.
    for a = 1:dimension
        for b = 1:2
            X(2*a + b - 2) = table_data(a,b);
        end
    end
    
    %Transposes row vector to column vector x = [x_1; y_1; ... x_n; y_n]
    x = transpose(X);
    
    %This stores the offset data in the following way: offset_vector = 
    %[x_off_1 y_off_1 x_off_2 y_off_2 ... x_off_n y_off_n]
    offset_vector = zeros(length(X),1);
    if offset_check == 1
        for a = 1:dimension
            for b = 4:5
                offset_vector(2*a + b - 2) = table_data(a,b);
            end
        end
    end
    
    D_out = zeros(dimension);
    y = zeros(length(X),1);
    
    %----------------------------------------------------------------------
    %Insert Cost Function here to permute the offset_vector to minimize
    %cost of movement initially. Note that this has to be
    %done within the while loop as well (so cost is minimized on every
    %iteration). Your cost function should be in a
    %different function and should be called with something like this:
   
    %offset_vector = cost_fn(app, x,offset_vector)
    
    %Functions can be defined in MATLAB Script editor and called by
    %the app designer in the same manner you would normally call them
    %----------------------------------------------------------------------
    
    %This stores the delay in updating for each agent in a vector of the
    %form tau = [delay_1 delay_1 ... delay_n delay_n]
    tau = zeros(length(X),1);
    for q = 1: (length(X)) / 2
        tau(2*q) = table_data(q,3);
        tau(2*q - 1) = table_data(q,3);
    end
    
    %H is the history matrix which gets populated by the x and y positions
    %of each agent in the form H(:,1) = [x_1; y_1; ... x_n; y_n]
    H = x;
    
    %initializing variables for the while loop: i and j are used for 
    j = 1;
    t = 2;
    diff = zeros(1,dimension);
    count = 1;
    
    while(app.CurrentIterationEditField.Value > 0 && app.CurrentIterationEditField.Value <= MAX_i)
        for q = 1:switch_num
            A = B(((q-1)*dimension + 1):dimension*q, :);
            
            for c = 1:dimension
                for d = 1:dimension
                    neighbor(d) = A(c,d);
                end
                diag(c) = sum(neighbor);
                D_out(c,c) = diag(c);
                neighbor = [];
            end
            
            L = D_out - A;
            I = eye(2);
            
            
            % This sets an appropriate delta such that the consensus algorithm 
            %converges (delta is the time step between each interval)
            for s = 1:dimension
                d(s) = L(s,s);
            end
            delta_t = 1 / (max(d) + 1);
            
            %This is a Kronecker product to ensure that the dimensions of
            %the Laplacian matrix are extended so that the x vector (which
            %stores both (x,y) coordinates of each agent) multiplied by the
            %Laplacian is of proper size for matrix multiplication.
            L_kron = kron(L,I);
            
            
            for i = 1:length(X)
                if tau(i) == 0
                    %This is the consensus algo for regular updating (no
                    %delays)
                    x(i) = H(i,(t-1)) - delta_t * L_kron(i,:) * (H(:,t-1) - offset_vector);
                    j = 1;
                elseif t > tau(i) + 1 && mod(t, tau(i) + 2 ) == 0
                    %This is the consensus algo for delays in updating
                    x(i) = H(i,(t-tau(i))) - delta_t * L_kron(i,:) * (H(:,t-1) - offset_vector);
                    j = 1;
                else
                    %This is the case when we have a stubborn agent and
                    %tau(i) is set to Inf.
                    x(i) = x(i);
                end
                
            end
            
    %----------------------------------------------------------------------        
    %You can design a power restriction scale which allocates movement penalties
    %according to the weight of agents and their power rating (thus putting
    %a restriction on their allowable movement for one time iteration).
    %Your power restriction scale should be another function and should be
    %called with something like this:
    
    %x = power_restriction_scale(x,H(t-1),weights_of_agents)
    
    %----------------------------------------------------------------------        
    
    
    %----------------------------------------------------------------------        
    %You can design a perturbations for the agents in their x and y
    %directions to see if your model is robust to external perturbations
    
    %x = perturbations(x,perturbations_vector)
    
    %---------------------------------------------------------------------- 
    
    
    %----------------------------------------------------------------------
    %Insert Cost Function here to permute the offset_vector to minimize
    %cost of movement at every iteration. Your cost function should be in a
    %different function and should be called with something like this:
   
    %offset_vector = cost_fn(x,offset_vector)
    
    %----------------------------------------------------------------------
            
            %This increases the time step - this is used for plotting.
            t = t+1;
            app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
            for m = 1:dimension
                for n = 1:2
                    table_data(m,n) = x(2*m + n - 2);
                end
            end
            
            app.UITable.Data(:, 2:6) = table_data;
            
            %--------------------------------------------------------------
            %--------------------------------------------------------------
            %Plotting Section - Do Not Touch
            app.UIAxes.ColorOrderIndex = 1;
            cla(app.UIAxes)
            for i = 1:dimension
                if table_data(i,3) == Inf
                    plot(app.UIAxes1, table_data(i,1),table_data(i,2),'Marker','o','MarkerEdgeColor','c','MarkerFaceColor','c');
                    x_axis_unit = (max(table_data(:,1)) -  ...
                        min(table_data(:,1))) / 14;
                    y_axis_unit = (max(table_data(:,2)) - ...
                        min(table_data(:,2))) / 14;
                    text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) ...
                        - y_axis_unit, sprintf('%d', i), 'color', 'k', ...
                        'fontweight', 'bold');
                else
                    
                    plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o');
                    x_axis_unit = (max(table_data(:,1)) - ...
                        min(table_data(:,1))) / 14;
                    y_axis_unit = (max(table_data(:,2)) - ...
                        min(table_data(:,2))) / 14;
                    text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) ...
                        - y_axis_unit, sprintf('%d', i), 'color', 'k', ...
                        'fontweight', 'bold');
                end
                app.UIAxes.XLim = ([min(table_data(:,1)) - 1, max(table_data(:,1)) + 1]);
                app.UIAxes.YLim = ([min(table_data(:,2)) - 1, max(table_data(:,2)) + 1]);
            end
            app.UIAxes.ColorOrderIndex = 1;
              for i = 1:dimension
                for j = 1:dimension
                    if A(i,j) == 1
                        line(app.UIAxes, [table_data(i,1),table_data(j,1)],[table_data(i,2),table_data(j,2)],'Color','k');
                    end
                end
              end    
           app.UIAxes2.ColorOrderIndex = 1;
            for i = 1:2:2*dimension - 1
                z = count;
                diff(z,(i + 1) / 2) = pdist([x(i) x(i+1); avg]);
            end
            for i = 1:count
                plot(app.UIAxes2, linspace(0, count, count), diff);
                app.UIAxes2.XLim = [0 app.MaxIterationsEditField.Value];
            end
            %--------------------------------------------------------------
            %--------------------------------------------------------------
            
            %This history matrix horizontally concatenates the newly 
            %calculated positions for the agents after undergoing consensus
            %algo for one time step
            H = horzcat(H,x);

            
            %Increases count & puts pause so graphing can be observed in
            %app
            count = count + 1;
            pause(0.1);
            if q == switch_num
                q = 1;
            end
        end
    end
else
    %this loop is for cyclic, path and custom graph types. Comments are
    %similar to the switching graph case and will not be repeated here.
    
    %If you are using a custom adjacency matrix, the code will retrieve it 
    %from the MATLAB workspace. If your matrix is always the same, you could
    %just hard code it here to save yourself time. 
    if strcmpi(app.GraphTypeDropDown.Value, 'Custom')
        A = (evalin('base', 'A'));
    end 
    
    for a = 1:dimension
        for b = 1:2
            X(2*a + b - 2) = table_data(a,b);
        end
    end
    x = transpose(X);
    
    if offset_check == 1
            for a = 1:dimension
                for b = 1:2
                    offset_vector(2*a + b - 2) = table_data(a,b+3);
                end
            end
    else
        offset_vector = zeros(1, size(X,1));
    end
    
    offset_vector = transpose(offset_vector);
    
    D_out = zeros(dimension);
    
    for c = 1:dimension
        for d = 1:dimension
            neighbor(d) = A(c,d);
        end
        diag(c) = sum(neighbor);
        D_out(c,c) = diag(c);
        neighbor = [];
    end
    
    L = D_out - A;
    
    I = eye(2);
    y = zeros(length(X),1);
 
    %----------------------------------------------------------------------
    %Insert Cost Function here to permute the offset_vector to minimize
    %cost of movement initially. Your cost function should be in a
    %different function and should be called with something like this:
   
    %offset_vector = cost_fn(x,offset_vector)
    
    %----------------------------------------------------------------------

    %----------------------------------------------------------------------
    % This chooses appropriate delta such that the consensus algorithm 
    %converges
    
    for s = 1:dimension
        d(s) = L(s,s);
    end
    delta_t = 1 / (max(d) + 1);
    
    tau = zeros(length(X),1);
    for q = 1: (length(X)) / 2
        tau(2*q) = table_data(q,3);
        tau(2*q - 1) = table_data(q,3);
    end
    
    max_tau = max(tau);
    H = x;
    L_kron = kron(L,I);
    
    j = 1;
    t = 2;
    diff = zeros(1,dimension);
    count = 1;
    while (app.CurrentIterationEditField.Value <= (MAX_i)) 
        for i = 1:length(X)
            if tau(i) == 0
                x(i) = H(i,(t-1)) - delta_t * L_kron(i,:) * (H(:,t-1) -...
                    offset_vector);
                j = 1;
            elseif t > tau(i) + 1 && mod(t, tau(i) + 2 ) == 0
                x(i) = H(i,(t-tau(i))) - delta_t * L_kron(i,:) * ...
                    (H(:,t-1) - offset_vector);
                j = 1;
            else
                x(i) = x(i);
            end
            
        end
    %----------------------------------------------------------------------        
    %You can design a power restriction scale which allocates movement penalties
    %according to the weight of agents and their power rating (thus putting
    %a restriction on their allowable movement for one time iteration).
    %Your power restriction scale should be another function and should be
    %called with something like this:
    
    %x = power_restriction_scale(x,H(t-1),weights_of_agents)
    
    %----------------------------------------------------------------------        
    
    
    %----------------------------------------------------------------------        
    %You can design a perturbations for the agents in their x and y
    %directions to see if your model is robust to external perturbations
    
    %x = perturbations(x,perturbations_vector)
    
    %---------------------------------------------------------------------- 
    
    
    %----------------------------------------------------------------------
    %Insert Cost Function here to permute the offset_vector to minimize
    %cost of movement at every iteration. Your cost function should be in a
    %different function and should be called with something like this:
   
    %offset_vector = cost_fn(x,offset_vector)
    
    %----------------------------------------------------------------------
        
        t = t+1;
        app.CurrentIterationEditField.Value = app.CurrentIterationEditField.Value + 1;
        for m = 1:dimension
            for n = 1:2
                table_data(m,n) = x(2*m + n - 2);
            end
        end
        
        app.UITable.Data(:, 2:6) = table_data;
        
        %------------------------------------------------------------------
        %------------------------------------------------------------------
        %Plotting Section - Do Not Touch
        app.UIAxes.ColorOrderIndex = 1;
        cla(app.UIAxes);
        app.UIAxes.ColorOrderIndex = 1;
        for i = 1:dimension
            if table_data(i,3) == Inf
                plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o','MarkerFaceColor','c','MarkerEdgeColor','c','MarkerSize',5);
                x_axis_unit = (max(table_data(:,1)) - min(table_data(:,1))) / 14;
                y_axis_unit = (max(table_data(:,2)) - min(table_data(:,2))) / 14;
                text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) - y_axis_unit, sprintf('%d', i), 'color', 'k', 'fontweight', 'bold');
            else
                
                plot(app.UIAxes, table_data(i,1),table_data(i,2),'Marker','o');
                x_axis_unit = (max(table_data(:,1)) - min(table_data(:,1))) / 14;
                y_axis_unit = (max(table_data(:,2)) - min(table_data(:,2))) / 14;
                text(app.UIAxes, table_data(i,1) - x_axis_unit,table_data(i,2) - y_axis_unit, sprintf('%d', i), 'color', 'k', 'fontweight', 'bold');
            end
            app.UIAxes.XLim = ([min(table_data(:,1)) - 1, max(table_data(:,1)) + 1]);
            app.UIAxes.YLim = ([min(table_data(:,2)) - 1, max(table_data(:,2)) + 1]);
            hold(app.UIAxes, 'on')
        end
        
         for i = 1:dimension
                for j = 1:dimension
                    if A(i,j) == 1
                        line(app.UIAxes, [table_data(i,1),table_data(j,1)],[table_data(i,2),table_data(j,2)],'Color','k');
                    end
                end
         end   
         
        %Again, horizontally concatenating the positions of the agents in
        %the history matrix.
        H = horzcat(H,x);
         app.UIAxes.ColorOrderIndex = 1;
         for i = 1:2:(dimension*2-1)
               plot(app.UIAxes, H(i,:), H(i+1, :))
         end 
                
        
        app.UIAxes2.ColorOrderIndex = 1;
        for i = 1:2:2*dimension - 1
            z = count;
            diff(z,(i + 1) / 2) = pdist([x(i) x(i+1); avg]);
        end
        for i = 1:count
            plot(app.UIAxes2, linspace(0, count, count), diff);
            app.UIAxes2.XLim = [0 app.MaxIterationsEditField.Value];
        end
        hold(app.UIAxes,'off')
        %------------------------------------------------------------------
        %------------------------------------------------------------------


        count = count + 1;
        pause(0.1);
      
    end
end
end 
%Write history to excel spreadsheet with called filename. Column 1
%contains agent 1's x positions, column 2 contains agent 2's y
%positions, column 3 contains agent 2's x positions, and so on. The first
%row contains initial positions, the following rows containing the next
%position, and so on, until the final position.
delete agentData.xlsx
xlswrite('agentData.xlsx',transpose(H))
H = transpose(H);
save('agentData.mat','H');

% assignin('base','H', H);
% assignin('base','offset_vector',offset_vector);
% assignin('base','offset_temp',offset_temp);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        app.CurrentIterationEditField.Value = app.MaxIterationsEditField.Value + 1; %end loop if the stop button is pressed
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CurrentIterationEditField.Value = 0; 
            
            a = ones(app.NumberofAgentsEditField.Value, 1);
            for i = 1:app.NumberofAgentsEditField.Value
                a(i) = i;
            end
            
            agents = app.NumberofAgentsEditField.Value;
            app.UITable.Data = [a, randi([0 10], agents, 2), randi([0 3], agents, 1), zeros(agents, 2)]; 

            
            if app.AllowOffsetCheckBox.Value == 1
                app.UITable.Data(:, 5:6) = randi([0 3], agents, 2);
            end 
 
            cla(app.UIAxes) %Clear our graphs
            cla(app.UIAxes2)

        end

        % Value changed function: NumberofAgentsEditField
        function NumberofAgentsEditFieldValueChanged(app, event)
%If we have more than 8 agents, we need to add more rows to our edit field
            data = app.UITable.Data;
            n = app.NumberofAgentsEditField.Value;
            a = ones(n, 1);
            for i = 1:n
                a(i) = i;
            end
            
            %If we reduce number of agents, truncate bottom rows.
            %If we increase number of agents, add empty rows
            if n < size(data,1)
                app.UITable.Data = data(1:n, :);
            else
                h = size(data,1);
                newdata = [a, randi([0, 10],n,2), randi([0, 5],n, 1), zeros(n, 2)];
                data(h+1:n, :) = newdata(h+1:n, :);
                app.UITable.Data = data;
            end 

        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
%Notify the user to input the adjacency matrices if they've selected switching graphs        
        if strcmpi(app.GraphTypeDropDown.Value, 'Switching')
            f = uifigure;
            uiconfirm(f,'Please Enter the Adjacency Matrices in the Command Window in the form: A1 = [...], A2 = [...], etc, prior to pressing the "Play" button','Switching Type Selected', 'Icon', 'warning');
        end 

        if strcmpi(app.GraphTypeDropDown.Value, 'Custom')
            f = uifigure;
            uiconfirm(f,'Please Enter the Adjacency Matrix in the Command Window in the form: A = [...] prior to pressing the "Play" button','Switching Type Selected', 'Icon', 'warning');
        end 
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Agent Positions')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NumberofAgentsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAgentsEditField.Limits = [1 Inf];
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofAgentsEditFieldValueChanged, true);
            app.NumberofAgentsEditField.Value = 8;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Distance from Average')
            xlabel(app.UIAxes2, 'Time Steps')
            ylabel(app.UIAxes2, 'Distance')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Agent'; 'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.GraphTypeDropDownLabel = uilabel(app.UIFigure);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.UIFigure);
            app.GraphTypeDropDown.Items = {'Cyclic', 'Path', 'Switching', 'Custom'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Cyclic';
            app.AllowOffsetCheckBox = uicheckbox(app.UIFigure);
            app.AllowOffsetCheckBox.Text = 'Allow Offset';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SwitchesEditFieldLabel = uilabel(app.UIFigure);
            app.SwitchesEditFieldLabel.Text = 'Switches';
            app.SwitchesEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIterationEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditField.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Formation_App_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end