classdef Lloyd_App_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                          matlab.ui.Figure
        ArenaPlot                       matlab.ui.control.UIAxes
        CoveragePlot                    matlab.ui.control.UIAxes
        PointPlot                       matlab.ui.control.UIAxes
        CommPlot                        matlab.ui.control.UIAxes
        NumberofAgentsEditFieldLabel    matlab.ui.control.Label
        NumAgentsEditField              matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel        matlab.ui.control.Label
        SideLengthEditField             matlab.ui.control.NumericEditField
        PartitionNumberEditFieldLabel   matlab.ui.control.Label
        PartitionNumberEditField        matlab.ui.control.NumericEditField
        RadiusofObservationEditFieldLabel  matlab.ui.control.Label
        RadiusofObservationEditField    matlab.ui.control.NumericEditField
        RadiusofCommunicationEditFieldLabel  matlab.ui.control.Label
        RadiusofCommunicationEditField  matlab.ui.control.NumericEditField
        VelocityDropDownLabel           matlab.ui.control.Label
        VelocityDropDown                matlab.ui.control.DropDown
        VelocityEditField               matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        SimulationDurationEditFieldLabel  matlab.ui.control.Label
        SimulationDurationEditField     matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PositionTable                   matlab.ui.control.Table
        NameofCostFunction2EditFieldLabel  matlab.ui.control.Label
        NameofCostFunction2EditField    matlab.ui.control.EditField
        CostFunction2YAxisEditFieldLabel  matlab.ui.control.Label
        CostFunction2YAxisEditField     matlab.ui.control.EditField
        CurrentIterationEditFieldLabel  matlab.ui.control.Label
        CurrentIterationEditField       matlab.ui.control.NumericEditField
        DensityMatrixNameEditFieldLabel  matlab.ui.control.Label
        DensityMatrixNameEditField      matlab.ui.control.EditField
        SimulationControlsLabel         matlab.ui.control.Label
        DensityLabel                    matlab.ui.control.Label
        VelocityFieldLabel              matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        AgentsLabel                     matlab.ui.control.Label
        ArenaLabel                      matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorLabel                matlab.ui.control.Label
        InitialPositionFileEditFieldLabel  matlab.ui.control.Label
        InitialPositionFileEditField    matlab.ui.control.EditField
        LoadPositionsButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        isStopPushed % Holds state of stop button
    end

    methods (Access = private)
        
    % Draws a circle of specified radius around point (x_centre,y_centre)
    function [] = circle_Maker(app, x_centre,y_centre,rObs)
        theta = linspace(0,2*pi,100);
        x = rObs.*cos(theta) + x_centre;
        y = rObs.*sin(theta) + y_centre;
        plot(app.ArenaPlot,x,y,'magenta')
    end
    
    function [] = Plot_Assigned_Points(app, agentPoints, numAgents,...
        colours_energy,agentPositions,sides)
    
        for i = 1:numAgents
            hold(app.PointPlot, 'on')
            scatter(app.PointPlot, agentPoints{1,i}(:,1),agentPoints{1,i}(:,2),...
                'MarkerEdgeColor',colours_energy(i,:));
            hold(app.PointPlot, 'on')
            scatter(app.PointPlot, agentPositions(i,1),agentPositions(i,2),50,...
                colours_energy(i,:),'filled','diamond')
            label_agent_index = num2str(i);
            %t.FontSize = 15;
            text(app.PointPlot, agentPositions(i,1) + sides/65, agentPositions(i,2) + sides/65,...
                        label_agent_index);
            app.PointPlot.XLim = [0 app.SideLengthEditField.Value];
            app.PointPlot.YLim = [0 app.SideLengthEditField.Value];
        end
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Generates random positions upon startup
        numAgents = app.NumAgentsEditField.Value;
        sides = app.SideLengthEditField.Value;
        app.PositionTable.Data = rand(numAgents,2)*sides;
        end

        % Button pushed function: StartButton
        function startButtonPushed(app, event)
%% MAIN PROGRAM LOOP %%
tic;
close('all')
app.isStopPushed = false;

%% Get UI Values %%
sides = app.SideLengthEditField.Value;
partitions = app.PartitionNumberEditField.Value;
agentPositions = app.PositionTable.Data;
numAgents = app.NumAgentsEditField.Value;
maxVelocity = app.VelocityEditField.Value;
velocityScaleFactor = app.ScaleFactorEditField.Value;
velocityType = app.VelocityDropDown.Value;
duration = app.SimulationDurationEditField.Value;
rObs = app.RadiusofObservationEditField.Value;
rComm = app.RadiusofCommunicationEditField.Value;
delay = app.DelayEditField.Value;
app.CurrentIterationEditField.Value = 1;
%% END Get UI Values %%

% Generate colours for energy example (change/remove?)
for i = 1:numAgents
    colours_energy(i,:) = rand(1,3);
end

%% Load density matrix %%
densityFileName = app.DensityMatrixNameEditField.Value;
if isfile(densityFileName)
    try
        densityFile = load(densityFileName);
        syms D(t)
        D = str2sym(densityFile.matrix);
        % Ensure density function is in terms of only t
        if ~(isequal(symvar(D), t) || isempty(symvar(D)))
            error('Bad density matrix: The entries of your density matrix are invalid')
        end
    catch ME
        error(['Bad density matrix: ' densityFileName ' does not contain the variable ''matrix''. ' ...
            'Ensure you are using the Matrix Editor app to create a density matrix'])
    end
else
    error('Bad density matrix: File does not exist')
end
if size(D) ~= partitions*sides*ones(1,2)
    error('Bad density matrix: Dimensions of density matrix must equal sides*partitions')
end
%% END Load density matrix %%

%% Agent Positions setup %%
agentPositions = agentPositionFix(agentPositions,partitions,sides);
%% END Agent Positions setup %%

%% Dynamic var setup %%
iteration = 1;
totalMass = sum(sum(subs(D,t,iteration)));
% Turn symbolic D to currentDensity matrix
currentDensity = subs(D,t,iteration);
%% END Dynamic var setup %%

%% Stats setup %%
agentPositionHistory = [];
for i = 1 : numAgents
    agentPositionHistory(1:1,(2*i-1):2*i) = agentPositions(i,1:2);
end
% coverage is a column vector of percent of total arena observed during each iteration
coverage = [];
% distanceTravelled is a column vector of the total distance travelled by all agents
% in each iteration
distanceTravelled = zeros(duration,1);
% Keep track of density over time
densityHistory = currentDensity;
%% END Stats setup %%

%% Energy %%
E = 100*ones(1,numAgents);
%% END Energy %%

% Not sure what these do yet
syms x y z
%If you want contours, then you need variable density!!
[yy,xx] = meshgrid(linspace(0,sides,sides*partitions)); 
disp('Setup complete')
toc
%----------------------------------------- Loop -----------------------------------------------------%
while (iteration <= duration) && ~app.isStopPushed

% Update Current Iteration field
app.CurrentIterationEditField.Value = iteration;
tic;
% Calculate agent communication cells and adjacency matrix
%% GIVE TO STUDENTS?
[commCells, adjMatrix] = communication(agentPositions,rComm);
disp('communication complete')
toc
tic;
%% Plot Communication %%
%% TODO: Revise/optimize
% Clear old plots
cla(app.ArenaPlot)
cla(app.CoveragePlot)
cla(app.PointPlot)
cla(app.CommPlot)

n_connected_subgraphs = size(commCells,2);
close('all')    
% This is a 'first iteration' thing. Clean up?
if iteration == 1
    for i = 1:n_connected_subgraphs
        colours(i,:) = rand(1,3);
    end
end

for i = 1:size(agentPositions,1)
    scatter(app.CommPlot,agentPositions(:,1),agentPositions(:,2));
    label_agent_index = num2str(i);
    text(app.CommPlot, agentPositions(i,1) + sides/65, agentPositions(i,2) + sides/65,...
        label_agent_index);
    hold(app.CommPlot, 'on')
end
for i = 1:n_connected_subgraphs
    for j = 1:size(commCells{1,i},1)
        for k = 1:size(commCells{1,i},1)
            if adjMatrix(commCells{1,i}(j,1),commCells{1,i}(k,1)) == 1 
                    x1 = agentPositions(commCells{1,i}(j,1),1);
                    y1 = agentPositions(commCells{1,i}(j,1),2);
                    x2 = agentPositions(commCells{1,i}(k,1),1);
                    y2 = agentPositions(commCells{1,i}(k,1),2);
                    hold(app.CommPlot, 'on')
                    plot(app.CommPlot,[x1 x2],[y1 y2],'Color',colours(i,:));
                    hold(app.CommPlot, 'on')
            end
        end
    end
end 
%% END Plot Communication %%
disp('comm plot complete')
toc
tic;
%% Points, Mass, and Coverage Calculation %%
% Assign points to agents to generate their observed region
agentPoints = assignAgentPoints(agentPositions, commCells, sides,...
    partitions, rObs);
% Calculate mass of each observed region
mass = calcMass(agentPoints, currentDensity, partitions);

% Keep track of coverage stat
coverage = horzcat(coverage, 100*sum(mass)/totalMass);
%% END Points, Mass, and Coverage Calculation %%
disp('pts, mass, cvg complete')
toc
tic;
%% Plot Coverage %%
%% TODO: Revise/optimize
independent = 1:(iteration); % for plotting the coverage figure
yyaxis(app.CoveragePlot, 'left')
plot(app.CoveragePlot,independent, coverage, 'b');
hold(app.CoveragePlot, 'on')
%% End Plot Coverage %%
disp('coverage plot complete')
toc
tic;
%% Centroids and Movement %%
% Calculate centroids of each region for agents to move towards
centroids = calcCentroids(agentPoints, mass, currentDensity, agentPositions,...
    partitions);
% Move the agents
[agentPositions,distanceChange,E] = moveAgents(agentPositions,...
    centroids, E, velocityType, maxVelocity, velocityScaleFactor);
if iteration == 1
    distanceTravelled(1,1) = distanceChange;
else
    distanceTravelled(iteration,1) = distanceTravelled(iteration-1,1) + distanceChange;
end
disp('centroids, movement complete')
toc
% Positions stat
%agentPositionHistory = vertcat(agentPositionHistory, reshape(agentPositions,1,numAgents*2));
%% END Centroids and Movement %%

%% Plot Others %%
%% TODO: Optimize/revise
% for i = 1:numAgents % Plot Agents and centroids
% 
%     hold(app.ArenaPlot, 'on')
%     scatter(app.ArenaPlot,agentPositions(i,1),...
%         agentPositions(i,2),90,'blue','filled');
%     app.ArenaPlot.XLim = [0 sides];
%     app.ArenaPlot.YLim = [0 sides];
%     label_agent_index = num2str(i);
%     %t.FontSize = 20;
%     text(app.ArenaPlot, agentPositions(i,1) + sides/65, agentPositions(i,2) + sides/65,...
%         label_agent_index);
%     hold(app.ArenaPlot, 'on')
%     scatter(app.ArenaPlot,centroids(i,1),centroids(i,2),50,'red','filled');  
%     hold(app.ArenaPlot, 'on')
%     
%     theta = linspace(0,2*pi,100);
%     x = rObs.*cos(theta) + agentPositions(i,1);
%     y = rObs.*sin(theta) + agentPositions(i,2);
%     
%     plot(app.ArenaPlot,x,y,'magenta')
% 
% circle_Maker(app, agentPositions(i,1), agentPositions(i,2),rObs); 
% end
% 
app.PositionTable.Data(:,1:2) = agentPositions;    
% 
% hold(app.ArenaPlot, 'on')
% voronoi(app.ArenaPlot,agentPositions(:,1),agentPositions(:,2));
% hold(app.ArenaPlot, 'on')
% [contour_plot, h] = contour(app.ArenaPlot,xx,yy,currentDensity,4); % h is used for line width
% set(h,'linewidth',2);
% str = app.NameofCostFunction2EditField.Value;
% stra = 'Coverage and ';
% str = [stra str];
% independent = 1:iteration; % for plotting cost function 2 (distance travelled)
% yyaxis(app.CoveragePlot, 'right') 
% ylabel(app.CoveragePlot, app.CostFunction2YAxisEditField.Value);
% title(app.CoveragePlot, str)
% plot(app.CoveragePlot,independent, distanceTravelled, 'r');
% hold(app.CoveragePlot, 'off')
% 
% hold(app.PointPlot, 'on')
% Plot_Assigned_Points(app, agentPoints,numAgents,colours_energy,...
%     agentPositions,sides);
% hold(app.PointPlot, 'on')
% pause(0.1);
%% END Plot Others %%
tic;
iteration = iteration + 1;

%% Density Variation %%
if iteration <= duration
    cla(app.ArenaPlot);
    clf(app.ArenaPlot);
    % Check if we update density this iteration
    if mod(iteration,delay) == 0
        currentDensity = subs(D,t,iteration);
        totalMass = sum(sum(currentDensity));
    end
    %densityHistory = vertcat(densityHistory, currentDensity);
end
%% END Density Variation
disp('density variation complete')
toc;
end
%% END OF LOOP

% Save data and stuff
% TODO: Change to csv, make saved info more useful
close('all')
% History2 = agentPositionHistory; 
% delete agentData.xlsx
% xlswrite('agentData.xlsx',agentPositionHistory,1)
% xlswrite('agentData.xlsx',History2,2);
% xlswrite('agentData.xlsx',coverage',3);
% xlswrite('agentData.xlsx',distanceTravelled',4);
% xlswrite('agentData.xlsx',densityHistory, 5);
% Extra_info_matrix = zeros(1,3);
% Extra_info_matrix(1,1) = rObs;
% Extra_info_matrix(1,2) = rComm;
% xlswrite('agentData.xlsx', Extra_info_matrix, 6);
% H = agentPositionHistory;
% save('agentData.mat','H');

   
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
        % Updates state of isStopPushed to end simulation
        app.CurrentIterationEditField.Value = 0;
        app.isStopPushed = true;
        end

        % Value changed function: NumAgentsEditField
        function numAgentsChanged(app, event)
            % Adds/removes agents upon numAgents changing
            numAgents = app.NumAgentsEditField.Value;
            oldNumAgents = size(app.PositionTable.Data,1);
            delta = numAgents - oldNumAgents;
            sides = app.SideLengthEditField.Value;
            % If adding agents
            if delta > 0
                app.PositionTable.Data = vertcat(app.PositionTable.Data, rand(delta,2)*sides);
            % If removing agents
            else
                app.PositionTable.Data = app.PositionTable.Data(1:numAgents,:);
            end
        end

        % Value changed function: SideLengthEditField
        function sidesChanged(app, event)
            % Filter any invalid positions via logical indexing
            sides = app.SideLengthEditField.Value;
            positions = app.PositionTable.Data;
            positions(positions>sides) = sides;
            app.PositionTable.Data = positions;
        end

        % Value changed function: VelocityDropDown
        function velocityTypeChanged(app, event)
            value = app.VelocityDropDown.Value;
            % The Velocity edit field acts in different ways depending on
            % Velocity type, and the label reflects that
            if strcmp(value, 'Constant Velocity')
                app.VelocityFieldLabel.Text = 'Velocity';
                set(app.ScaleFactorEditField,'Enable',false,'Editable',false,'Value',1);
                set(app.ScaleFactorLabel,'Enable',false);
            else
                app.VelocityFieldLabel.Text = 'Max Velocity';
                set(app.ScaleFactorEditField,'Enable',true,'Editable',true);
                set(app.ScaleFactorLabel,'Enable',true);
            end
        end

        % Button pushed function: LoadPositionsButton
        function loadPositions(app, event)
            % Loads initial positions from the file specified
            % Stores them in the position table
            positionFileName = app.InitialPositionFileEditField.Value;
            numAgents = app.NumAgentsEditField.Value;
            sides = app.SideLengthEditField.Value;
            if isfile(positionFileName)
                try
                    positionFile = load(positionFileName);
                    positions = str2double(positionFile.matrix);
                    % Ensure there are enough rows in position matrix
                    if numAgents > size(positions,1)
                        error(['Bad position matrix: Not enough rows for ' numAgents ' agents'])
                    % Ensure there are x,y coordinates for all agents
                    elseif size(positions,2) ~= 2
                        error('Bad position matrix: Matrix must have 2 columns in x, y format')
                    % Ensure no NaN values in positions
                    elseif sum(sum(isnan(positions)))
                        error('Bad position matrix: NaN values not allowed')
                    else
                        % Filter all invalid positions via logical indexing
                        positions(positions<0) = 0;
                        positions(positions>sides) = sides;
                        app.PositionTable.Data = positions;
                    end
                catch ME
                    disp(ME)
                    error(['Bad position matrix: ' positionFileName ' does not contain the variable ''matrix''. ' ...
                        'Ensure you are using the Matrix Editor app to create a position matrix'])
                end
            else
                error('Bad position matrix: File does not exist')
            end
        end

        % Cell edit callback: PositionTable
        function positionChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            sides = app.SideLengthEditField.Value;
            % Filter new value
            if newData > sides
                newData = sides;
            elseif newData < 0 || isnan(newData)
                newData = 0;
            end
            app.PositionTable.Data(indices(1),indices(2)) = newData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Lloyd''s Deployment Algorithm';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.CoveragePlot = uiaxes(app.Window);
            title(app.CoveragePlot, 'Coverage')
            xlabel(app.CoveragePlot, 'Iteration Number')
            ylabel(app.CoveragePlot, 'Percent Covered')
            app.PointPlot = uiaxes(app.Window);
            title(app.PointPlot, 'Assigned Points')
            app.CommPlot = uiaxes(app.Window);
            title(app.CommPlot, 'Communication Graph')
            xlabel(app.CommPlot, 'X')
            ylabel(app.CommPlot, 'Y')
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumAgentsEditField.Value = 10;
            app.SideLengthEditFieldLabel = uilabel(app.Window);
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.Window, 'numeric');
            app.SideLengthEditField.ValueChangedFcn = createCallbackFcn(app, @sidesChanged, true);
            app.SideLengthEditField.Value = 5;
            app.PartitionNumberEditFieldLabel = uilabel(app.Window);
            app.PartitionNumberEditFieldLabel.Text = 'Partition Number';
            app.PartitionNumberEditField = uieditfield(app.Window, 'numeric');
            app.PartitionNumberEditField.Value = 10;
            app.RadiusofObservationEditFieldLabel = uilabel(app.Window);
            app.RadiusofObservationEditFieldLabel.Text = 'Radius of Observation';
            app.RadiusofObservationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofObservationEditField.Value = 2;
            app.RadiusofCommunicationEditFieldLabel = uilabel(app.Window);
            app.RadiusofCommunicationEditFieldLabel.Text = 'Radius of Communication';
            app.RadiusofCommunicationEditField = uieditfield(app.Window, 'numeric');
            app.RadiusofCommunicationEditField.Value = 3;
            app.VelocityDropDownLabel = uilabel(app.Window);
            app.VelocityDropDownLabel.Text = 'Velocity';
            app.VelocityDropDown = uidropdown(app.Window);
            app.VelocityDropDown.Items = {'Constant Velocity', 'Proportional Velocity'};
            app.VelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @velocityTypeChanged, true);
            app.VelocityDropDown.Value = 'Constant Velocity';
            app.VelocityEditField = uieditfield(app.Window, 'numeric');
            app.VelocityEditField.Value = 1;
            app.DelayEditFieldLabel = uilabel(app.Window);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.Window, 'numeric');
            app.DelayEditField.Value = 5;
            app.SimulationDurationEditFieldLabel = uilabel(app.Window);
            app.SimulationDurationEditFieldLabel.Text = 'Simulation Duration';
            app.SimulationDurationEditField = uieditfield(app.Window, 'numeric');
            app.SimulationDurationEditField.Value = 50;
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PositionTable = uitable(app.Window);
            app.PositionTable.ColumnName = {'X'; 'Y'};
            app.PositionTable.ColumnEditable = [true true];
            app.PositionTable.CellEditCallback = createCallbackFcn(app, @positionChanged, true);
            app.NameofCostFunction2EditFieldLabel = uilabel(app.Window);
            app.NameofCostFunction2EditFieldLabel.Text = 'Name of Cost Function 2';
            app.NameofCostFunction2EditField = uieditfield(app.Window, 'text');
            app.NameofCostFunction2EditField.Value = 'Energy';
            app.CostFunction2YAxisEditFieldLabel = uilabel(app.Window);
            app.CostFunction2YAxisEditFieldLabel.Text = 'Cost Function 2 Y-Axis';
            app.CostFunction2YAxisEditField = uieditfield(app.Window, 'text');
            app.CostFunction2YAxisEditField.Value = 'Distance Travelled';
            app.CurrentIterationEditFieldLabel = uilabel(app.Window);
            app.CurrentIterationEditFieldLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Editable = 'off';
            app.CurrentIterationEditField.Value = 1;
            app.DensityMatrixNameEditFieldLabel = uilabel(app.Window);
            app.DensityMatrixNameEditFieldLabel.Text = 'Density Matrix Name';
            app.DensityMatrixNameEditField = uieditfield(app.Window, 'text');
            app.DensityMatrixNameEditField.Value = 'density.mat';
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.DensityLabel = uilabel(app.Window);
            app.DensityLabel.Text = 'Density';
            app.VelocityFieldLabel = uilabel(app.Window);
            app.VelocityFieldLabel.Text = 'Velocity';
            app.VelocityLabel = uilabel(app.Window);
            app.VelocityLabel.Text = 'Velocity';
            app.AgentsLabel = uilabel(app.Window);
            app.AgentsLabel.Text = 'Agents';
            app.ArenaLabel = uilabel(app.Window);
            app.ArenaLabel.Text = 'Arena';
            app.ScaleFactorEditField = uieditfield(app.Window, 'numeric');
            app.ScaleFactorEditField.Editable = 'off';
            app.ScaleFactorEditField.Enable = 'off';
            app.ScaleFactorEditField.Value = 1;
            app.ScaleFactorLabel = uilabel(app.Window);
            app.ScaleFactorLabel.Enable = 'off';
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.InitialPositionFileEditFieldLabel = uilabel(app.Window);
            app.InitialPositionFileEditFieldLabel.Text = 'Initial Position File';
            app.InitialPositionFileEditField = uieditfield(app.Window, 'text');
            app.InitialPositionFileEditField.Value = 'positions.mat';
            app.LoadPositionsButton = uibutton(app.Window, 'push');
            app.LoadPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @loadPositions, true);
            app.LoadPositionsButton.Text = 'Load Positions';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lloyd_App_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end