classdef OpinionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                     matlab.ui.Figure
        NodeDataTable              matlab.ui.control.Table
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        NumberofNodesLabel         matlab.ui.control.Label
        NumNodesEditField          matlab.ui.control.NumericEditField
        DimensionsButtonGroup      matlab.ui.container.ButtonGroup
        One                        matlab.ui.control.RadioButton
        Two                        matlab.ui.control.RadioButton
        NodeTrajectoryPlot         matlab.ui.control.UIAxes
        SimulationDurationLabel    matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        CurrentIterationLabel      matlab.ui.control.Label
        CurrentIterationEditField  matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel     matlab.ui.control.Label
        TimeStepEditField          matlab.ui.control.NumericEditField
        SimulationControlsLabel    matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        AgentInformationLabel      matlab.ui.control.Label
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        NodeLocationPlot           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function saveSimulationData(~,nodeDataHistory, f)
            ssName = 'nodeData.xlsx';
            matName = 'nodeData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            nodeData = nodeDataHistory(1:f,:);
      
            save(matName,'nodeData');
            writematrix(nodeData,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
       end
        
        function toggleDimensionUI(app, isOneDimensional)
            if isOneDimensional
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','Position',...
                    'Left Noise','Right Noise'}');
                app.One.Value = true;
                app.Two.Value = false;
                % Show trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',true);
                % Decrease location plot size
                set(app.NodeLocationPlot,'Position',[12 208 300 185]);
            else
                % Change column headers
                set(app.NodeDataTable,'ColumnName',{'Radius of Comm.','X Position',...
                    'Y Position'});
                app.One.Value = false;
                app.Two.Value = true;
                % Hide trajectory plot
                set(app.NodeTrajectoryPlot,'Visible',false);
                % Increase location plot size
                set(app.NodeLocationPlot,'Position',[12 15 300 378]);
            end
        end
        
        % Works for both 1D and 2D by assuming that nodePosition is n-by-2
        % and the calling function substitutes zeros for y in 1D case
        function plotLocation(app,nodePositions,rComms,colors,numNodes)
            % Text offset
            delta = 1/10;
            % Clear old plot
            cla(app.NodeLocationPlot);
            hold(app.NodeLocationPlot,'on');
            
            % Plot the location of each node
            scatter(app.NodeLocationPlot,nodePositions(:,1),nodePositions(:,2),36,colors,'Marker','o');
            
            % Plot circles around each agent to reflect an agents radius of communication
            pos = [nodePositions(:,1)-rComms nodePositions(:,2)-rComms 2*rComms 2*rComms];
            for node = 1:numNodes
                rectangle(app.NodeLocationPlot,'Position',pos(node,:),'Curvature',[1 1],'EdgeColor',colors(node,:));
            end
            
            % Adding node labels
            text(app.NodeLocationPlot, nodePositions(:,1)+delta, nodePositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numNodes)'),'UniformOutput',false));
            
            hold(app.NodeLocationPlot,'off')
        end
        
        function plotTrajectory(app,nodePaths,numNodes,iteration,timeStep,colors)
            delta = -(iteration*timeStep)/10;
            app.NodeTrajectoryPlot.ColorOrderIndex = 1;
            cla(app.NodeTrajectoryPlot);
            hold(app.NodeTrajectoryPlot,'on')
            % Trim node paths
            nodePaths = nodePaths(1:iteration,:);
            % Must plot in loop to get proper colors...
            for i = 1:numNodes
                plot(app.NodeTrajectoryPlot, timeStep*(0:iteration-1), nodePaths(:,i),...
                    '-o','MarkerIndices',iteration,'Color',colors(i,:));
            end
            text(app.NodeTrajectoryPlot, (timeStep*(iteration-1)+delta)*ones(numNodes,1),...
                nodePaths(end,:)'+delta, cellfun(@num2str,num2cell((1:numNodes)'),'UniformOutput',false));
        
            hold(app.NodeTrajectoryPlot, 'off') 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
            % Initialize table data
            app.NodeDataTable.Data = zeros(5,4);
            app.NumNodesEditField.Value = 5;
        end

        % Button pushed function: StopButton
        function stopButtonPushed(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NumNodesEditField
        function numNodesChanged(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            % New total number of agents
            newNumNodes = app.NumNodesEditField.Value; 
            
            % Old total number of agents
            oldNumNodes = size(app.NodeDataTable.Data,1);
            delta = newNumNodes - oldNumNodes;
            
            % If adding nodes
            if delta > 0
                app.NodeDataTable.Data = vertcat(app.NodeDataTable.Data, zeros(delta,5));
            else
                app.NodeDataTable.Data = app.NodeDataTable.Data(1:newNumNodes,:);
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
        % Main simulation code
        app.isStopPushed = false;
        
        %% Variable Initialization %%
        iteration = 1;
        duration = app.DurationEditField.Value;
        timeStep = app.TimeStepEditField.Value;
        isOneDim = app.One.Value;
        % All current node data
        % Note that if app is 1D, nodeData is n-by-4 (rComm,pos,lNoise,rNoise)
        % If app is 2D, nodeData is n-by-3 (rComm,xPos,yPos)
        nodeData = app.NodeDataTable.Data;
        numNodes = app.NumNodesEditField.Value;
        nodeColors = rand(numNodes,3);
        %% END Variable Initialization %%
        
        %% Stats Initialization %%
        nodeDataHistory = zeros(app.DurationEditField.Value, numel(nodeData));
        nodeDataHistory(1,:) = reshape(nodeData',1,[]);
        %% END Stats Initialization %%
        
        %% Main Loop %%
        while iteration < duration && ~app.isStopPushed
            % Update UI iteration counter
            app.CurrentIterationEditField.Value = iteration;
            
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            A = calcA(nodeData);
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            L = calcL(A);
            
            % STUDENT MADE FUNCTION to update the Node Data. Must update
            % Node Positions, but can also update radii of communication
            % and left and right offsets with the introduction of cost
            % functions
            nodeData = updateNodeData(nodeData,L,timeStep,iteration);
            
            iteration = iteration + 1;
            
            %% Stats %%
            nodeDataHistory(iteration,:) = reshape(nodeData',1,[]);
            %% END Stats %%
            
            %% Plotting %%
            app.NodeDataTable.Data = nodeData;
            % If simulation is one dim, pad y location with zeros and plot
            % trajectory
            if isOneDim
                plotLocation(app,cat(2,nodeData(:,2),zeros(numNodes,1)),nodeData(:,1),...
                    nodeColors,numNodes);
                plotTrajectory(app, nodeDataHistory(:,2:4:numNodes*4), numNodes,...
                    iteration, timeStep, nodeColors);
            else
                plotLocation(app,nodeData(:,2:3),nodeData(:,1),nodeColors,numNodes)
            end
            %% END Plotting %%
            
            pause(0.2);
        end
        % Update last iteration value
        app.CurrentIterationEditField.Value = iteration;
        
        % Save simulation Results
        saveSimulationData(app, nodeDataHistory, iteration);
        end

        % Button pushed function: LoadButton
        function loadNodeInfo(app, event)
            filename = app.FilenameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load dimension
                    isOneDimensional = file.isOneDimensional;
                    % Load data
                    app.NodeDataTable.Data = file.matrix;
                    % Set num agents edit field
                    app.NumNodesEditField.Value = size(app.NodeDataTable.Data,1);
                    % Set UI based on dimension
                    toggleDimensionUI(app, isOneDimensional);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Selection changed function: DimensionsButtonGroup
        function dimensionChanged(app, event)
            isOneDim = app.One.Value;
            selection = uiconfirm(app.Window,'This could delete table data. Continue?', 'Confirm Change',...
                    'Options',{'Yes', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
            switch (selection)
                case 'Yes'
                    oldData = app.NodeDataTable.Data;
                    % If it was 2D and is now 1D
                    if isOneDim
                        % Keep rComm,xPos, add left/right noise columns
                        toggleDimensionUI(app, true);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),2));
                    % If it was 1D and is now 2D
                    else
                        % Keep rComm,Pos, add y Pos
                        toggleDimensionUI(app, false);
                        app.NodeDataTable.Data = cat(2,oldData(:,1:2),zeros(size(oldData,1),1));
                    end
                case 'Cancel'
                    % Revert UI to previous state
                    toggleDimensionUI(app, ~isOneDim);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Opinion Algorithm';
            app.NodeDataTable = uitable(app.Window);
            app.NodeDataTable.ColumnName = {'Radius of Comm.'; 'Position'; 'Left Noise'; 'Right Noise'};
            app.NodeDataTable.ColumnEditable = [true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.NumberofNodesLabel = uilabel(app.Window);
            app.NumberofNodesLabel.Text = 'Number of Nodes';
            app.NumNodesEditField = uieditfield(app.Window, 'numeric');
            app.NumNodesEditField.ValueChangedFcn = createCallbackFcn(app, @numNodesChanged, true);
            app.NumNodesEditField.Value = 5;
            app.DimensionsButtonGroup = uibuttongroup(app.Window);
            app.DimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimensionChanged, true);
            app.DimensionsButtonGroup.Title = 'Simulation Dimension(s)';
            app.One = uiradiobutton(app.DimensionsButtonGroup);
            app.One.Text = '1';
            app.One.Value = true;
            app.Two = uiradiobutton(app.DimensionsButtonGroup);
            app.Two.Text = '2';
            app.NodeTrajectoryPlot = uiaxes(app.Window);
            title(app.NodeTrajectoryPlot, 'Node Trajectory')
            xlabel(app.NodeTrajectoryPlot, 'Time')
            ylabel(app.NodeTrajectoryPlot, 'Location')
            app.SimulationDurationLabel = uilabel(app.Window);
            app.SimulationDurationLabel.Text = 'Simulation Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.CurrentIterationLabel = uilabel(app.Window);
            app.CurrentIterationLabel.Text = 'Current Iteration';
            app.CurrentIterationEditField = uieditfield(app.Window, 'numeric');
            app.CurrentIterationEditField.Limits = [0 Inf];
            app.CurrentIterationEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.Window);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.Window, 'numeric');
            app.TimeStepEditField.Value = 0.2;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadNodeInfo, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FilenameEditFieldLabel = uilabel(app.Window);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Window, 'text');
            app.FilenameEditField.Value = 'agentInfo.mat';
            app.NodeLocationPlot = uiaxes(app.Window);
            title(app.NodeLocationPlot, 'Node Location')
            xlabel(app.NodeLocationPlot, 'X')
            ylabel(app.NodeLocationPlot, 'Y')
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpinionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end