classdef Opinion_App_modified < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NodeInformation              matlab.ui.control.Table
        RunButton                    matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        Nodes                        matlab.ui.control.NumericEditField
        DimensionButtonGroup         matlab.ui.container.ButtonGroup
        OneD                         matlab.ui.control.RadioButton
        TwoD                         matlab.ui.control.RadioButton
        NodeLocationPlot             matlab.ui.control.UIAxes
        NodeTrajectoryPlot           matlab.ui.control.UIAxes
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumIterations                matlab.ui.control.NumericEditField
        CurrentIterationLabel        matlab.ui.control.Label
        CurrentIteration             matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel       matlab.ui.control.Label
        TimeStep                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % X, Y, Radius, Left Noise and Right Noise for each node in the
        % simulation
        nodeInformation; 
    end
    
    methods (Access = private)
        
        function saveSimulationData(~,nodeDataHistory, f)
            ssName = 'nodeData.xlsx';
            matName = 'nodeData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            nodeData = nodeDataHistory(1:f,:);
      
            save(matName,'nodeData')
            writematrix(nodeData,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.CurrentIteration.Value = app.NumIterations.Value + 1;
        end

        % Value changed function: Nodes
        function NumNodesChanged(app, event)
            % This function adjusts the number of rows in the agent
            % position and velocity information so it refelcts the new
            % number of agents to be in the simulation.
            
            % New total number of agents 
            numNewNodes = app.Nodes.Value; 
            
            % Old total number of agents
            numOldNodes = size(app.NodeInformation.Data,1);      
            
            % Updates the number of rows in the agent position and velocity
            % table to properly reflect the changes
            if numOldNodes < numNewNodes
                app.nodeInformation = vertcat(app.nodeInformation, zeros(numNewNodes-numOldNodes,5));
            else
                app.nodeInformation = app.nodeInformation(1:numNewNodes,:);
            end
            
            % Updates the Agent information data in the table
            app.NodeInformation.Data = app.nodeInformation;
            
        end

        % Cell edit callback: NodeInformation
        function NodeInformationUpdated(app, event)
        % This function saves any changes made to the node information table and stores it to
        % global 'nodeData' variable
        app.nodeInformation = app.NodeInformation.Data;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        % This function runs the opinion dynamics simulation using the user defined data
        
        % Resets the Current Iteration Value for the start of the simulation
        app.CurrentIteration.Value = 1;
        % Iteration counter variable used in while loop
        iteration = 1;
        % Reads in the timestep to be used in the simulation
        timeStep = app.TimeStep.Value;
        % Spacer for ensuring number label for each node is offset from the plotted point
        delta = 1/20;
        
        % Storing the Node information for the current iteration in the simulation
        nodeData = app.nodeInformation;
        
        % Storing the node information from each iteration in the simulation to be exported 
        % in an excel spreadsheet upon simulation completion
        nodeDataHistory = zeros(app.NumIterations.Value, 5*size(nodeData,1));
        nodeDataHistory(1,:) = reshape(nodeData',1,[]);
        
        % While Loop that runs the simulation
        while app.CurrentIteration.Value < app.NumIterations.Value
            
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            A = CalculateA(nodeData);
            % STUDENT MADE FUNCTION to calculate the Adjacency Matrix using Node Data
            L = calcL(A);
            
            % STUDENT MADE FUNCTION to update the Node Data. Must update
            % Node Positions, but can also update radii of communication
            % and left and right offsets with the introduction of cost
            % functions
            nodeData = UpdateNodeData(nodeData,L,timeStep,app.CurrentIteration.Value);
           
            % Stroing node data for this iteration to node history
            nodeDataHistory(iteration,:) = reshape(nodeData',1,[]);
            
            % Displaying the current node positions in the app's node data table
            app.NodeInformation.Data = nodeData;
                       
            % -----------------------------------------------------------------------------------------------
            
            % PLOTTING
                        
            % Clearing both plots 
            cla(app.NodeLocationPlot)
            cla(app.NodeTrajectoryPlot)
            
            % Plotting the node location on the Node Location Plot
            hold(app.NodeLocationPlot,'on');
            
            % Plot the location of each node
            scatter(app.NodeLocationPlot,nodeData(:,1),nodeData(:,2),'Marker','o');
            
            % Plot circles around each agent to reflect an agents radius of communication
            for node = 1:size(nodeData,1)
                radii = nodeData(node,3);
                pos = [nodeData(node,1)-radii nodeData(node,2)-radii 2*radii 2*radii];
                rectangle(app.NodeLocationPlot,'Position',pos,'Curvature',[1 1]);
            end
            
            % Adding node labels
            text(app.NodeLocationPlot, nodeData(:,1)+delta, nodeData(:,2)+delta,...
                cellfun(@num2str,num2cell((1:app.Nodes.Value)'),'UniformOutput',false));
            
            % Adjusting plotting window to display all nodes
            app.NodeLocationPlot.XLim = ([min(nodeData(:,1)) - 3, max(nodeData(:,1)) + 3]);
            app.NodeLocationPlot.YLim = ([min(nodeData(:,2)) - 3, max(nodeData(:,2)) + 3]);
            
            hold(app.NodeLocationPlot,'off')
            
            % Plotting the node trajectories on the Node Trajectory Plot
            % (only runs when 1-D button switched on)
            if app.OneD.Value == 1
                hold(app.NodeTrajectoryPlot,'on')
            
                for node = 1:app.Nodes.Value
                    plot(app.NodeTrajectoryPlot, 0:timeStep:timeStep*(iteration-1), nodeDataHistory(1:iteration,(5*node)-4),...
                        '-o','MarkerIndices',iteration);
                    text(app.NodeTrajectoryPlot, timeStep*(iteration-1)+delta, nodeDataHistory(iteration,5*node-4),sprintf('%d',node));
                end
               
                app.NodeTrajectoryPlot.XLim = ([0, app.NumIterations.Value*timeStep]);
                app.NodeTrajectoryPlot.YLim = ([min(nodeDataHistory(:,1)) - 1, max(nodeDataHistory(:,1)) + 1]);
            
                hold(app.NodeTrajectoryPlot, 'off') 
            end
            
            % -----------------------------------------------------------------------------------------------
            
            % Updating the current iteration by 1
            iteration = iteration + 1;
            app.CurrentIteration.Value = iteration;
            
            pause(0.2)
        end
        
        % Save simulation Results
        saveSimulationData(app, nodeDataHistory, iteration);
        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NodeInformation.Data = app.nodeInformation;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NodeInformation = uitable(app.UIFigure);
            app.NodeInformation.ColumnName = {'X Position'; 'Y Position'; 'Radius'; 'Left Noise'; 'Right Noise'};
            app.NodeInformation.ColumnEditable = [true true true true true];
            app.NodeInformation.CellEditCallback = createCallbackFcn(app, @NodeInformationUpdated, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NumberofNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.Nodes = uieditfield(app.UIFigure, 'numeric');
            app.Nodes.ValueChangedFcn = createCallbackFcn(app, @NumNodesChanged, true);
            app.DimensionButtonGroup = uibuttongroup(app.UIFigure);
            app.DimensionButtonGroup.Title = 'Dimension ';
            app.OneD = uiradiobutton(app.DimensionButtonGroup);
            app.OneD.Text = '1-D';
            app.OneD.Value = true;
            app.TwoD = uiradiobutton(app.DimensionButtonGroup);
            app.TwoD.Text = '2-D';
            app.NodeLocationPlot = uiaxes(app.UIFigure);
            title(app.NodeLocationPlot, 'Node Location')
            xlabel(app.NodeLocationPlot, 'X')
            ylabel(app.NodeLocationPlot, 'Y')
            app.NodeTrajectoryPlot = uiaxes(app.UIFigure);
            title(app.NodeTrajectoryPlot, 'Node Trajectory')
            xlabel(app.NodeTrajectoryPlot, 'Time')
            ylabel(app.NodeTrajectoryPlot, 'Location')
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumIterations = uieditfield(app.UIFigure, 'numeric');
            app.NumIterations.Value = 20;
            app.CurrentIterationLabel = uilabel(app.UIFigure);
            app.CurrentIterationLabel.Text = 'Current Iteration';
            app.CurrentIteration = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIteration.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStep = uieditfield(app.UIFigure, 'numeric');
            app.TimeStep.Value = 0.2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Opinion_App_modified

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end