classdef RobotExecutionApp_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Switch                         matlab.ui.control.ToggleSwitch
        PlotPathButton                 matlab.ui.control.Button
        DesiredVelocityEditFieldLabel  matlab.ui.control.Label
        DesiredVelocityEditField       matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        NumberofBotsEditFieldLabel     matlab.ui.control.Label
        NumberofBotsEditField          matlab.ui.control.NumericEditField
        AlgorithmDropDownLabel         matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        DataFileNameEditFieldLabel     matlab.ui.control.Label
        DataFileNameEditField          matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        AgentsDriveBackwardsCheckBox   matlab.ui.control.CheckBox
        TimeLengthEditFieldLabel       matlab.ui.control.Label
        TimeLengthEditField            matlab.ui.control.NumericEditField
        ShowErrorsCheckBox             matlab.ui.control.CheckBox
        ShowControlInputsCheckBox      matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        RandomizeAnglesButton          matlab.ui.control.Button
        IconDropDownLabel              matlab.ui.control.Label
        IconDropDown                   matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UITable.Data = zeros(app.NumberofBotsEditField.Value);
        end

        % Button pushed function: PlotPathButton
        function PlotPathButtonPushed(app, event)
           
%%%%%%%%%%%% variables and constants %%%%%%%%%%%%%%%%%%%%%%%%
figure(1)
str = {'Processing...','Please Wait'};
annotation('textbox','String',str,'FitBoxToText','on');
set(figure(1), 'Visible', 'off');    
end_time = app.TimeLengthEditField.Value; %when we want our simulation to end
dt = 0.05; %time step
time = linspace(0, end_time, end_time/dt);
Rad = 0.126/2; %Width of robot chassis 
r = 0.065/2; %radius of robot wheels
bot_Number = app.NumberofBotsEditField.Value;
agent_Data = load(app.DataFileNameEditField.Value);
agent_Data = agent_Data.H; %convert from struct
angles = app.UITable.Data;
POINTS = size(agent_Data,1);
WIDTH = bot_Number*2;

%%%%%%%%%%%% Parametric Path and Getting Values from Edit Fields %%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
%make some empty arrays to hold important things
q = zeros(3, length(time), bot_Number);
e = zeros(3, length(time), bot_Number); 
Bot_L = zeros(1, length(time)-2, bot_Number);
Bot_R = zeros(1, length(time)-2, bot_Number);
count = 1;   

%%% Figure out how to interpret excel data sheet based on which algorithm is selected %%%
if strcmpi(app.AlgorithmDropDown.Value, 'Opinion 1-D')
    for k = 1:bot_Number
        qd(1,:, k) = agent_Data(:,k);
        qd(2,:, k) = zeros(length(qd(1,:,k)), 1);
        qd(3,:, k) = zeros(1, POINTS);
     
        q(1,1,k) = agent_Data(1,k);
        q(2,1,k) = 0;
        q(3,1,k) = angles(k);
    end
else 
     for k = 1:2:WIDTH
        qd(1,:, count) = agent_Data(:,k);
        qd(2,:, count) = agent_Data(:,k+1);
        qd(3,:, count) = zeros(1, POINTS);
    
        q(1,1,count) = agent_Data(1,k);
        q(2,1,count) = agent_Data(1, k+1);
        q(3,1,count) = angles(count);
        
        count = count + 1;
     end 
end 

%----------------- Sorting through table data to disregard points that are too close together ----------------------%
for k = 1:bot_Number
    sort = 1;
    while(sort < size(qd,2)-1)
       if ((qd(1, sort+1, k)-qd(1, sort, k))^2 + (qd(2, sort+1, k)-qd(2, sort, k))^2) <= 0.15
            qd(:,sort+1,:) = [];
       else 
           sort = sort + 1;
       end  
    end 
end 
%--------------------------------------------------------------------------------------------------------------------

Vn = app.DesiredVelocityEditField.Value; %chosen velocity

Vd = Vn; %desired velocity (different than chosen velocity since this might flip signs if backwards driving is selected)
u = zeros(2, length(time)-2); %empty input array
Wd = 0; %desired angular velocity is zero (don't want to turn unless we have to)

%% Linear Quadratic Regulator Conditions 
XW = 8;
YW = 6;
AW = 15;
LQRV = 3;
LQRR = 10; %This should be 10 for smooth motion, but high density of points in algorithms like Lloyd require ease of turning
%Note, lower LQRR values may result in oversaturation 

%%%%%%%%%%%%%%%%%%%%%% Control (DON'T CHANGE) %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

Q = [XW, 0, 0;0, YW, 0;0, 0, AW]; %Weighting matrix for importance of the three errors x, y, theta
R = [LQRV, 0; 0, LQRR]; %Cost matrix for V, W

for k = 1:bot_Number
    b = 1;
    Vd = Vn;
    for i = 1:length(time)-2
         
         %% All of this stuff is just dealing with the many issues that arise from having to have angles bounded between -pi and pi
          if abs(q(3,i,k)) > pi
            if q(3,i,k) > 0 
                while (q(3,i,k) > pi)
                    q(3,i,k) = q(3,i,k) - 2*pi;
                end 
            else 
                while (q(3,i,k) < -pi)
                    q(3,i,k) = q(3,i,k) + 2*pi;
                end 
            end 
          end 
        
        qd(3,b,k) = atan2(qd(2,b,k)-q(2,i,k),qd(1,b,k)-q(1,i,k)); %desired angle is a straight line to the desired point
        e(:,i,k) = q(:,i,k) - qd(:,b,k);
        e(3,:,k) = unwrap(e(3,:,k));
        
        if abs(e(3,i,k)>pi)
            if e(3,i,k)>0
               e(3,i,k) = e(3,i,k) - 2*pi;
            else 
                e(3,i,k) = e(3,i,k) + 2*pi;
            end 
        end 
        %start control stuff
        [Vd, qd(:,b,k)] = GetInputs(qd, b, Vn, e(3,i,k), k);
        e(:,i,k) = q(:,i,k) - qd(:,b,k);
        e(3,:,k) = unwrap(e(3,:,k));
        
        if abs(e(3,i,k)>pi)
            if e(3,i,k)>0
               e(3,i,k) = e(3,i,k) - 2*pi;
            else 
                e(3,i,k) = e(3,i,k) + 2*pi;
            end 
        end 
        
        u(:, i,k) = LQRcontrols(qd(:,b,k), Q, R, Vd, e(3,i,k));
        [Wl, Wr] = GetDDInput(u(:,i,k), r, Rad);%Convert from our unicycle model to inputs that are compatible with a differential drive robot
        [Wl, Wr, Bot_L(i, k), Bot_R(i, k)] = MappingInputs(Wl, Wr); %constrain our motion based on limitations of robots
        u(:,i,k) = InverseMap(Wl, Wr, r, Rad); %get unicycle inputs based on constrained motion 
        q(:,i+1,k) = MotionResponse(q(:,i,k), u(:,i,k), dt); %get change in position based on inputs
        e(:,i+1,k) = q(:,i+1,k) - qd(:,b,k); %Get error again to see if we're close enough to desired point
        
        %(We don't recalculate desired angle but that's okay since it's only distance that matters)
        if sqrt(e(2,i+1,k)^2+e(1,i+1,k)^2)<0.15 %move to next desired point if we're within 20 cm
            b = b + 1;
            if b == size(qd,2)+1
                q(:, (i+1):length(time)-2, k) = q(:, i+1, k).*ones(3, length(time)-2-i);
                break 
            end   
        end 
        
    end 
end 

%% Convert to DD from Unicycle
for k = 1:bot_Number
[Wl_final(:,k), Wr_final(:,k)] = GetDDInput(u(:,:,k), r, Rad); 
em(:,k) = sqrt(e(1,:,k).^2+e(2,:,k).^2); %error magnitude is the norm of the error in x and y 
end 


%%%%%%%%%%%%%%%%%%%%%%%%% PLOTTING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%all this stuff is just getting vectors to be the right length for plotting
U_t = zeros(2, length(time),bot_Number); 
for k = 1:bot_Number
U_t(1:2,1:length(time)-2,k) = u(:,:,k);
U_t(length(time)-1:length(time),k) = 0;
q(:,length(time)-1,k) = q(:, length(time)-2,k);
em(length(time)-1,k) = em(length(time)-2,k);
end 

%getting our axis limits for our plots
xmin = -0.25;
xmax = 1;
ymin = -0.25;
ymax = 1;
for k = 1:bot_Number
xmin_new = min(q(1,:,k)); 
ymin_new = min(q(2,:,k));
xmax_new = max(q(1,:,k));
ymax_new = max(q(2,:,k));
    if xmin_new < xmin
        xmin = xmin_new;
    end 
    if ymin_new < ymin
        ymin = ymin_new;
    end
    if xmax_new > xmax
        xmax = xmax_new;
    end
    if ymax_new > ymax
        ymax = ymax_new;
    end 
end
gridmax = max(xmax, ymax); 
gridmin = min(xmin, ymin);

if strcmpi(app.IconDropDown.Value, 'Plane')
    x = [-0.1 -0.085, 0.005,0.01,0.02,0.03, 0.09,0.098, 0.1 0.098,0.09, 0.03,0.02,0.01,0.005 -0.085, -0.1]; %plane drawing
    y = [-0.05, -0.023, -0.023,-0.1,-0.1,-0.023,-0.023,-0.01,0, 0.01,0.023, 0.023, 0.1, 0.1, 0.023, 0.023, 0.05]; 
elseif strcmpi(app.IconDropDown.Value, 'Robot')
    x = [-0.098, -0.01, -0.01, 0.05, 0.05, 0.087, 0.098, 0.098, 0.087, 0.05 ,0.05, -0.01, -0.01, -0.098]; %robot drawing with wheels at front
    y = [-0.063,-0.063,-0.09, -0.09,-0.063, -0.063, -0.02, 0.02, 0.063, 0.063,0.09, 0.09, 0.063, 0.063]; %this is to scale for the real robot
elseif strcmpi(app.IconDropDown.Value, 'Submersible')
    x = [-0.1, 0.13, 0.13,0.07,0.071,0.075,0.08,0.085,0.09,0.09,0.085,0.08,0.073,0.071,0.07,0.13,0.13,-0.1,-0.1,-0.08,-0.08,-0.1, -0.1]; %submersible drawing
    y = 1.5*[-0.05,-0.05,-0.02,-0.02,-0.02,-0.015,-0.013,-0.011,-0.005,0.005,0.011,0.013,0.015,0.02,0.02,0.02,0.05,0.05,0.025,0.025,-0.025,-0.025, -0.05]; 
end 

% plot the error vs time
Leg = cell(bot_Number, 1);
if app.ShowErrorsCheckBox.Value == 1
   for k = 1:bot_Number
       plot(app.UIAxes3, time,em(:, k));
       hold(app.UIAxes3, 'on');
       Leg{k} = sprintf('Agent %d', k);
   end 
   legend(app.UIAxes3,Leg);
   hold(app.UIAxes3, 'off');
end 

%display the robot inputs
Legend = cell(bot_Number, 1);
leg_count = 1;
if app.ShowControlInputsCheckBox.Value == 1
    for k = 1:bot_Number 
    col = rand(1, 3);
    coltwo = rand(1, 3);
    plot(app.UIAxes2, time(1:length(Bot_L(:,1))), Bot_L(:, k),'color', col);
    hold(app.UIAxes2, 'on')
    plot(app.UIAxes2, time(1:length(Bot_R(:,1))), Bot_R(:, k), 'color', coltwo);
    Legend{leg_count} = sprintf('Agent %d Left', k);
    Legend{leg_count+1} = sprintf('Agent %d Right', k);
    
    app.UIAxes2.XLim = [0 app.TimeLengthEditField.Value]; 
    hold(app.UIAxes2, 'on');
    leg_count = leg_count + 2;
    end 
    legend(app.UIAxes2,Legend);
    hold(app.UIAxes2, 'off');
end  

%Plot the robot motion and trajectories at each time step
for j = 1:(length(time))-1
    for k = 1:bot_Number
        plot(app.UIAxes, q(1,1:j,k), q(2,1:j,k), 'k')
        hold(app.UIAxes, 'on')
    end
    if strcmpi(app.AlgorithmDropDown.Value, 'Lloyd')
            for k = 1:bot_Number   
            vor_x(k) = q(1, j, k);
            vor_y(k) = q(2, j, k);
            end 
        voronoi(app.UIAxes, vor_x, vor_y)
    end 
    if strcmpi(app.AlgorithmDropDown.Value, 'Flocking')
        leader = hgtransform(app.UIAxes);
        patch(app.UIAxes,'XData',x,'YData',y,'FaceColor','blue','EdgeColor','blue', 'Parent',leader) %draw our bot
        leader.Matrix = makehgtform('translate',[q(1, j, 1), q(2,j, 1), 0], ... %rotate and move our robot
                        'zrotate',q(3,j, 1));
        text(app.UIAxes, q(1, j, 1), q(2,j, 1), '\leftarrow leader')
        for k = 2:bot_Number  
            name(k) = hgtransform(app.UIAxes); %make a transform object that we can manipulate
            patch(app.UIAxes,'XData',x,'YData',y,'FaceColor','black','Parent',name(k)) %draw our bot
            name(k).Matrix = makehgtform('translate',[q(1, j, k), q(2,j, k), 0], ... %rotate and move our robot
                        'zrotate',q(3,j, k));
            text(app.UIAxes, q(1, j, k),q(2,j, k) ...
                        , sprintf('%d', k), 'color', 'r', ...
                        'fontweight', 'bold');
        end
    else 
        for k = 1:bot_Number  
            name(k) = hgtransform(app.UIAxes); %make a transform object that we can manipulate
            patch(app.UIAxes,'XData',x,'YData',y,'FaceColor','black','Parent',name(k)) %draw our bot
            name(k).Matrix = makehgtform('translate',[q(1, j, k), q(2,j, k), 0], ... %rotate and move our robot
                        'zrotate',q(3,j, k));
            text(app.UIAxes, q(1, j, k),q(2,j, k) ...
                        ,sprintf('%d', k), 'color', 'r', ...
                        'fontweight', 'bold');
        end
    end 
    
    axis equal
    app.UIAxes.XLim = [gridmin, gridmax]; 
    app.UIAxes.YLim = [gridmin, gridmax];      
    hold(app.UIAxes, 'on')
    drawnow
    hold(app.UIAxes, 'on')
    pause(0.05);
    hold(app.UIAxes, 'off')
    
    
    Break = app.Switch.Value;
    
    %if we hit the end switch, stop plotting
    if strcmpi('End', Break)
        j = length(time);
        break
    end 

end 


%--------------------------- LOCAL CONTROL FUNCTIONS -------------------------------%


 %This function looks at whether it is more efficient for our robot to
 %follow the desired curve forwards or backwards
function [Vdx, qdx] = GetInputs(qd, b, Vn, e, k)

 
        %if we're going to a point, desired angle is the angle of a line
        %from the current point to the desired point

   if abs(qd(3,b, k))>pi
        if qd(3,b, k)>0
            qd(3,b, k) = qd(3,b, k)-2*pi;
        else 
            qd(3,b, k) = qd(3,b, k) + 2*pi;
        end 
   end    
   
   if app.AgentsDriveBackwardsCheckBox.Value == 1
        if abs(e) > pi/2
            %if we are further than 90 degrees from our desired angle
            %it's easier to go backwards (since it'll be a turn of less than 90 degrees)
            if qd(3,b,k)>=0
            qd(3,b,k) = qd(3,b,k) - pi;
            Vd = -Vn;
            
            elseif qd(3,b,k) < 0
             qd(3,b,k) = qd(3,b,k) + pi;
             Vd = -Vn;
            end 
        else 
        Vd = Vn;
        end
   else 
       Vd = Vn;
   end 
    
    qd(3,1:b, k) = unwrap(qd(3,1:b, k)); %our values might get wrapped due to the above operations, so we need to unwrap for safety

    Vdx = Vd;
    qdx = qd(:,b, k);

end 

%this functions gets control inputs based on error and desired inputs
function u = LQRcontrols(qd, Q, R, Vd, e)
    A = [0, 0, -Vd*sin(qd(3));0, 0, Vd*cos(qd(3));0, 0, 0]; %evaluate matrix at desired inputs and location
    B = [cos(qd(3)), 0;sin(qd(3)), 0;0, 1];
    K = lqr(A, B, Q, R); %This computes our gain matrix using a LQR
    u = -K*[0; 0; e] + [Vd; 0]; %this will keep our robot velocity constant, just playing around
end 


%this function coverts from unicycle inputs to diff drive inputs
function [Wl, Wr] = GetDDInput(u, r, Rad)
    Wl = u(1,:)./r - Rad.*u(2,:)./r;
    Wr = u(1,:)./r + Rad.*u(2,:)./r;

end 

%this function maps diff drive inputs to what the robot can achieve
function [Wl, Wr, Bot_L, Bot_R] = MappingInputs(Wl, Wr)

    %this model was developed from diagnostic testing
    Wr = round(Wr*13+90*sign(Wr));
    Wl = round(Wl*13+90*sign(Wl));
    
    if abs(Wr)>255
        Wr = 255*sign(Wr);
    elseif abs(Wr) <= 90
        Wr = 0;
    end 
    
    if abs(Wl)>255
        Wl = 255*sign(Wl);
    elseif abs(Wl) <= 90
        Wl = 0;
    end 
    
    Bot_L = Wl;
    Bot_R = Wr;
    
end 

        
%this function takes the robot's inputs and turns them into unicycle inputs        
function u = InverseMap(Wl, Wr, r, Rad)
    Wr = (Wr-90*sign(Wr))/13; %from diagnostic testing model
    Wl = (Wl-90*sign(Wl))/13;

    u(1) = 0.5*r*(Wr+Wl);
    u(2) = 0.5*(r/Rad)*(Wr-Wl);  
end 

%this function evaluates the change in position over a timestep due to control inputs
function q = MotionResponse(q, u, dt)
    q = q + [cos(q(3)), 0; sin(q(3)), 0; 0, 1]*u*dt;
end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%% END OF LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);      
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Value changed function: NumberofBotsEditField
        function NumberofBotsEditFieldValueChanged(app, event)
        app.UITable.Data = zeros(app.NumberofBotsEditField.Value, 1);
        end

        % Button pushed function: RandomizeAnglesButton
        function RandomizeAnglesButtonPushed(app, event)
        app.UITable.Data = 6.28*rand(app.NumberofBotsEditField.Value, 1);
        
        for k = 1:app.NumberofBotsEditField.Value
            if app.UITable.Data(k) > pi
               app.UITable.Data(k) = app.UITable.Data(k) - 2*pi;
            end 
        end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Path')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Ready', 'End'};
            app.Switch.Value = 'Ready';
            app.PlotPathButton = uibutton(app.UIFigure, 'push');
            app.PlotPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPathButtonPushed, true);
            app.PlotPathButton.Text = 'Plot Path';
            app.DesiredVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredVelocityEditFieldLabel.Text = 'Desired Velocity';
            app.DesiredVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredVelocityEditField.Limits = [0 3.7];
            app.DesiredVelocityEditField.Value = 0.3;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NumberofBotsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBotsEditFieldLabel.Text = 'Number of Bots';
            app.NumberofBotsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBotsEditField.Limits = [1 Inf];
            app.NumberofBotsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBotsEditFieldValueChanged, true);
            app.NumberofBotsEditField.Value = 1;
            app.AlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.UIFigure);
            app.AlgorithmDropDown.Items = {'Opinion 1-D', 'Opinion 2-D', 'Formation', 'Flocking', 'Lloyd', 'Other'};
            app.AlgorithmDropDown.Value = 'Opinion 1-D';
            app.DataFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.DataFileNameEditFieldLabel.Text = 'Data File Name';
            app.DataFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.DataFileNameEditField.Value = 'agentData.mat';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Initial Angle'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AgentsDriveBackwardsCheckBox = uicheckbox(app.UIFigure);
            app.AgentsDriveBackwardsCheckBox.Text = 'Agents Drive Backwards';
            app.AgentsDriveBackwardsCheckBox.Value = true;
            app.TimeLengthEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLengthEditFieldLabel.Text = 'Time Length';
            app.TimeLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeLengthEditField.Limits = [1 Inf];
            app.TimeLengthEditField.Value = 50;
            app.ShowErrorsCheckBox = uicheckbox(app.UIFigure);
            app.ShowErrorsCheckBox.Text = 'Show Errors';
            app.ShowErrorsCheckBox.Value = true;
            app.ShowControlInputsCheckBox = uicheckbox(app.UIFigure);
            app.ShowControlInputsCheckBox.Text = 'Show Control Inputs';
            app.ShowControlInputsCheckBox.Value = true;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Inputs')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Signal')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Error')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Magnitude')
            app.RandomizeAnglesButton = uibutton(app.UIFigure, 'push');
            app.RandomizeAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @RandomizeAnglesButtonPushed, true);
            app.RandomizeAnglesButton.Text = 'Randomize Angles';
            app.IconDropDownLabel = uilabel(app.UIFigure);
            app.IconDropDownLabel.Text = 'Icon';
            app.IconDropDown = uidropdown(app.UIFigure);
            app.IconDropDown.Items = {'Robot', 'Plane', 'Submersible'};
            app.IconDropDown.Value = 'Robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotExecutionApp_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end