classdef RSSTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RSSContour                    matlab.ui.control.UIAxes
        RSSData                       matlab.ui.control.Table
        GridSizeEditFieldLabel        matlab.ui.control.Label
        GridSize                      matlab.ui.control.NumericEditField
        GridResolutionEditFieldLabel  matlab.ui.control.Label
        GridResolution                matlab.ui.control.NumericEditField
        SaveTestResultsButton         matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GridSize
        function GridSizeChanged(app, event)
            tableLength = app.GridSize.Value*2/app.GridResolution.Value+1;
            app.RSSData.Data = zeros(tableLength);
            xAxisLabel = -app.GridSize.Value: app.GridResolution.Value: app.GridSize.Value;
            yAxisLabel = app.GridSize.Value: -app.GridResolution.Value: -app.GridSize.Value;
            
            app.RSSData.RowName = yAxisLabel;
            app.RSSData.ColumnName = xAxisLabel;
        end

        % Value changed function: GridResolution
        function GridResolutionChanged(app, event)
            tableLength = app.GridSize.Value*2/app.GridResolution.Value+1;
            app.RSSData.Data = zeros(tableLength);
            xAxisLabel = -app.GridSize.Value: app.GridResolution.Value: app.GridSize.Value;
            yAxisLabel = app.GridSize.Value: -app.GridResolution.Value: -app.GridSize.Value;
            
            app.RSSData.RowName = yAxisLabel;
            app.RSSData.ColumnName = xAxisLabel;
        end

        % Button pushed function: PlotButton
        function PlotResults(app, event)
            x = -app.GridSize.Value: app.GridResolution.Value: app.GridSize.Value;
            y = app.GridSize.Value: -app.GridResolution.Value: -app.GridSize.Value;
            
            [X,Y] = meshgrid(x,y);
            
            contour(app.RSSContour, X, Y, app.RSSData.Data, 5,'ShowText','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RSSContour = uiaxes(app.UIFigure);
            title(app.RSSContour, 'Signal Strength Contour Plot')
            xlabel(app.RSSContour, {'Distance from Beacon [m]'; ''})
            ylabel(app.RSSContour, 'Distance from Beacon [m]')
            app.RSSData = uitable(app.UIFigure);
            app.RSSData.ColumnName = {''};
            app.RSSData.RowName = {''};
            app.RSSData.ColumnEditable = true;
            app.GridSizeEditFieldLabel = uilabel(app.UIFigure);
            app.GridSizeEditFieldLabel.Text = 'Grid Size';
            app.GridSize = uieditfield(app.UIFigure, 'numeric');
            app.GridSize.ValueChangedFcn = createCallbackFcn(app, @GridSizeChanged, true);
            app.GridResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.GridResolutionEditFieldLabel.Text = 'Grid Resolution';
            app.GridResolution = uieditfield(app.UIFigure, 'numeric');
            app.GridResolution.ValueChangedFcn = createCallbackFcn(app, @GridResolutionChanged, true);
            app.GridResolution.Value = 1;
            app.SaveTestResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveTestResultsButton.Text = 'Save Test Results';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResults, true);
            app.PlotButton.Text = 'Plot';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSSTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end