classdef MatrixEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                 matlab.ui.Figure
        MatrixTable            matlab.ui.control.Table
        RowsEditFieldLabel     matlab.ui.control.Label
        RowsEditField          matlab.ui.control.NumericEditField
        DimensionsLabel        matlab.ui.control.Label
        ColumnsEditFieldLabel  matlab.ui.control.Label
        ColumnsEditField       matlab.ui.control.NumericEditField
        DepthEditField         matlab.ui.control.NumericEditField
        ViewLabel              matlab.ui.control.Label
        CurrentDepthEditField  matlab.ui.control.NumericEditField
        FileLabel              matlab.ui.control.Label
        NameEditFieldLabel     matlab.ui.control.Label
        NameEditField          matlab.ui.control.EditField
        SaveButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        MatrixTypeLabel        matlab.ui.control.Label
        ContinuousCheckBox     matlab.ui.control.CheckBox
        FunctionEditField      matlab.ui.control.EditField
        ComputeButton          matlab.ui.control.Button
        DepthLabel             matlab.ui.control.Label
        CurrentDepthLabel      matlab.ui.control.Label
        FunctionLabel          matlab.ui.control.Label
        NewButton              matlab.ui.control.Button
        DensityCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        data % matrix data for the table
        originalData % matrix data upon last save
    end
    
    methods (Access = private)
        
        % Creates the default matrix, stores it in app.data, and displays it
        function newMatrix(app)
            app.data = cell(5,5,1);
            app.RowsEditField.Value = 5;
            app.ColumnsEditField.Value = 5;
            app.DepthEditField.Value = 1;
            app.CurrentDepthEditField.Value = 1;
            app.MatrixTable.Data = app.data(:,:,1);
        end
        
        % Converts a string to symbolic expression, makes substitutions and
        % simplifies, then returns string of final equation. f should be in
        % terms of x, y, and t
        function equation = simplifyAndEval(~,f,xVal,yVal)
            syms x y t
            equation = str2sym(f);
            equation = subs(equation,x,xVal);
            equation = char(subs(equation,y,yVal));
        end
        
        % Handles the enable/disable of UI elements. Called when changing
        % function types, and when loading from file
        function toggleContinuous(app,isContinuous)
            if isContinuous
                % Moving to continuous
                set(app.DepthEditField,'Value',1,'Editable',false,'Enable',false);
                set(app.DepthLabel,'Enable',false);
                set(app.CurrentDepthEditField,'Value',1,'Editable',false,'Enable',false);
                set(app.CurrentDepthLabel,'Enable',false);
                set(app.FunctionEditField,'Editable',true,'Enable',true);
                set(app.FunctionLabel,'Enable',true);
                set(app.ContinuousCheckBox,'Value',true);
                if ~isempty(app.FunctionEditField.Value)
                    set(app.ComputeButton,'Enable',true);
                end
            else
                % Moving to discrete. Entries can remain
                set(app.DepthEditField,'Editable',true,'Enable',true);
                set(app.DepthLabel,'Enable',true);
                set(app.CurrentDepthEditField,'Editable',true,'Enable',true);
                set(app.CurrentDepthLabel,'Enable',true);
                set(app.FunctionEditField,'Editable',false,'Enable',false);
                set(app.FunctionLabel,'Enable',false);
                set(app.ComputeButton,'Enable',false);
                set(app.ContinuousCheckBox,'Value',false);
            end
        end
        
        function saveData(app, filename)
            isDensity = app.DensityCheckBox.Value;
            isContinuous = app.ContinuousCheckBox.Value;
            % Optimize for future loading if this is a density matrix by
            % storing the matrix as symbolic expressions
            if isDensity
                syms D(t);
                matrix = str2sym(app.data);
            else
                matrix = app.data;
            end
            func = app.FunctionEditField.Value;
            app.originalData = app.data;
            save(filename, 'matrix','isContinuous','isDensity', 'func');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Get matrix entries from file
                    isDensity = file.isDensity;
                    % If entries were stored as symbolic equations, they need
                    % to be converted to char to be displayed in table
                    if isDensity
                        app.data = arrayfun(@char, file.matrix, 'UniformOutput', false);
                    else
                        app.data = file.matrix;
                    end
                    app.originalData = app.data;
                    dimensions = size(app.data);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    % If the matrix has depth > 1, ndims(app.data) == 3
                    if ndims(app.data) == 3
                        app.DepthEditField.Value = dimensions(3);
                    else
                        app.DepthEditField.Value = 1;
                    end
                    % Function loading
                    isContinuous = file.isContinuous;
                    app.DensityCheckBox.Value = isDensity;
                    app.FunctionEditField.Value = file.func;
                    toggleContinuous(app,isContinuous);
                    
                    %% Various setup config things
                    set(app.MatrixTable,'ColumnEditable',true,'ColumnWidth','auto');
                    app.MatrixTable.Data = app.data(:,:,1);
                catch ME
                    disp(['Error: ' filename ' is corrupt (likely has 0 dimension). Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                newMatrix(app);
                %% Various setup config things
                set(app.MatrixTable,'ColumnEditable',true,'ColumnWidth','auto');
            end
        end

        % Button pushed function: LoadButton
        function loadFcn(app, event)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                % Open file
                file = load(filename);
                % Get matrix entries from file
                isDensity = file.isDensity;
                % If entries were stored as symbolic equations, they need
                % to be converted to char to be displayed in table
                if isDensity
                    app.data = arrayfun(@char, file.matrix, 'UniformOutput', false);
                else
                    app.data = file.matrix;
                end
                app.originalData = app.data;
                dimensions = size(app.data);
                % Repopulate dimension fields
                app.RowsEditField.Value = dimensions(1);
                app.ColumnsEditField.Value = dimensions(2);
                % If the matrix has depth > 1, ndims(app.data) == 3
                if ndims(app.data) == 3
                    app.DepthEditField.Value = dimensions(3);
                else
                    app.DepthEditField.Value = 1;
                end
                app.CurrentDepthEditField.Value = 1;
                app.MatrixTable.Data = app.data(:,:,1);
                % Function loading
                isContinuous = file.isContinuous;
                app.DensityCheckBox.Value = isDensity;
                app.FunctionEditField.Value = file.func;
                toggleContinuous(app,isContinuous);
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Button pushed function: SaveButton
        function saveButtonFcn(app, event)
            filename = app.NameEditField.Value;
            if isfile(filename)
                selection = uiconfirm(app.Window,'File exists. Overwrite?', 'Confirm Save',...
                    'Options',{'Overwrite','Save as Copy', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Overwrite'
                        saveData(app, filename);
                    case 'Save as Copy'
                        fileParts = split(filename, '.');
                        filename = char(join([fileParts(1) '_copy.mat'],""));
                        saveData(app, filename);
                        % Also need to change the filename field
                        app.NameEditField.Value = filename;
                    case 'Cancel'
                        % Do nothing
                end
            else
                saveData(app, filename);
            end
        end

        % Value changed function: RowsEditField
        function rowsChanged(app, event)
            newNumRows = app.RowsEditField.Value;
            oldNumRows = size(app.data,1);
            deltaRows = newNumRows - oldNumRows;
            depth = size(app.data,3);
            columns = size(app.data,2);
            % If we are deleting rows
            if deltaRows < 0
                app.data = app.data(1:newNumRows,:,:);
            % If we are adding rows
            elseif deltaRows > 0
                newRows = cell(deltaRows, columns, depth);
                app.data = cat(1, app.data, newRows);
            end
            currDepth = app.CurrentDepthEditField.Value;
            app.MatrixTable.Data = app.data(:,:,currDepth);
        end

        % Value changed function: ColumnsEditField
        function columnsChanged(app, event)
            newNumCols = app.ColumnsEditField.Value;
            oldNumCols = size(app.data,2);
            deltaCols = newNumCols - oldNumCols;
            depth = size(app.data,3);
            rows = size(app.data,1);
            % If we are deleting columns
            if deltaCols < 0
                app.data = app.data(:,1:newNumCols,:);
            % If we are adding columns
            elseif deltaCols > 0
                newCols = cell(rows, deltaCols, depth);
                app.data = cat(2, app.data, newCols);
            end
            currDepth = app.CurrentDepthEditField.Value;
            app.MatrixTable.Data = app.data(:,:,currDepth);
        end

        % Value changed function: DepthEditField
        function depthChanged(app, event)
            newDepth = app.DepthEditField.Value;
            oldDepth = size(app.data,3);
            deltaDepth = newDepth - oldDepth;
            currDepth = app.CurrentDepthEditField.Value;
            rows = size(app.data,1);
            columns = size(app.data,2);
            % If we are deleting depth
            if deltaDepth < 0
                app.data = app.data(:,:,1:newDepth);
                if newDepth<currDepth
                    app.CurrentDepthEditField.Value = newDepth;
                    % Only need to change view if cutting off currently
                    % viewed matrix
                    app.MatrixTable.Data = app.data(:,:,newDepth);
                end
            % If we are adding columns
            elseif deltaDepth > 0
                newDepthMats = cell(rows, columns, deltaDepth);
                app.data = cat(3, app.data, newDepthMats);
            end
        end

        % Value changed function: CurrentDepthEditField
        function viewChanged(app, event)
            value = app.CurrentDepthEditField.Value;
            if value>size(app.data,3)
                app.MatrixTable.Data = app.data(:,:,size(app.data,3));
                app.CurrentDepthEditField.Value = size(app.data,3);
            else
                app.MatrixTable.Data = app.data(:,:,value);
            end
        end

        % Cell edit callback: MatrixTable
        function valueChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            currDepth = app.CurrentDepthEditField.Value;
            app.data{indices(1),indices(2),currDepth} = newData;
            app.MatrixTable.Data = app.data(:,:,currDepth);
        end

        % Close request function: Window
        function closeFcn(app, event)
            % Prompt user to save if there are unsaved changes
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before closing?', 'Confirm Save',...
                    'Options',{'Save & Exit','Exit', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save & Exit'
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        delete(app.Window);
                    case 'Exit'
                        delete(app.Window);
                    case 'Cancel'
                        % Do nothing
                end
            else
                delete(app.Window);
            end
        end

        % Value changed function: ContinuousCheckBox
        function functionTypeChanged(app, event)
            value = app.ContinuousCheckBox.Value;
            % Prompt user, as this could erase data
            selection = uiconfirm(app.Window,'This will delete your existing data. Continue?','Confirm Change',...
                'Options',{'Yes','Cancel'}, 'DefaultOption',2,'CancelOption',2);
            switch (selection)
                case 'Yes'
                    toggleContinuous(app,value);
                    if value
                        % If we're changing to a continuous function,
                        % remove any depth>1, and reset all entries to blank
                        app.data = cell(size(app.data,1),size(app.data,2));
                        app.MatrixTable.Data = app.data;
                    end
                case 'Cancel'
                    % Remove checkmark
                    app.ContinuousCheckBox.Value = ~app.ContinuousCheckBox.Value;
            end
        end

        % Button pushed function: ComputeButton
        function computeContinuous(app, event)
            f = app.FunctionEditField.Value;
            for y = 1:size(app.data,1)
                for x = 1:size(app.data,2)
                    try
                        app.data{y,x} = simplifyAndEval(app,f,x,y);
                    catch ME
                        disp('Error: Invalid function. Make sure the function is a valid MATLAB expression in terms of x, y, and t')
                        disp(ME)
                    end
                end
            end
            app.MatrixTable.Data = app.data;
        end

        % Callback function: FunctionEditField, FunctionEditField
        function shouldEnableCompute(app, event)
            changingValue = event.Value;
            if isempty(app.FunctionEditField.Value)
                set(app.ComputeButton,'Enable',false);
            end
            if ~isempty(changingValue)
                set(app.ComputeButton,'Enable',true);
            end
        end

        % Button pushed function: NewButton
        function newFcn(app, event)
            % Prompt user to save any unsaved data
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before opening new?', 'Confirm Save',...
                    'Options',{'Save','Don''t Save', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save'
                        % Save stuff under specified filename
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        % Reset to defaults
                        toggleContinuous(app,false);
                        app.FunctionEditField.Value = '';
                        newMatrix(app);
                    case 'Don''t Save'
                        % Reset to defaults
                        toggleContinuous(app,false);
                        app.FunctionEditField.Value = '';
                        newMatrix(app);
                    case 'Cancel'
                        % Do nothing
                end
            else
                % Reset to defaults
                toggleContinuous(app,false);
                app.FunctionEditField.Value = '';
                newMatrix(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.MatrixTable = uitable(app.Window);
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @valueChanged, true);
            app.RowsEditFieldLabel = uilabel(app.Window);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.Window, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsChanged, true);
            app.RowsEditField.Value = 1;
            app.DimensionsLabel = uilabel(app.Window);
            app.DimensionsLabel.Text = 'Dimensions';
            app.ColumnsEditFieldLabel = uilabel(app.Window);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.Window, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsChanged, true);
            app.ColumnsEditField.Value = 1;
            app.DepthEditField = uieditfield(app.Window, 'numeric');
            app.DepthEditField.Limits = [1 Inf];
            app.DepthEditField.ValueChangedFcn = createCallbackFcn(app, @depthChanged, true);
            app.DepthEditField.Value = 1;
            app.ViewLabel = uilabel(app.Window);
            app.ViewLabel.Text = 'View';
            app.CurrentDepthEditField = uieditfield(app.Window, 'numeric');
            app.CurrentDepthEditField.Limits = [1 Inf];
            app.CurrentDepthEditField.ValueChangedFcn = createCallbackFcn(app, @viewChanged, true);
            app.CurrentDepthEditField.Value = 1;
            app.FileLabel = uilabel(app.Window);
            app.FileLabel.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.Window);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Window, 'text');
            app.NameEditField.Value = 'matrix.mat';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFcn, true);
            app.LoadButton.Text = 'Load';
            app.MatrixTypeLabel = uilabel(app.Window);
            app.MatrixTypeLabel.Text = 'Matrix Type';
            app.ContinuousCheckBox = uicheckbox(app.Window);
            app.ContinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @functionTypeChanged, true);
            app.ContinuousCheckBox.Text = 'Continuous?';
            app.FunctionEditField = uieditfield(app.Window, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @shouldEnableCompute, true);
            app.FunctionEditField.ValueChangingFcn = createCallbackFcn(app, @shouldEnableCompute, true);
            app.FunctionEditField.Editable = 'off';
            app.FunctionEditField.Enable = 'off';
            app.FunctionEditField.Tooltip = {'The continuous function in terms of x y t'};
            app.ComputeButton = uibutton(app.Window, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @computeContinuous, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.DepthLabel = uilabel(app.Window);
            app.DepthLabel.Text = 'Depth';
            app.CurrentDepthLabel = uilabel(app.Window);
            app.CurrentDepthLabel.Text = 'Current Depth';
            app.FunctionLabel = uilabel(app.Window);
            app.FunctionLabel.Enable = 'off';
            app.FunctionLabel.Text = 'Function';
            app.NewButton = uibutton(app.Window, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @newFcn, true);
            app.NewButton.Text = 'New';
            app.DensityCheckBox = uicheckbox(app.Window);
            app.DensityCheckBox.Text = 'Density?';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end