classdef PathTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        XInitialPositionEditFieldLabel  matlab.ui.control.Label
        XInitialPositionEditField       matlab.ui.control.NumericEditField
        InputsSwitchLabel               matlab.ui.control.Label
        InputsSwitch                    matlab.ui.control.Switch
        YInitialPositionEditFieldLabel  matlab.ui.control.Label
        YInitialPositionEditField       matlab.ui.control.NumericEditField
        InitialAngleEditFieldLabel      matlab.ui.control.Label
        InitialAngleEditField           matlab.ui.control.NumericEditField
        TimeLengthSliderLabel           matlab.ui.control.Label
        TimeLengthSlider                matlab.ui.control.Slider
        Switch                          matlab.ui.control.ToggleSwitch
        PlotPathButton                  matlab.ui.control.Button
        LQRForwardWeightEditFieldLabel  matlab.ui.control.Label
        LQRForwardWeightEditField       matlab.ui.control.NumericEditField
        LQRLateralWeightEditFieldLabel  matlab.ui.control.Label
        LQRLateralWeightEditField       matlab.ui.control.NumericEditField
        LQRAngleWeightEditFieldLabel    matlab.ui.control.Label
        LQRAngleWeightEditField         matlab.ui.control.NumericEditField
        LQRVelocityCostEditFieldLabel   matlab.ui.control.Label
        LQRVelocityCostEditField        matlab.ui.control.NumericEditField
        LQRTurningCostEditFieldLabel    matlab.ui.control.Label
        LQRTurningCostEditField         matlab.ui.control.NumericEditField
        MapInputsforRealRobotCheckBox   matlab.ui.control.CheckBox
        DesiredVelocityEditFieldLabel   matlab.ui.control.Label
        DesiredVelocityEditField        matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        ResetButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.UITable.Data = zeros(app.NumberofPointsEditField.Value, 2);
        end

        % Callback function
        function PlotPathButtonPushed(app, event)
            
        end

        % Value changed function: XInitialPositionEditField
        function XInitialPositionEditFieldValueChanged(app, event)
            value = app.XInitialPositionEditField.Value;
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
 
        end

        % Value changed function: YInitialPositionEditField
        function YInitialPositionEditFieldValueChanged(app, event)
            value = app.YInitialPositionEditField.Value;
            
        end

        % Value changed function: InitialAngleEditField
        function InitialAngleEditFieldValueChanged(app, event)
            value = app.InitialAngleEditField.Value;
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            
        end

        % Callback function
        function PlotPathButtonValueChanged(app, event)
            
        end

        % Button pushed function: PlotPathButton
        function PlotPathButtonPushed2(app, event)
%%%%%%%%%%%% variables and constants %%%%%%%%%%%%%%%%%%%%%%%%
figure(1)
str = {'Processing...','Please Wait'};
annotation('textbox','String',str,'FitBoxToText','on');
set(figure(1), 'Visible', 'off');    
end_time = app.TimeLengthSlider.Value; %when we want our simulation to end
dt = 0.05;
time = linspace(0, end_time, end_time/dt);
Rad = 0.126/2; %Width of robot chassis 
r = 0.065/2; %radius of robot wheels
POINTS = app.NumberofPointsEditField.Value;

%%%%%%%%%%%% Parametric Path and Getting Values from Edit Fields %%%%%%%%%%%%%%%%%%%%%%%%%%%%
data = app.UITable.Data;
qd(1,:) = data(:,1);
qd(2,:) = data(:,2);
qd(3,:) = zeros(1, POINTS);
% thetad = atan2(diff(qd(2, :)),diff(qd(1, :))); %desired angle points in the direction of the path velocity vector
% thetad(length(t)) = thetad(length(t)-1); %make up for losing one unit of length
% qd(3,:) = thetad;
% qd(3,:) = unwrap(thetad); %unwrapping smooths jumps in angles bigger then pi.
%                     %this is helpful when the robot goes from -pi to pi as they are     
%                     %the same angle, but the robot would see it as a 2pi rotation
  
%make some empty arrays to hold important things
q = zeros(3, length(time));
e = zeros(3, length(time)); 
Bot_L = zeros(1, length(time)-2);
Bot_R = zeros(1, length(time)-2);

%get initial values from the initial value edit fields
q(1,1) = app.XInitialPositionEditField.Value; 
q(2,1) = app.YInitialPositionEditField.Value;
q(3,1) = app.InitialAngleEditField.Value;

Vn = app.DesiredVelocityEditField.Value; %desired velocity if going to constant point

Vd = Vn; %desired velocity is norm of velocity in each curve
u = zeros(2, length(time)-2); %empty input array

%%% Velocity Conditions %%%

Wd = 0; %desired angular velocity is change in angle of path over time

%% Linear Quadratic Regulator Conditions 
XW = app.LQRForwardWeightEditField.Value;
YW = app.LQRLateralWeightEditField.Value;
AW = app.LQRAngleWeightEditField.Value;
LQRV = app.LQRVelocityCostEditField.Value;
LQRR = app.LQRTurningCostEditField.Value;

%%%%%%%%%%%%%%%%%%%%%% Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

Q = [XW, 0, 0;0, YW, 0;0, 0, AW]; %Weighting matrix for importance of the three errors x, y, theta
R = [LQRV, 0; 0, LQRR]; %Cost matrix for V, W
b = 1;

for i = 1:length(time)-2
    
      if abs(q(3,i)) > pi
        if q(3,i) > 0 
            while (q(3,i) > pi)
                q(3,i) = q(3,i) - 2*pi;
            end 
        else 
            while (q(3,i) < -pi)
                q(3,i) = q(3,i) + 2*pi;
            end 
        end 
      end 
    
    qd(3,b) = atan2(qd(2,b)-q(2,i),qd(1,b)-q(1,i));
    e(:,i) = q(:,i) - qd(:,b);
    e(3,:) = unwrap(e(3,:));
    [Vd, qd(:,b)] = GetInputs(qd, b, Vn, e(3,i));
    e(:,i) = q(:,i) - qd(:,b);
    e(3,:) = unwrap(e(3,:));
    u(:, i) = LQRcontrols(qd(:,b), Q, R, Vd, e(3,i));
    if app.MapInputsforRealRobotCheckBox.Value == 1
    [Wl, Wr] = GetDDInput(u(:,i), r, Rad);%Convert from our unicycle model to inputs that are compatible with a differential drive robot
    [Wl, Wr, Bot_L(i), Bot_R(i)] = MappingInputs(Wl, Wr); %constrain our motion based on limitations of robots
    u(:,i) = InverseMap(Wl, Wr, r, Rad); %get unicycle inputs based on constrained motion 
    end 
    q(:,i+1) = MotionResponse(q(:,i), u(:,i), dt); %get change in position based on inputs
    e(:,i+1) = q(:,i+1) - qd(:,b); %Get error again to see if we're close enough to desired point 
    %(We don't recalculate desired angle but that's okay since it's only distance that matters)
    if sqrt(e(2,i+1)^2+e(1,i+1)^2)<0.05 %move to next desired point if we're close enough
        b = b + 1;
        if b == POINTS+1
            q(:, (i+1):length(time)-2) = q(:, i+1).*ones(3, length(time)-2-i);
            break 
        end   
    end 
    
end 

%% Convert to DD from Unicycle
[Wl, Wr] = GetDDInput(u, r, Rad); 

%%%%%%%%%%%%%%%%%%%%%%%%% PLOTTING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
em = sqrt(e(1,:).^2+e(2,:).^2); %error magnitude is the norm of the error in x and y 

%all this stuff is just getting vectors to be the right length for plotting
U_t = zeros(2, length(time)); 
U_t(:,1:length(time)-2) = u;
U_t(length(time)-1:length(time)) = 0;
q(:,length(time)-1) = q(:, length(time)-2);
em(length(time)-1) = em(length(time)-2);


xmin = min(q(1,:))-1; %getting our axis limits for our plots
ymin = min(q(2,:))-1;
xmax = max(q(1,:))+1;
ymax = max(q(2,:))+1;
gridmax = max(xmax, ymax); %we want our main grid to show all of the robot's motion
gridmin = min(xmin, ymin);

x = [-0.098  , -0.01, -0.01, 0.05, 0.05, 0.087, 0.098, 0.098, 0.087, 0.05 ,0.05, -0.01, -0.01, -0.098]; %robot drawing with wheels at front
y = [-0.063,-0.063,-0.09, -0.09,-0.063, -0.063, -0.02, 0.02, 0.063, 0.063,0.09, 0.09, 0.063, 0.063]; %this is to scale for the real robot
for j = 1:(length(time))-1
    plot(app.UIAxes, q(1,1:j), q(2,1:j), 'k')
    hold(app.UIAxes, 'on')
    plot(app.UIAxes,qd(1,:), qd(2,:), 'o')
%     hold on
    clf
    app.UIAxes.XLim = [gridmin, gridmax]; %we keep the plot square to avoid warping the robot
    app.UIAxes.YLim = [gridmin, gridmax];
    %x = [-0.098  , -0.075, -0.075, 0, 0,0.087, 0.098, 0.098, 0.087, 0,0, -0.075, -0.075, -0.098]; %robot drawing with wheels at back
    %y = [-0.063,-0.063,-0.09, -0.09,-0.063, -0.063, -0.02, 0.02, 0.063, 0.063,0.09, 0.09, 0.063, 0.063];
    
  
    g = hgtransform(app.UIAxes); %make a transform object that we can manipulate
    patch(app.UIAxes,'XData',x,'YData',y,'FaceColor','black','Parent',g) %draw our bot
    axis equal
    g.Matrix = makehgtform('translate',[q(1, j), q(2,j), 0], ... %rotate and move our robot
                    'zrotate',q(3,j));
    drawnow
    pause(0.05);
    hold(app.UIAxes, 'off')
    cla
    
    %plot the error vs time
    plot(app.UIAxes2,time(1:j),em(1:j))
    app.UIAxes2.XLim = [0 max(time)];
    app.UIAxes2.YLim = [0 max(em)];
 
    %getting max and min for plotting inputs
    WR = [Wr 0];
    WL = [Wl 0];
    as = [max(WR) max(WL) max(U_t(1,:)) max(U_t(2,:))];
    max_in = max(as);
    ad = [min(WR) min(WL) min(U_t(1,:)) min(U_t(2,:))];
    min_in = min(ad);
    

    %display the inputs depending on what position our inputs switch is in
    if app.MapInputsforRealRobotCheckBox.Value == 1 %if box is checked, show real inputs
        plot(app.UIAxes3_3,time(1:j), Bot_L(1:j), 'k', time(1:j), Bot_R(1:j), 'b')
        app.UIAxes3_3.YLim = [-255 255];
        legend(app.UIAxes3_3, 'Left Wheel','Right Wheel')
    elseif strcmpi('Unicycle', app.InputsSwitch.Value) %if box isn't checked, switch is on unicycle
        plot(app.UIAxes3_3,time(1:j), U_t(1,1:j), 'r', time(1:j), U_t(2, 1:j),'g')
        legend(app.UIAxes3_3, 'Velocity','Angular Velocity')
        app.UIAxes3_3.YLim = [min_in max_in];
    else %if box isn't checked and switch is on diff drive
        plot(app.UIAxes3_3,time(1:j), WL(1:j), 'k', time(1:j), WR(1:j), 'b');
        legend(app.UIAxes3_3, 'Left Wheel','Right Wheel')
        app.UIAxes3_3.YLim = [min_in max_in];
    end 
    app.UIAxes3_3.XLim = [0 max(time)];

    
    Break = app.Switch.Value;
    
    %if we hit the end switch, stop plotting
    if strcmpi('End', Break)
        j = length(time);
        break
    end 

end 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%


 %This function looks at whether it is more efficient for our robot to
 %follow the desired curve forwards or backwards
function [Vdx, qdx] = GetInputs(qd, b, Vn, e)

 
        %if we're going to a point, desired angle is the angle of a line
        %from the current point to the desired point

        
   if abs(qd(3,b))>pi
        if qd(3,b)>0
            qd(3,b) = qd(3,b)-2*pi;
        else qd(3,b) = qd(3,b) + 2*pi;
        end 
   end    
    if abs(e) > pi/2
        %if we are further than 90 degrees from our desired angle
        %it's easier to go backwards (since it'll be a turn of less than 90 degrees)
        if qd(3,b)>=0
        qd(3,b) = qd(3,b) - pi;
        Vd = -Vn;
        
        elseif qd(3,b) < 0
         qd(3,b) = qd(3,b) + pi;
         Vd = -Vn;
        end 
    else 
    Vd = Vn;
    end   
    
    qd(3,1:b) = unwrap(qd(3,1:b)); %our values might get wrapped due to the above operations, so we need to unwrap for safety

    Vdx = Vd;
    qdx = qd(:,b);

end 

%this functions gets control inputs based on error and desired inputs
function u = LQRcontrols(qd, Q, R, Vd, e)
    A = [0, 0, -Vd*sin(qd(3));0, 0, Vd*cos(qd(3));0, 0, 0]; %evaluate matrix at desired inputs and location
    B = [cos(qd(3)), 0;sin(qd(3)), 0;0, 1];
    K = lqr(A, B, Q, R); %This computes our gain matrix using a LQR
    u = -K*[0; 0; e] + [Vd; 0]; %this will keep our robot velocity constant, just playing around
end 


%this function coverts from unicycle inputs to diff drive inputs
function [Wl, Wr] = GetDDInput(u, r, Rad)
    Wl = u(1,:)./r - Rad.*u(2,:)./r;
    Wr = u(1,:)./r + Rad.*u(2,:)./r;

end 

%this function maps diff drive inputs to what the robot can achieve
function [Wl, Wr, Bot_L, Bot_R] = MappingInputs(Wl, Wr)

    %this model was developed from diagnostic testing
    Wr = round(Wr*13+90*sign(Wr));
    Wl = round(Wl*13+90*sign(Wl));
    
    if abs(Wr)>255
        Wr = 255*sign(Wr);
    elseif abs(Wr) <= 90
        Wr = 0;
    end 
    
    if abs(Wl)>255
        Wl = 255*sign(Wl);
    elseif abs(Wl) <= 90
        Wl = 0;
    end 
    
    Bot_L = Wl;
    Bot_R = Wr;
    
end 

        
%this function takes the robot's inputs and turns them into unicycle inputs        
function u = InverseMap(Wl, Wr, r, Rad)
    Wr = (Wr-90*sign(Wr))/13; %from diagnostic testing model
    Wl = (Wl-90*sign(Wl))/13;

    u(1) = 0.5*r*(Wr+Wl);
    u(2) = 0.5*(r/Rad)*(Wr-Wl);  
end 

%this function evaluates the change in position over a timestep due to control inputs
function q = MotionResponse(q, u, dt)
    q = q + [cos(q(3)), 0; sin(q(3)), 0; 0, 1]*u*dt;
end 


        end

        % Callback function
        function SwitchValueChanged2(app, event)
            
        end

        % Value changed function: NumberofPointsEditField
        function NumberofPointsEditFieldValueChanged(app, event)
            data = app.UITable.Data;
            
            %If we reduce number of agents, truncate bottom rows.
            %If we increase number of agents, add empty rows
            if app.NumberofPointsEditField.Value < size(data,1)
                app.UITable.Data = data(1:app.NumberofPointsEditField.Value, :);
            else
                h = size(data,1);
                data(h:app.NumberofPointsEditField.Value, :) = zeros(app.NumberofPointsEditField.Value-h+1, 2);
                app.UITable.Data = data;
            end 

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Path')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Positional Error')
            xlabel(app.UIAxes2, 'Time')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Control Inputs')
            xlabel(app.UIAxes3_3, 'Time')
            app.XInitialPositionEditFieldLabel = uilabel(app.UIFigure);
            app.XInitialPositionEditFieldLabel.Text = 'X Initial Position';
            app.XInitialPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XInitialPositionEditField.ValueChangedFcn = createCallbackFcn(app, @XInitialPositionEditFieldValueChanged, true);
            app.InputsSwitchLabel = uilabel(app.UIFigure);
            app.InputsSwitchLabel.Text = 'Inputs';
            app.InputsSwitch = uiswitch(app.UIFigure, 'slider');
            app.InputsSwitch.Items = {'Differential Drive', 'Unicycle'};
            app.InputsSwitch.Value = 'Differential Drive';
            app.YInitialPositionEditFieldLabel = uilabel(app.UIFigure);
            app.YInitialPositionEditFieldLabel.Text = 'Y Initial Position';
            app.YInitialPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YInitialPositionEditField.ValueChangedFcn = createCallbackFcn(app, @YInitialPositionEditFieldValueChanged, true);
            app.InitialAngleEditFieldLabel = uilabel(app.UIFigure);
            app.InitialAngleEditFieldLabel.Text = 'Initial Angle';
            app.InitialAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialAngleEditField.ValueChangedFcn = createCallbackFcn(app, @InitialAngleEditFieldValueChanged, true);
            app.TimeLengthSliderLabel = uilabel(app.UIFigure);
            app.TimeLengthSliderLabel.Text = 'Time Length';
            app.TimeLengthSlider = uislider(app.UIFigure);
            app.TimeLengthSlider.Limits = [1 101];
            app.TimeLengthSlider.Value = 50;
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'', 'End'};
            app.Switch.Value = '';
            app.PlotPathButton = uibutton(app.UIFigure, 'push');
            app.PlotPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPathButtonPushed2, true);
            app.PlotPathButton.Text = 'Plot Path';
            app.LQRForwardWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRForwardWeightEditFieldLabel.Text = 'LQR Forward Weight';
            app.LQRForwardWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRForwardWeightEditField.Value = 8;
            app.LQRLateralWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRLateralWeightEditFieldLabel.Text = 'LQR Lateral Weight';
            app.LQRLateralWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRLateralWeightEditField.Value = 6;
            app.LQRAngleWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRAngleWeightEditFieldLabel.Text = 'LQR Angle Weight';
            app.LQRAngleWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRAngleWeightEditField.Value = 15;
            app.LQRVelocityCostEditFieldLabel = uilabel(app.UIFigure);
            app.LQRVelocityCostEditFieldLabel.Text = 'LQR Velocity Cost';
            app.LQRVelocityCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRVelocityCostEditField.Value = 3;
            app.LQRTurningCostEditFieldLabel = uilabel(app.UIFigure);
            app.LQRTurningCostEditFieldLabel.Text = 'LQR Turning Cost';
            app.LQRTurningCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRTurningCostEditField.Value = 10;
            app.MapInputsforRealRobotCheckBox = uicheckbox(app.UIFigure);
            app.MapInputsforRealRobotCheckBox.Text = 'Map Inputs for Real Robot';
            app.DesiredVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredVelocityEditFieldLabel.Text = 'Desired Velocity';
            app.DesiredVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredVelocityEditField.Value = 0.1;
            app.NumberofPointsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointsEditFieldValueChanged, true);
            app.NumberofPointsEditField.Value = 10;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathTrackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end