classdef TrajectoryTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        XInitialPositionEditFieldLabel  matlab.ui.control.Label
        XInitialPositionEditField       matlab.ui.control.NumericEditField
        InputsSwitchLabel               matlab.ui.control.Label
        InputsSwitch                    matlab.ui.control.Switch
        YInitialPositionEditFieldLabel  matlab.ui.control.Label
        YInitialPositionEditField       matlab.ui.control.NumericEditField
        InitialAngleEditFieldLabel      matlab.ui.control.Label
        InitialAngleEditField           matlab.ui.control.NumericEditField
        TimeLengthSliderLabel           matlab.ui.control.Label
        TimeLengthSlider                matlab.ui.control.Slider
        XCurveDropDownLabel             matlab.ui.control.Label
        XCurveDropDown                  matlab.ui.control.DropDown
        YCurveDropDownLabel             matlab.ui.control.Label
        YCurveDropDown                  matlab.ui.control.DropDown
        XAmplitudeEditFieldLabel        matlab.ui.control.Label
        XAmplitudeEditField             matlab.ui.control.NumericEditField
        YAmplitudeEditFieldLabel        matlab.ui.control.Label
        YAmplitudeEditField             matlab.ui.control.NumericEditField
        XFrequencyEditFieldLabel        matlab.ui.control.Label
        XFrequencyEditField             matlab.ui.control.NumericEditField
        YFrequencyEditFieldLabel        matlab.ui.control.Label
        YFrequencyEditField             matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.ToggleSwitch
        PlotPathButton                  matlab.ui.control.Button
        LQRForwardWeightEditFieldLabel  matlab.ui.control.Label
        LQRForwardWeightEditField       matlab.ui.control.NumericEditField
        LQRLateralWeightEditFieldLabel  matlab.ui.control.Label
        LQRLateralWeightEditField       matlab.ui.control.NumericEditField
        LQRAngleWeightEditFieldLabel    matlab.ui.control.Label
        LQRAngleWeightEditField         matlab.ui.control.NumericEditField
        LQRVelocityCostEditFieldLabel   matlab.ui.control.Label
        LQRVelocityCostEditField        matlab.ui.control.NumericEditField
        LQRTurningCostEditFieldLabel    matlab.ui.control.Label
        LQRTurningCostEditField         matlab.ui.control.NumericEditField
        MapInputsforRealRobotCheckBox   matlab.ui.control.CheckBox
        DesiredVelocityEditFieldLabel   matlab.ui.control.Label
        DesiredVelocityEditField        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Callback function
        function PlotPathButtonPushed(app, event)

        end

        % Value changed function: XInitialPositionEditField
        function XInitialPositionEditFieldValueChanged(app, event)

        end

        % Callback function
        function SwitchValueChanged(app, event)
 
        end

        % Value changed function: YInitialPositionEditField
        function YInitialPositionEditFieldValueChanged(app, event)

        end

        % Value changed function: InitialAngleEditField
        function InitialAngleEditFieldValueChanged(app, event)

        end

        % Callback function
        function StopButtonPushed(app, event)

        end

        % Callback function
        function PlotPathButtonValueChanged(app, event)

        end

        % Button pushed function: PlotPathButton
        function PlotPathButtonPushed2(app, event)
%%%%%%%%%%%% variables and constants %%%%%%%%%%%%%%%%%%%%%%%%
figure(1)
str = {'Processing...','Please Wait'};
annotation('textbox','String',str,'FitBoxToText','on');
set(figure(1), 'Visible', 'off');   
dt = 0.05; %time step 
end_time = app.TimeLengthSlider.Value; %when we want our simulation to end
steps = end_time/dt; %number of steps to divide our time interval into
t = linspace(0,end_time,steps);

Rad = 0.126/2; %Width of robot chassis 
r = 0.065/2; %radius of robot wheels


%%%%%%%%%%%% Parametric Path and Getting Values from Edit Fields %%%%%%%%%%%%%%%%%%%%%%%%%%%%
Xscale = app.XAmplitudeEditField.Value; %get our amplitude and frequency values from the edit fields
Yscale = app.YAmplitudeEditField.Value;
XF = app.XFrequencyEditField.Value;
YF = app.YFrequencyEditField.Value;
qd = zeros(3, length(t)); %make empty array for desired positions

%these compare strings from the dropdown menu to determine what curve we're plotting
%then set the desired positions in each coordinate to those of the curve
if strcmpi('sin(t)', app.XCurveDropDown.Value) 
    qd(1,:) = Xscale*sin(t.*XF);
elseif strcmpi('cos(t)', app.XCurveDropDown.Value)
    qd(1,:) = Xscale*cos(t.*XF);
elseif strcmpi('t', app.XCurveDropDown.Value)
    qd(1,:) = Xscale*(t.*XF);
elseif strcmpi('t^2', app.XCurveDropDown.Value)
    qd(1,:) = Xscale*(t.*XF).^2;
elseif strcmpi('t^3', app.XCurveDropDown.Value)
    qd(1,:) = Xscale*(t.*XF).^3;
elseif strcmpi('t^4', app.XCurveDropDown.Value)
    qd(1,:) = Xscale*(t.*XF).^4;
else 
    qd(1,:) = Xscale*ones(1, length(t));
end 

if strcmpi('sin(t)', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*sin(t.*YF);
elseif strcmpi('cos(t)', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*cos(t.*YF);
elseif strcmpi('t', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*(t.*YF);
elseif strcmpi('t^2', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*(t.*YF).^2;
elseif strcmpi('t^3', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*(t.*YF).^3;
elseif strcmpi('t^4', app.YCurveDropDown.Value)
    qd(2,:) = Yscale*(t.*YF).^4;
else 
    qd(2,:) = Yscale*ones(1, length(t));
end 


thetad = atan2(diff(qd(2, :)),diff(qd(1, :))); %desired angle points in the direction of the path velocity vector
thetad(length(t)) = thetad(length(t)-1); %make up for losing one unit of length
qd(3,:) = thetad;
qd(3,:) = unwrap(thetad); %unwrapping smooths jumps in angles bigger then pi.
                    %this is helpful when the robot goes from -pi to pi as they are     
                    %the same angle, but the robot would see it as a 2pi rotation
  
%make some empty arrays to hold important things
q = zeros(3, length(t));
e = zeros(3, length(t)); 
Bot_L = zeros(1, length(t)-2);
Bot_R = zeros(1, length(t)-2);

%get initial values from the initial value edit fields
q(1,1) = app.XInitialPositionEditField.Value; 
q(2,1) = app.YInitialPositionEditField.Value;
q(3,1) = app.InitialAngleEditField.Value;

% Wd = diff(qd(3,:))./dt; %desired angular velocity, defunct code
Vd = sqrt((diff(qd(1,:)).^2+diff(qd(2,:)).^2))./dt; %desired velocity is norm of velocity in each curve
u = zeros(2, length(t)-2); %empty input array

%%% Velocity Conditions %%%

Vn = app.DesiredVelocityEditField.Value; %desired velocity if going to constant point

[Vd, qd, point] = VConstraint(Vd, t, qd, Vn); %constrain velocity if necessary

Wd = diff(qd(3,:))/dt; %desired angular velocity is change in angle of path over time

%% Linear Quadratic Regulator Conditions 
XW = app.LQRForwardWeightEditField.Value;
YW = app.LQRLateralWeightEditField.Value;
AW = app.LQRAngleWeightEditField.Value;
LQRV = app.LQRVelocityCostEditField.Value;
LQRR = app.LQRTurningCostEditField.Value;

%%%%%%%%%%%%%%%%%%%%%% Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

Q = [XW, 0, 0;0, YW, 0;0, 0, AW]; %Weighting matrix for importance of the three errors x, y, theta
R = [LQRV, 0; 0, LQRR]; %Cost matrix for V, W

for i = 1:length(t)-2
    [Vd(i), Wd(i), qd(:,i)] = GetInputs(Vd, qd, q, dt, i, point);
    %q(3,1:i) = unwrap(q(3,1:i)); %I think this would be redundant since we never have big angle jumps in this loop
    e(:,i) = q(:,i) - qd(:,i);
    u(:, i) = LQRcontrols(qd(:,i), Q, R, Wd(i), Vd(i), e(:,i));
   
    if app.MapInputsforRealRobotCheckBox.Value == 1 %map our inputs to what the robot can actually achieve
    [Wl, Wr] = GetDDInput(u(:,i), r, Rad);%Convert from our unicycle model to inputs that are compatible with a differential drive robot
    [Wl, Wr, Bot_L(i), Bot_R(i)] = MappingInputs(Wl, Wr); %constrain our motion based on limitations of robots
    u(:,i) = InverseMap(Wl, Wr, r, Rad); %get unicycle inputs based on constrained motion 
    end 
    
    q(:,i+1) = MotionResponse(q(:,i), u(:,i), dt); %get change in position based on inputs

    if point == 1 && sqrt(e(2,i)^2+e(1,i)^2)<0.05 %break out of the loop if we're close enough to our constant point
        q(:, i:length(t)) = q(:, i)*ones(1,length(t)-i+1); %set position to stay the same after we break
        u(:,i:length(t)-2) = zeros(2,length(t)-i-1); %send no controls after breaking out of loop
        e(:, i:length(t)-2) = e(:,i)*ones(1,length(t)-i-1); %set error to stay the same after we break
        break 
    end 
end 

%% Convert to DD from Unicycle
[Wl, Wr] = GetDDInput(u, r, Rad); 

%%%%%%%%%%%%%%%%%%%%%%%%% PLOTTING %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
em = sqrt(e(1,:).^2+e(2,:).^2); %error magnitude is the norm of the error in x and y 

%all this stuff is just getting vectors to be the right length for plotting
U_t = zeros(2, length(t)); 
U_t(:,1:length(t)-2) = u;
U_t(length(t)-1:length(t)) = 0;
q(:,length(t)) = q(:, length(t)-1);
em(length(t)) = em(length(t)-1);


xmin = min(q(1,:))-1; %getting our axis limits for our plots
ymin = min(q(2,:))-1;
xmax = max(q(1,:))+1;
ymax = max(q(2,:))+1;
gridmax = max(xmax, ymax); %we want our main grid to show all of the robot's motion
gridmin = min(xmin, ymin);
for j = 1:(length(t))-1
    plot(app.UIAxes, q(1,1:j), q(2,1:j), 'k', qd(1, j+1), qd(2, j+1), 'o', qd(1,1:j), qd(2, 1:j), 'g')
    hold on
    clf
    app.UIAxes.XLim = [gridmin, gridmax]; %we keep the plot square to avoid warping the robot
    app.UIAxes.YLim = [gridmin, gridmax];
    %x = [-0.098  , -0.075, -0.075, 0, 0,0.087, 0.098, 0.098, 0.087, 0,0, -0.075, -0.075, -0.098]; %robot drawing with wheels at back
    %y = [-0.063,-0.063,-0.09, -0.09,-0.063, -0.063, -0.02, 0.02, 0.063, 0.063,0.09, 0.09, 0.063, 0.063];
    
    x = [-0.098  , -0.01, -0.01, 0.05, 0.05, 0.087, 0.098, 0.098, 0.087, 0.05 ,0.05, -0.01, -0.01, -0.098]; %robot drawing with wheels at front
    y = [-0.063,-0.063,-0.09, -0.09,-0.063, -0.063, -0.02, 0.02, 0.063, 0.063,0.09, 0.09, 0.063, 0.063]; %this is to scale for the real robot
   
    g = hgtransform(app.UIAxes); %make a transform object that we can manipulate
    patch(app.UIAxes,'XData',x,'YData',y,'FaceColor','black','Parent',g) %draw our bot
    axis equal
    g.Matrix = makehgtform('translate',[q(1, j), q(2,j), 0], ... %rotate and move our robot
                    'zrotate',q(3,j));
    drawnow
    pause(0.001);
    hold off
    cla
    
    %plot the error vs time
    plot(app.UIAxes2,t(1:j),em(1:j))
    app.UIAxes2.XLim = [0 max(t)];
    app.UIAxes2.YLim = [0 max(em)];
 
    %getting max and min for plotting inputs
    WR = [Wr 0];
    WL = [Wl 0];
    as = [max(WR) max(WL) max(U_t(1,:)) max(U_t(2,:))];
    max_in = max(as);
    ad = [min(WR) min(WL) min(U_t(1,:)) min(U_t(2,:))];
    min_in = min(ad);
    

    %display the inputs depending on what position our inputs switch is in
    if app.MapInputsforRealRobotCheckBox.Value == 1 %if box is checked, show real inputs
        plot(app.UIAxes3_3,t(1:j), Bot_L(1:j), 'k', t(1:j), Bot_R(1:j), 'b')
        app.UIAxes3_3.YLim = [-255 255];
        legend(app.UIAxes3_3, 'Left Wheel','Right Wheel')
    elseif strcmpi('Unicycle', app.InputsSwitch.Value) %if box isn't checked, switch is on unicycle
        plot(app.UIAxes3_3,t(1:j), U_t(1,1:j), 'r', t(1:j), U_t(2, 1:j),'g')
        legend(app.UIAxes3_3, 'Velocity','Angular Velocity')
        app.UIAxes3_3.YLim = [min_in max_in];
    else %if box isn't checked and switch is on diff drive
        plot(app.UIAxes3_3,t(1:j), WL(1:j), 'k', t(1:j), WR(1:j), 'b');
        legend(app.UIAxes3_3, 'Left Wheel','Right Wheel')
        app.UIAxes3_3.YLim = [min_in max_in];
    end 
    app.UIAxes3_3.XLim = [0 max(t)];

    
    Break = app.Switch.Value;
    
    %if we hit the end switch, stop plotting
    if strcmpi('End', Break)
        j = length(t);
        break
    end 

end 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LOCAL FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%this function makes sure we don't have zero for velocity, and handles velocity for constant point objectives
function [Vd, qd, point] = VConstraint(Vd, t, qd, Vn)

    if Vd == zeros(1, length(t)-1) %this condition accounts for if our path is a constant point we want to reach
        Vd = Vn*ones(1, length(t)-1); %our 'desired' velocity if we're travelling to a point
        point = 1; %variable that'll help later with desired angle stuff. 
    else 
        point = 0; %we are not travelling to a constant destination
    end 
    
    %if we don't have a constant point, but we have a path that has situations with zero velocity
     for j = 1:length(t)-1 %since the rank of our A matrix will be zero if V is zero, we need to approximate zero with a very small V
            if Vd(j) == 0
                Vd(j) = 0.00001;
            end
     end 

end 


 %This function looks at whether it is more efficient for our robot to
 %follow the desired curve forwards or backwards
function [Vdx, Wdx, qdx] = GetInputs(Vd, qd, q, dt, i, point)

    if point == 1
        %if we're going to a point, desired angle is the angle of a line
        %from the current point to the desired point
        qd(3,i) = atan2(qd(2,i)-q(2,i), qd(1,i)-q(1,i));
    end 
    
    if abs(q(3,i)-qd(3,i)) > pi/2
        %if we are further than 90 degrees from our desired angle
        %it's easier to go backwards (since it'll be a turn of less than 90 degrees)
        if qd(3,i)>0
        qd(3,i) = qd(3,i) - pi;
        Vd(i) = -Vd(i);
        
        elseif qd(3,i) < 0
         qd(3,i) = qd(3,i) + pi;
         Vd(i) = -Vd(i);
        end 
        
    end 
    
    qd(3,1:i) = unwrap(qd(3,1:i)); %our values might get unwrapped due to the above operations, so we need to rewrap for safety
   
    if i == 1
        Wdx = 0;
    else 
        Wdx =(qd(3,i)-qd(3,i-1))/dt; %update our desired angular velocity with new desired angles
    end 
    
    if point == 1
        Wdx = 0; %if we are going to a constant point, no desired angular velocity
    end 
    
    Vdx = Vd(i);
    qdx = qd(:,i);

end 

%this functions gets control inputs based on error and desired inputs
function u = LQRcontrols(qd, Q, R, Wd, Vd, e)
    A = [0, 0, -Vd*sin(qd(3));0, 0, Vd*cos(qd(3));0, 0, 0]; %evaluate matrix at desired inputs and location
    B = [cos(qd(3)), 0;sin(qd(3)), 0;0, 1];
    K = lqr(A, B, Q, R); %This computes our gain matrix using a LQR
    u = -K*[e(1); e(2); e(3)] + [Vd; Wd]; %get the inputs for our system 
                    %from the gain matrix, multiplied by the error vector
                    %the -ve comes from defn u = -Kq (proportional control), which is used by matlab to solve lqr                
    if point == 1 && app.MapInputsforRealRobotCheckBox.Value == 1
         u = -K*[0; 0; e(3)] + [Vd; Wd]; %this will keep our robot velocity constant, just playing around
    end 
end 


%this function coverts from unicycle inputs to diff drive inputs
function [Wl, Wr] = GetDDInput(u, r, Rad)
    Wr = u(1,:)./r + Rad.*u(2,:)./r;
    Wl = u(1,:)./r - Rad.*u(2,:)./r;

end 

%this function maps diff drive inputs to what the robot can achieve
function [Wl, Wr, Bot_L, Bot_R] = MappingInputs(Wl, Wr)

    %this model was developed from diagnostic testing
    Wr = round(Wr*13+90*sign(Wr));
    Wl = round(Wl*13+90*sign(Wl));
    
    if abs(Wr)>255
        Wr = 255*sign(Wr);
    elseif abs(Wr) <= 90
        Wr = 0;
    end 
    
    if abs(Wl)>255
        Wl = 255*sign(Wl);
    elseif abs(Wl) <= 90
        Wl = 0;
    end 
    
    Bot_L = Wl;
    Bot_R = Wr;
    
end 

        
%this function takes the robot's inputs and turns them into unicycle inputs        
function u = InverseMap(Wl, Wr, r, Rad)
    Wr = (Wr-90*sign(Wr))/13; %from diagnostic testing model
    Wl = (Wl-90*sign(Wl))/13;

    u(1) = 0.5*r*(Wr+Wl);
    u(2) = 0.5*(r/Rad)*(Wr-Wl);  
end 

%this function evaluates the change in position over a timestep due to control inputs
function q = MotionResponse(q, u, dt)
    q = q + [cos(q(3)), 0; sin(q(3)), 0; 0, 1]*u*dt;
end 


        end

        % Callback function
        function SwitchValueChanged2(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Path')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Positional Error')
            xlabel(app.UIAxes2, 'Time')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, 'Control Inputs')
            xlabel(app.UIAxes3_3, 'Time')
            app.XInitialPositionEditFieldLabel = uilabel(app.UIFigure);
            app.XInitialPositionEditFieldLabel.Text = 'X Initial Position';
            app.XInitialPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.XInitialPositionEditField.ValueChangedFcn = createCallbackFcn(app, @XInitialPositionEditFieldValueChanged, true);
            app.InputsSwitchLabel = uilabel(app.UIFigure);
            app.InputsSwitchLabel.Text = 'Inputs';
            app.InputsSwitch = uiswitch(app.UIFigure, 'slider');
            app.InputsSwitch.Items = {'Differential Drive', 'Unicycle'};
            app.InputsSwitch.Value = 'Differential Drive';
            app.YInitialPositionEditFieldLabel = uilabel(app.UIFigure);
            app.YInitialPositionEditFieldLabel.Text = 'Y Initial Position';
            app.YInitialPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YInitialPositionEditField.ValueChangedFcn = createCallbackFcn(app, @YInitialPositionEditFieldValueChanged, true);
            app.InitialAngleEditFieldLabel = uilabel(app.UIFigure);
            app.InitialAngleEditFieldLabel.Text = 'Initial Angle';
            app.InitialAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialAngleEditField.ValueChangedFcn = createCallbackFcn(app, @InitialAngleEditFieldValueChanged, true);
            app.TimeLengthSliderLabel = uilabel(app.UIFigure);
            app.TimeLengthSliderLabel.Text = 'Time Length';
            app.TimeLengthSlider = uislider(app.UIFigure);
            app.TimeLengthSlider.Limits = [1 101];
            app.TimeLengthSlider.Value = 50;
            app.XCurveDropDownLabel = uilabel(app.UIFigure);
            app.XCurveDropDownLabel.Text = 'X-Curve';
            app.XCurveDropDown = uidropdown(app.UIFigure);
            app.XCurveDropDown.Items = {'sin(t)', 'cos(t)', 't', 't^2', 't^3', 't^4', '1'};
            app.XCurveDropDown.Value = 'sin(t)';
            app.YCurveDropDownLabel = uilabel(app.UIFigure);
            app.YCurveDropDownLabel.Text = 'Y-Curve';
            app.YCurveDropDown = uidropdown(app.UIFigure);
            app.YCurveDropDown.Items = {'sin(t)', 'cos(t)', 't', 't^2', 't^3', 't^4', '1'};
            app.YCurveDropDown.Value = 'sin(t)';
            app.XAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.XAmplitudeEditFieldLabel.Text = 'X Amplitude';
            app.XAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.XAmplitudeEditField.Value = 1;
            app.YAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.YAmplitudeEditFieldLabel.Text = 'Y Amplitude';
            app.YAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YAmplitudeEditField.Value = 1;
            app.XFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.XFrequencyEditFieldLabel.Text = 'X Frequency';
            app.XFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.XFrequencyEditField.Value = 1;
            app.YFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.YFrequencyEditFieldLabel.Text = 'Y Frequency';
            app.YFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.YFrequencyEditField.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'', 'End'};
            app.Switch.Value = '';
            app.PlotPathButton = uibutton(app.UIFigure, 'push');
            app.PlotPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPathButtonPushed2, true);
            app.PlotPathButton.Text = 'Plot Path';
            app.LQRForwardWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRForwardWeightEditFieldLabel.Text = 'LQR Forward Weight';
            app.LQRForwardWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRForwardWeightEditField.Value = 8;
            app.LQRLateralWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRLateralWeightEditFieldLabel.Text = 'LQR Lateral Weight';
            app.LQRLateralWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRLateralWeightEditField.Value = 6;
            app.LQRAngleWeightEditFieldLabel = uilabel(app.UIFigure);
            app.LQRAngleWeightEditFieldLabel.Text = 'LQR Angle Weight';
            app.LQRAngleWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRAngleWeightEditField.Value = 15;
            app.LQRVelocityCostEditFieldLabel = uilabel(app.UIFigure);
            app.LQRVelocityCostEditFieldLabel.Text = 'LQR Velocity Cost';
            app.LQRVelocityCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRVelocityCostEditField.Value = 3;
            app.LQRTurningCostEditFieldLabel = uilabel(app.UIFigure);
            app.LQRTurningCostEditFieldLabel.Text = 'LQR Turning Cost';
            app.LQRTurningCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.LQRTurningCostEditField.Value = 10;
            app.MapInputsforRealRobotCheckBox = uicheckbox(app.UIFigure);
            app.MapInputsforRealRobotCheckBox.Text = 'Map Inputs for Real Robot';
            app.DesiredVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredVelocityEditFieldLabel.Text = 'Desired Velocity';
            app.DesiredVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredVelocityEditField.Value = 0.1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrajectoryTrackingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end